classdef CANDO_control_GUI_V15 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CenterPanel                  matlab.ui.container.Panel
        LFPGraph                     matlab.ui.control.UIAxes
        ProcessedGraph               matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.StateButton
        StopButton                   matlab.ui.control.StateButton
        CurrentstatusPanel           matlab.ui.container.Panel
        AlgorithmONOFFLampLabel      matlab.ui.control.Label
        AlgorithmONOFFLamp           matlab.ui.control.Lamp
        AlgorithmstatusLabel         matlab.ui.control.Label
        AlgorithmLabel               matlab.ui.control.Label
        ConnectionstatusLampLabel    matlab.ui.control.Label
        ConnectionstatusLamp         matlab.ui.control.Lamp
        PhaseLabel                   matlab.ui.control.Label
        degLabel_2                   matlab.ui.control.Label
        IntensityLabel               matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GUISoftwareVersionLabel      matlab.ui.control.Label
        MicrocontrollerSoftwareVersionLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        XXLabel_2                    matlab.ui.control.Label
        QuickConfigOptionsPanel      matlab.ui.container.Panel
        ONtimeEditFieldLabel         matlab.ui.control.Label
        ONtimeValue                  matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        OFFtimeEditFieldLabel        matlab.ui.control.Label
        OFFtimeValue                 matlab.ui.control.NumericEditField
        msLabel_2                    matlab.ui.control.Label
        ThresholdEditFieldLabel_2    matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ConfigureButton              matlab.ui.control.Button
        UserFeedbackText             matlab.ui.control.Label
        InputselectDropDownLabel     matlab.ui.control.Label
        InputselectDropDown          matlab.ui.control.DropDown
        OutputselectDropDownLabel    matlab.ui.control.Label
        OutputselectDropDown         matlab.ui.control.DropDown
        ExperimentalParametersPanel  matlab.ui.container.Panel
        ExperimentParamsLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ExperimentPlanner % Placeholder for the experiment planner gui
        algorithm1 % structure to store the algorithm results from the experimental planner
        algorithm2 % As above for the second algorithm, if relevant.
    end
    
    methods (Access = public)
        
        function algorithms = experimentWriter(app,algorithm1,algorithm2,varargin)
            % a function to add text to the panel which holds the
            % experiment plan in the main app.
            % Needs to be called by the experiment planner and by the quick
            % update functions on the main gui if the parameters are
            % changed.
            if (strcmp(algorithm2.algorithm,'No Algorithm'))
                just1 = true;
            else
                just1 = false;
            end
            ParameterText_1 = '';
            ParameterText_2 = '';
            % if there is something selected for algorithm 1:
            if (strcmp(algorithm1.algorithm,'No Algorithm'))
               if just1
                    ParameterText_1 = "No algorithms selected, the control box will record data only.";
               end
                % if there is no algorithm selected for the first algorithm
                % but there is for the second, then do nothing here and the
                % text will be populated by the second algorithm.
            else % If we have selected an algorithm..
                if just1
                    ParameterText_1 = ['Algorithm: ' algorithm1.algorithm newline];
                else
                    ParameterText_1 = ['First Algorithm: ' algorithm1.algorithm newline];
                end
                if (strcmp(algorithm1.algorithm,'Phase Shift'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline];
                elseif (strcmp(algorithm1.algorithm,'Set Intensity'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline ...
                        'Intensity: ' num2str(algorithm1.intensity) newline];
                elseif (strcmp(algorithm1.algorithm,'Step functions'))
                    ParameterText_1 = [ParameterText_1,'Pulse length: ' num2str(algorithm1.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm1.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm1.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm1.pulsepercyc) newline];
                elseif (strcmp(algorithm1.algorithm,'Random noise'))
                    ParameterText_1 = [ParameterText_1,'Gain: ' num2str(algorithm1.gain) newline];
                elseif (strcmp(algorithm1.algorithm,'Sinusoidal'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline];
                else
                    ParameterText_1 = [ParameterText_1,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end    
            
            if (just1)
                 % nothing to add
            elseif (strcmp(algorithm1.algorithm,'No Algorithm')) % if only the second algorithm
                ParameterText_2 = ['Algorithm: ' algorithm2.algorithm newline];
            else
                ParameterText_2 = ['Second Algorithm: ' algorithm2.algorithm newline];
                if (strcmp(algorithm2.algorithm,'Phase Shift'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                elseif (strcmp(algorithm2.algorithm,'Set Intensity'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline];
                elseif (strcmp(algorithm2.algorithm,'Step functions'))
                    ParameterText_2 = [ParameterText_2,'Pulse length: ' num2str(algorithm2.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm2.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm2.pulsepercyc) newline];
                elseif (strcmp(algorithm2.algorithm,'Random noise'))
                    ParameterText_2 = [ParameterText_2,'Gain: ' num2str(algorithm2.gain) newline];
                elseif (strcmp(algorithm2.algorithm,'Sinusoidal'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                else
                    ParameterText_2 = [ParameterText_2,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end
            
            ParameterText_3 = ['Stimulation on time: ' num2str(algorithm1.OnTime) newline 'Stimulation off time: ' ...
                num2str(algorithm1.OffTime) newline algorithm1.PseudSeq newline newline ...
                'Expected total experiment time for one full run: ' algorithm1.TimeEst];
            
            app.ExperimentParamsLabel.Text = [ParameterText_1,newline,ParameterText_2,newline,ParameterText_3];
            
        end
        
        function SelectAndPlotData(app)
            
            [fname,dirname] = uigetfile();
            loadedData = load([dirname,fname]);
            % now plot the loaded data
            setappdata(app.LFPGraph,'dataloaded',true);
            setappdata(app.LFPGraph,'staticdata',loadedData.Seizure(:,1));
            %try
                plot(app.LFPGraph,loadedData.Seizure(:,1)); % this will prob needed changing from x.Seizure because that is specific to the example data
                disp('Plotted the data.')
                %try
                    processedData = algorithmRun(app,loadedData.Seizure(:,1));
                    disp('algrithm processed')
                %catch ME2
                    warning('Applying the algorithm to the loaded Data has resulted in errors. Please check your algorithm parameters and make a selection if you havent already, or check your data input file.')
                %end
                plot(app.ProcessedGraph,processedData)
            %catch ME
               warning('Issue plotting and processing loaded data, please check if your file is a time series in matlab readable format.') 
            %end
                
        end
        
        function replotLoadedData(app)
            %try             
                loadedData = getappdata(app.LFPGraph,'staticdata');
                processedData = algorithmRun(app,loadedData);
            %catch ME
            %    warning('Applying the algorithm to the loaded Data has resulted in errors. Please check your algorithm parameters and make a selection if you havent already, or check your data input file.')
            %end
            %try 
                plot(app.ProcessedGraph,processedData)
            %catch ME
            %    warning('Issue plotting and processing loaded data, please check if your file is a time series in matlab readable format.') 
            %end
        end
        
        function processedData = algorithmRun(app,loadedData)
            alg1 = getappdata(0,'algorithm_1');
            %alg2 = getappdata(app.ExperimentPlanner,'algorithm_2');
            disp('Running the algorithm over the test data: ')
            disp(alg1)
            % check algorithm and apply to the loaded data if closed loop.
            if strcmp(alg1.algorithm,'Phase Shift') || strcmp(alg1.algorithm,'Set Intensity')
                disp('A phase loop algorithm is detected')
                processedData = closedLoopFIR(app,alg1,loadedData);
                if strcmp(alg1.algorithm,'Set Intensity')
                    processedData(processedData>0)=alg1.intensity(1);
                end
            elseif strcmp(alg1.algorithm,'Step functions')            
            % If open loop then it just needs to be calculated for the
            % length of the loaded data.
                onepulse = [zeros(1,alg1.pulseoff),ones(1,alg1.pulselen).*alg1.intensity(1)];
                allpulses = repmat(onepulse,1,alg1.pulsespercyc);
                if (length(allpulses)<alg1.OnTime) % pad it out if needed
                    allpulses = [allpulses,zeros(1,alg1.OnTime-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                % now need to repeat for the length of the data.
                % need to find the number of times the stimulation will fit
                % into the data,then repeat for that number, plus another
                % if needed. (So, ceil!)
                allpulses = [allpulses,zeros(1,alg.OffTime)];
                repnums = ceil(length(loadedData)/length(allpulses));
                processedData = repmat(allpulses,repnums);
            elseif strcmp(alg1.algorithm,'Random noise')
                processedData = randn(1,alg1.OnTime)*alg1.gain(1);
            elseif strcmp(alg1.algorithm,'Sinusoidal')
                dt = 0.1*(1/alg1.frequency(1));
                x = 0:dt:alg1.OnTime;  % Needs work...
                processedData = alg1.gain(1).*sin(2.*pi.*alg.frequency(1).*x+alg1.phase(1));
            else
                warning('The algorithm is unrecognised, outputting a zero line.')
                processedData = zeros(1,length(loadedData));
            end    
        end

        
        function stim = closedLoopFIR(app,alg,data)
            disp('closedLoopFIR has been triggered for the test data.')
            % set up the kernel:
            x=-alg.kernlen+1:1:0;x=x*(1/500); % h needs to be 1/sampling frequency I think? Assuming 500Hz for now. Check the C code!!
            kernelcos=cos(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x);
            kernelsin=sin(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x); 
            kernel=kernelcos*cos(alg.phase(1))-kernelsin*sin(alg.phase(1));
            kernel = kernel-mean(kernel);
            stim = zeros(1,length(data));
            % apply the closed loop algorithm here.
            for i = 1:(length(data)-alg.kernlen)
                stim(i+alg.kernlen-1) = sum(kernel'.*data(i:i+alg.kernlen-1)); % convolve with the kernel
                stim(i+alg.kernlen-1) = stim(i+alg.kernlen-1) - alg.threshold; % is this where the threshold is applied in the C code? Need to check this.
                stim(i+alg.kernlen-1) = max(stim(i+alg.kernlen-1),0); % half wave rectify
            end
            disp('Got to the end of the closed loop algorithm function')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {661, 661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {22, '1x', 400};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            
        % This is the main 'GO' function. 
        % It is operated with a toggle button which defines whether to run
        % the system or stop the system.
        ONState = app.StartButton.Value;
        
        % Set the graph x limits limits
        % Setting these manually for now - we can add a zoom button later
        app.LFPGraph.XLim       = [0 500];          % ms
        app.ProcessedGraph.XLim = [0 500];          % ms
        app.AlgStatusGraph.XLim = [0 500];          % ms
        
        % Set the graph y limits limits
        % Setting these manually for now - we can add a zoom button later
        app.LFPGraph.YLim       = [0 3.4];          % uV
        app.ProcessedGraph.YLim = [0 260];         % Value
        app.AlgStatusGraph.YLim = [0 365];          % ON/OFF
        
        % -------------------------------------------------------------------------
        % If the Start button is clicked
        % -------------------------------------------------------------------------
        if ONState == 1

%             % Update the button visual information
%             app.StartButton.BackgroundColor = 'Red';
%             app.StartButton.Text = 'Stop';
            
            % Communication variables
            % Currently these are set manually, but ideally they should be a
            % panel somewhere
            inputBufferSize = 3000;     % bytes 
            baudRateValue   = 256000;
            
            % Attempt to make connection with the microcontroller and throw and
            % exception if no connection can be made
            try
                delete(instrfind);                      % Delete previous instances
                s = serial('COM6', "BaudRate", baudRateValue,"InputBufferSize", inputBufferSize);
%                 handles.srun        = s;        
                fopen(s);                               % Open the port
                connectionStatus = 1;
            catch ME1 
                %throw(ME1) % Throw an exception if no connection
                app.ConnectionstatusLamp.Color = 'red';
                connectionStatus = 0;
            end    
            
            % Proceed only if connected so the program does not fall over
            % when offline
            if connectionStatus == 1
                
                % Show that the system is connected
                app.ConnectionstatusLamp.Color = 'green';               
                
                % Get the time and date and use them to define a default data name
                dateandtime = clock;
                filename = strcat(num2str(dateandtime(1)),'_', num2str(dateandtime(2)), '_', num2str(dateandtime(3)),'_Data');
                [file, path]  = uiputfile('1','Save Workspace As');
                
                % set up acquisition buffers
                datalength  = 6;    % bytes
                dataPoints  = inputBufferSize/datalength;
                
                % set up the data struct
                data.date           = date;
                %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                % We also need software versions and perhaps optional text
                data.index          = [];
                data.LFPData        = [];
                data.AlgValues      = [];
                data.phaseCondition = [];
                data.time           = [];
                data.gain           = [];
                data.IntensAlg      = [];
                data.Algorithm      = [];
                                                                           
                % Main acquisition and display loop
                index = 1;
                while(1)
                    % Get the microcontroller to send data
%                     fwrite(s,[1 0 0 0 0 0 0 0 0 0 0 0 0],'uint8','async');                    % Start a command to response            
                    
                    while (~strcmp(get(s,'TransferStatus'),'idle')) 
                    end
                 
                    while(s.BytesAvailable ~= inputBufferSize) 
                    end    
                    
                    % Get the input data stream and put it in the buffer
                    dataBuffer = fread(s, inputBufferSize, 'uint8');         
                    
                    while (~strcmp(get(s,'TransferStatus'),'idle'))
                    end
                    
                    % ------------------------------------------------
                    % extract data 
                    % ------------------------------------------------
                    % The data is in a binary sequence so the data needs to
                    % be acquired as bytes, converted to binary and then
                    % the information extracted for each of the components
                    byteSequence = 1:datalength;
                    
                    LFPData = 0;
                    AlgValues = 0;
                    
                    for datapoint = 1:dataPoints
                                            
                        % extract the bytes associated with the data point
                        dataBytes = dataBuffer(byteSequence);
                    
                        % convert the byte sequence to a binary sequence
                        binVals = [];
                        
                        for n = 1:datalength
                            binVals = [binVals, de2bi(dataBytes(n),8)]; 
                        end
                    
                        % Byte 1: bits 0-7:     LFP Data
                        % Byte 2: bits 8-15:    not used
                        % Byte 3: bits 0-7:     Algorithm values
                        % Byte 4: bits 8-11:    Algorithm values (cont)
                        % Byte 4: bits 12-15:   Phase condition
                        % Byte 5: bits 0-7:     Time
                        % Byte 5: bits 8-9:     Time (cont)
                        % Byte 6: bits 10-13:   Gain 
                        % Byte 6: bits 14:      Algo: 0 = original, 1 = set intensity
                        % Byte 6: bits 15:      Algorithm ON/OFF
                    %     LFPData(datapoint)          = bi2de(binVals(1:8));
                    %     AlgValues(datapoint)        = bi2de(binVals(17:28));
                    %     phaseCondition(datapoint)   = bi2de(binVals(29:32));
                    %     time(datapoint)             = bi2de(binVals(33:42));
                    %     gain(datapoint)             = bi2de(binVals(43:46));
                    %     IntensAlg(datapoint)        = binVals(47);
                    %     Algorithm(datapoint)        = binVals(48);
                    
                        LFPData          = [LFPData; bi2de(binVals(1:8))];
                        AlgValues        = [AlgValues; bi2de(binVals(9:16))];
                    
                        byteSequence = byteSequence + datalength;
                    end


%                     % ------------------------------------------------
%                     % extract data 
%                     % ------------------------------------------------
%                     % The data is in a binary sequence so the data needs to
%                     % be acquired as bytes, converted to binary and then
%                     % the information extracted for each of the components
%                     byteSequence = 1:datalength;
%                     for datapoint = 1:dataPoints
%                         
%                         % extract the bytes associated with the data point
%                         dataBytes = dataBuffer(byteSequence);
%                        
%                         % convert the byte sequence to a binary sequence
%                         binVals = [];
%                         for n = 1:datalength
%                             binVals = [binVals, de2bi(dataBytes(n),8)]; 
%                         end
%                     
%                         % Byte 1: bits 0-7:     LFP Data
%                         % Byte 2: bits 8-15:    not used
%                         % Byte 3: bits 0-7:     Algorithm values
%                         % Byte 4: bits 8-11:    Algorithm values (cont)
%                         % Byte 4: bits 12-15:   Phase condition
%                         % Byte 5: bits 0-7:     Time
%                         % Byte 5: bits 8-9:     Time (cont)
%                         % Byte 6: bits 10-13:   Gain 
%                         % Byte 6: bits 14:      Algo: 0 = original, 1 = set intensity
%                         % Byte 6: bits 15:      Algorithm ON/OFF
%                         LFPData(datapoint)          = bi2de(binVals(1:8));
%                         AlgValues(datapoint)        = bi2de(binVals(17:28));
%                         phaseCondition(datapoint)   = bi2de(binVals(29:32));
%                         time(datapoint)             = bi2de(binVals(33:42));
%                         gain(datapoint)             = bi2de(binVals(43:46));
%                         IntensAlg(datapoint)        = binVals(47);
%                         Algorithm(datapoint)        = binVals(48);
%                         
%                         byteSequence = byteSequence + datalength;
%                     end
                    
                    % ----------------------------------------
                    % Prepare the data for display
                    % ----------------------------------------
                    %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                    % This needs to be updated to convert the LFP into uV
                    % and time in ms (if possible - not important)
                    % convert LFPData into uV
                    LFPData = LFPData ./ 255 .* 3.3;
                    
                    % Convert phase condition to degrees
%                     phaseCondition = phaseCondition .* 45;
                    
                    % ----------------------------------------
                    % Append data to struct
                    % ----------------------------------------
                    data.index          = [data.index; index];
                    data.LFPData        = [data.LFPData; LFPData];
                    data.AlgValues      = [data.AlgValues; AlgValues];
%                     data.phaseCondition = [data.phaseCondition; phaseCondition];
%                     data.time           = [data.time; time];
%                     data.gain           = [data.gain; gain];
%                     data.IntensAlg      = [data.IntensAlg; IntensAlg];
%                     data.Algorithm      = [data.Algorithm; Algorithm];
                   
                    % ----------------------------------------
                    % Prepare the data for display
                    % ----------------------------------------
                    %!!! Needs updating
                    t_sequence = 1:500;
                    LFPData_sequence = LFPData(2:501);
                    ProcessedData_sequence = AlgValues(2:501);
%                     AlgState_sequence = phaseCondition;                   
                   
                    % ----------------------------------------
                    % Plot the data
                    % ----------------------------------------     
                    % Plot the incoming data (Top graph)
                    plot(app.LFPGraph, t_sequence, LFPData_sequence);
                    pause(0.00001);
                    
                    % Plot the processed data (middle graph)
                    plot(app.ProcessedGraph, t_sequence, ProcessedData_sequence);
                    pause(0.00001);
                    
                    % Plot the Algorithm state (bottom graph)
%                     plot(app.AlgStatusGraph, t_sequence, AlgState_sequence);
%                     pause(0.00001);

                    % ----------------------------------------
                    % Save data to file
                    % ----------------------------------------
                    fname      = sprintf ('%s %i', filename, index);
                    matfile    = fullfile(path, fname);
                    save(matfile,'dataBuffer');
                    
                    index = index + 1;
                end
            end
        end       
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
        
            % This function updates the algorithm parameters on the
            % microcontroller. It gives an error message if a connection
            % cannot be made.
            
            % First off, load the experiment planner window:
            disp('Loading the experiment planner')
            app.ExperimentPlanner = ExperimentPlanner(app);
            
            %app.UserFeedbackText.Text = 'The experiment conditions have been updated';
            %app.UserFeedbackText.Visible = 'on';


            % Determine whether to randomize gain
%             if app.RandomGainOffButton.Value == 1
%                 randomGainGainOption = 0;
%             elseif app.RandomGainOnButton.Value == 1 
%                 randomGainGainOption = 1;
%             end
%             
%             app.algorithm_1 = getappdata(0,'algorithm_1');
%             app.algorithm_2 = getappdata(0,'algorithm_2');
%             
%             disp('Algorithm parameters received')
%             disp(app.algorithm_1)
%             disp(app.algorithm_2)
%             
            % Get the parameters
%             centre_frequency = str2double(app.CentrefrequencyValue.Value);
%             shift_degree     = str2double(app.ShiftdegreeValue.Value);
%             scale_value      = app.ScaledValueValue.Value;
%             threshold        = app.ThresholdValue.Value;
%             setIntensityVal  = app.SetIntensityValue.Value;
%             kernelLength     = app.KernellengthValue.Value;
%             dutycycleONTime  = app.ONtimeValue.Value;
%             dutycycleOFFTime = app.OFFtimeValue.Value;
            
            
            % Communication variables
            % Currently these are set manually, but ideally they should be a
            % panel somewhere
            s                   = serial('COM6');                   % This COM has to be updated manually, needs to be transmitting to C in the linux case.
            num                 = 2048;
            BaudRateValue       = 256000;

            % Attempt to make connection with the microcontroller and throw and
            % exception if no connection can be made
            try
                delete(instrfind);                      % Delte previous instances
                set(s,'BaudRate',BaudRateValue);        % Set the BAUD rate
                s.InputBufferSize   = num;              % Set the Buffer size
                handles.srun        = s;        
                fopen(s);                               % Open the port
                
                % As these are simply settings, I suggest just using 1 or 2
                % bytes for each. There is no need to try to compress the
                % data as the send/programming frequency is very low.
                fwrite(s, [0 1 ...                      % Whatever code is required to let the uC know this is a programming command
                           centre_frequency, ...        % 8 bit value
                           shift_degree,...             % 8 bit value
                           scale_value, ...             % 8 bit value
                           threshold, ...               % 8 bit value
                           setIntensityVal, ...         % 8 bit value
                           kernelLength, ...            % 16 bit value (for convenience - needs ~9/10 bits)
                           dutycycleONTime,...          % 16 bit value (for convenience - needs ~11/12 bits)
                           dutycycleOFFTime, ...        % 16 bit value (for convenience - needs ~11/12 bits)
                           algorithmPhaseOption, ...    % 0 = fixed, 1 = sequential, 2 = random
                           gainType,...                 % 0 = algorithm gain, 1 = set intensity
                           randomGainGainOption,...     % 0 = off, 1 = on
                           dutycycleStatus, ...         % 0 = off, 1 = on
                           1, 0, ...                    % Footer if required
                           ],'uint8','async');
                
                app.ConnectionstatusLamp.Color = 'green';
                            
                %!!! We need an addtional command to the microcontroller
                %which receives its version number and an acknowledge that
                %these parameters have been updated.
                
                app.UserFeedbackText.Text = 'Algorithm successfully updated';
                app.UserFeedbackText.Visible = 'on';
            
            catch ME
                %throw(ME1) % Throw an exception if no connection
                app.ConnectionstatusLamp.Color = 'red';
 
            end     
        
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            
            % -------------------------------------------------------------------------
            % If the Start button is clicked
            % -------------------------------------------------------------------------
            if value == 1
                app.ConnectionstatusLamp.Color = 'red';
            
                % Try to close the serial port. But don't worry if this fails
                % the delete(instrfind) is the bullet proof backup.
                try
                    s = serial('COM6');
                    fclose(s);
                catch ME1 
                end
                
                % Close all serial ports Matlab can find. This makes absolutely
                % sure the ports get closed.
                delete(instrfind);
            end
        end

        % Value changed function: InputselectDropDown
        function InputselectDropDownValueChanged(app, event)
            value = app.InputselectDropDown.Value;
            
            if strcmp(value,'Static File Input')
                SelectAndPlotData(app)
            end
            
        end

        % Value changed function: ONtimeValue
        function ONtimeValueValueChanged(app, event)
            value = app.ONtimeValue.Value;
                                        
            alg1 = getappdata(0,'algorithm_1');
            alg1.OnTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
        end

        % Value changed function: OFFtimeValue
        function OFFtimeValueValueChanged(app, event)
            value = app.OFFtimeValue.Value;
            
            alg1 = getappdata(0,'algorithm_1');
            alg1.OffTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            

        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            
            % Need to get the value attached to the algorithm data, and
            % into the writing.
            alg1 = getappdata(0,'algorithm_1');
            alg1.threshold = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try 
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
                
            if val
                replotLoadedData(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.LFPGraph = uiaxes(app.CenterPanel);
            title(app.LFPGraph, 'Original Recording (LFP)')
            xlabel(app.LFPGraph, 'time (ms)')
            ylabel(app.LFPGraph, 'Voltage (uV)')
            app.ProcessedGraph = uiaxes(app.CenterPanel);
            title(app.ProcessedGraph, 'Processed Output')
            xlabel(app.ProcessedGraph, 'time (ms)')
            ylabel(app.ProcessedGraph, 'Algorithm value')
            app.StartButton = uibutton(app.CenterPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.CenterPanel, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.CurrentstatusPanel = uipanel(app.CenterPanel);
            app.CurrentstatusPanel.Title = 'Current status';
            app.AlgorithmONOFFLampLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmONOFFLampLabel.Text = 'Algorithm ON/OFF';
            app.AlgorithmONOFFLamp = uilamp(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel.Text = 'Algorithm status ';
            app.AlgorithmLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.ConnectionstatusLampLabel = uilabel(app.CurrentstatusPanel);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.CurrentstatusPanel);
            app.PhaseLabel = uilabel(app.CurrentstatusPanel);
            app.PhaseLabel.Text = 'Phase';
            app.degLabel_2 = uilabel(app.CurrentstatusPanel);
            app.degLabel_2.Text = '0 deg';
            app.IntensityLabel = uilabel(app.CurrentstatusPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.Label_6 = uilabel(app.CurrentstatusPanel);
            app.Label_6.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.GUISoftwareVersionLabel = uilabel(app.RightPanel);
            app.GUISoftwareVersionLabel.Text = 'GUI Software Version';
            app.MicrocontrollerSoftwareVersionLabel = uilabel(app.RightPanel);
            app.MicrocontrollerSoftwareVersionLabel.Text = 'Microcontroller Software Version';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '15';
            app.XXLabel_2 = uilabel(app.RightPanel);
            app.XXLabel_2.Text = 'XX';
            app.QuickConfigOptionsPanel = uipanel(app.RightPanel);
            app.QuickConfigOptionsPanel.Title = 'Quick Config Options';
            app.ONtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.ONtimeEditFieldLabel.Text = 'ON time';
            app.ONtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ONtimeValue.ValueChangedFcn = createCallbackFcn(app, @ONtimeValueValueChanged, true);
            app.msLabel = uilabel(app.QuickConfigOptionsPanel);
            app.msLabel.Text = '(ms)';
            app.OFFtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.OFFtimeEditFieldLabel.Text = 'OFF time';
            app.OFFtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.OFFtimeValue.ValueChangedFcn = createCallbackFcn(app, @OFFtimeValueValueChanged, true);
            app.msLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.msLabel_2.Text = '(ms)';
            app.ThresholdEditFieldLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.ThresholdEditFieldLabel_2.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ConfigureButton = uibutton(app.RightPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.UserFeedbackText = uilabel(app.RightPanel);
            app.UserFeedbackText.Visible = 'off';
            app.UserFeedbackText.Text = 'User Feedback';
            app.InputselectDropDownLabel = uilabel(app.RightPanel);
            app.InputselectDropDownLabel.Text = 'Input select';
            app.InputselectDropDown = uidropdown(app.RightPanel);
            app.InputselectDropDown.Items = {'Intan', 'CANDO 3', 'Linux Test Signal', 'Signal Generator', 'Static File Input'};
            app.InputselectDropDown.ValueChangedFcn = createCallbackFcn(app, @InputselectDropDownValueChanged, true);
            app.InputselectDropDown.Value = 'Intan';
            app.OutputselectDropDownLabel = uilabel(app.RightPanel);
            app.OutputselectDropDownLabel.Text = 'Output select';
            app.OutputselectDropDown = uidropdown(app.RightPanel);
            app.OutputselectDropDown.Items = {'Commercial LED', 'CANDO 3 v1', 'CANDO 3 v2', 'Oscilloscope', 'Static File Output'};
            app.OutputselectDropDown.Value = 'Commercial LED';
            app.ExperimentalParametersPanel = uipanel(app.RightPanel);
            app.ExperimentalParametersPanel.Title = 'Experimental Parameters';
            app.ExperimentParamsLabel = uilabel(app.ExperimentalParametersPanel);
            app.ExperimentParamsLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CANDO_control_GUI_V15

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end