classdef CANDO_control_GUI_V15b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        CenterPanel                  matlab.ui.container.Panel
        LFPGraph                     matlab.ui.control.UIAxes
        ProcessedGraph               matlab.ui.control.UIAxes
        StartButton                  matlab.ui.control.StateButton
        CurrentstatusPanel           matlab.ui.container.Panel
        AlgorithmONOFFLampLabel      matlab.ui.control.Label
        AlgorithmONOFFLamp           matlab.ui.control.Lamp
        AlgorithmstatusLabel         matlab.ui.control.Label
        AlgorithmLabel               matlab.ui.control.Label
        ConnectionstatusLampLabel    matlab.ui.control.Label
        ConnectionstatusLamp         matlab.ui.control.Lamp
        PhaseLabel                   matlab.ui.control.Label
        degLabel_2                   matlab.ui.control.Label
        IntensityLabel               matlab.ui.control.Label
        Label_6                      matlab.ui.control.Label
        RightPanel                   matlab.ui.container.Panel
        GUISoftwareVersionLabel      matlab.ui.control.Label
        MicrocontrollerSoftwareVersionLabel  matlab.ui.control.Label
        Label                        matlab.ui.control.Label
        XXLabel_2                    matlab.ui.control.Label
        QuickConfigOptionsPanel      matlab.ui.container.Panel
        ONtimeEditFieldLabel         matlab.ui.control.Label
        ONtimeValue                  matlab.ui.control.NumericEditField
        msLabel                      matlab.ui.control.Label
        OFFtimeEditFieldLabel        matlab.ui.control.Label
        OFFtimeValue                 matlab.ui.control.NumericEditField
        msLabel_2                    matlab.ui.control.Label
        ThresholdEditFieldLabel_2    matlab.ui.control.Label
        ThresholdEditField           matlab.ui.control.NumericEditField
        ConfigureButton              matlab.ui.control.Button
        UserFeedbackText             matlab.ui.control.Label
        InputselectDropDownLabel     matlab.ui.control.Label
        InputselectDropDown          matlab.ui.control.DropDown
        OutputselectDropDownLabel    matlab.ui.control.Label
        OutputselectDropDown         matlab.ui.control.DropDown
        ExperimentalParametersPanel  matlab.ui.container.Panel
        ExperimentParamsLabel        matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ExperimentPlanner % Placeholder for the experiment planner gui
        algorithm1 % structure to store the algorithm results from the experimental planner
        algorithm2 % As above for the second algorithm, if relevant.
        out
        input
        isSerial
    end
    
    properties (Access = public)
        inputBufferSize = 6000; % number of bytes before saving. This is 500 (samples per second) by 9 (data packets, so ~9 bytes per value?)
        dataLength = 6;
        index = 1; % Index for the automated naming of save files.
        filename % File name to save to
        path % File path to save to.
    end
    
    methods (Access = public)
        
        function experimentWriter(app,algorithm1,algorithm2,varargin)
            % a function to add text to the panel which holds the
            % experiment plan in the main app.
            % Needs to be called by the experiment planner and by the quick
            % update functions on the main gui if the parameters are
            % changed.
            if (strcmp(algorithm2.algorithm,'No Algorithm'))
                just1 = true;
            else
                just1 = false;
            end
            ParameterText_1 = '';
            ParameterText_2 = '';
            % if there is something selected for algorithm 1:
            if (strcmp(algorithm1.algorithm,'No Algorithm'))
                if just1
                    ParameterText_1 = "No algorithms selected, the control box will record data only.";
                end
                % if there is no algorithm selected for the first algorithm
                % but there is for the second, then do nothing here and the
                % text will be populated by the second algorithm.
            else % If we have selected an algorithm..
                if just1
                    ParameterText_1 = ['Algorithm: ' algorithm1.algorithm newline];
                else
                    ParameterText_1 = ['First Algorithm: ' algorithm1.algorithm newline];
                end
                if (strcmp(algorithm1.algorithm,'Phase Shift'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline];
                elseif (strcmp(algorithm1.algorithm,'Set Intensity'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline ...
                        'Intensity: ' num2str(algorithm1.intensity) newline];
                elseif (strcmp(algorithm1.algorithm,'Step functions'))
                    ParameterText_1 = [ParameterText_1,'Pulse length: ' num2str(algorithm1.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm1.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm1.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm1.pulsepercyc) newline];
                elseif (strcmp(algorithm1.algorithm,'Random noise'))
                    ParameterText_1 = [ParameterText_1,'Gain: ' num2str(algorithm1.gain) newline];
                elseif (strcmp(algorithm1.algorithm,'Sinusoidal'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Gain: ' num2str(algorithm1.gain) newline ...
                        'Phase: ' num2str(algorithm1.phase) newline];
                else
                    ParameterText_1 = [ParameterText_1,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end
            
            if (just1)
                % nothing to add
            elseif (strcmp(algorithm1.algorithm,'No Algorithm')) % if only the second algorithm
                ParameterText_2 = ['Algorithm: ' algorithm2.algorithm newline];
            else
                ParameterText_2 = ['Second Algorithm: ' algorithm2.algorithm newline];
                if (strcmp(algorithm2.algorithm,'Phase Shift'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                elseif (strcmp(algorithm2.algorithm,'Set Intensity'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline];
                elseif (strcmp(algorithm2.algorithm,'Step functions'))
                    ParameterText_2 = [ParameterText_2,'Pulse length: ' num2str(algorithm2.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm2.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm2.pulsepercyc) newline];
                elseif (strcmp(algorithm2.algorithm,'Random noise'))
                    ParameterText_2 = [ParameterText_2,'Gain: ' num2str(algorithm2.gain) newline];
                elseif (strcmp(algorithm2.algorithm,'Sinusoidal'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                else
                    ParameterText_2 = [ParameterText_2,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end
            
            ParameterText_3 = ['Stimulation on time: ' num2str(algorithm1.OnTime) newline 'Stimulation off time: ' ...
                num2str(algorithm1.OffTime) newline algorithm1.PseudSeq newline newline ...
                'Expected total experiment time for one full run: ' algorithm1.TimeEst];
            
            app.ExperimentParamsLabel.Text = [ParameterText_1,newline,ParameterText_2,newline,ParameterText_3];
            
        end
        
        function SelectAndPlotData(app)
            
            [fname,dirname] = uigetfile();
            loadedData = load([dirname,fname]);
            % now plot the loaded data
            setappdata(app.LFPGraph,'dataloaded',true);
            setappdata(app.LFPGraph,'staticdata',loadedData.Seizure(:,1));
            %try
            plot(app.LFPGraph,loadedData.Seizure(:,1)); % this will prob needed changing from x.Seizure because that is specific to the example data
            disp('Plotted the data.')
            %try
            processedData = algorithmRun(app,loadedData.Seizure(:,1));
            disp('algrithm processed')
            %catch ME2
            warning('Applying the algorithm to the loaded Data has resulted in errors. Please check your algorithm parameters and make a selection if you havent already, or check your data input file.')
            %end
            plot(app.ProcessedGraph,processedData)
            %catch ME
            warning('Issue plotting and processing loaded data, please check if your file is a time series in matlab readable format.')
            %end
            
        end
        
        function replotLoadedData(app)
            %try
            loadedData = getappdata(app.LFPGraph,'staticdata');
            processedData = algorithmRun(app,loadedData);
            %catch ME
            %    warning('Applying the algorithm to the loaded Data has resulted in errors. Please check your algorithm parameters and make a selection if you havent already, or check your data input file.')
            %end
            %try
            plot(app.ProcessedGraph,processedData)
            %catch ME
            %    warning('Issue plotting and processing loaded data, please check if your file is a time series in matlab readable format.')
            %end
        end
        
        function processedData = algorithmRun(app,loadedData)
            alg1 = getappdata(0,'algorithm_1');
            %alg2 = getappdata(app.ExperimentPlanner,'algorithm_2');
            disp('Running the algorithm over the test data: ')
            disp(alg1)
            % check algorithm and apply to the loaded data if closed loop.
            if strcmp(alg1.algorithm,'Phase Shift') || strcmp(alg1.algorithm,'Set Intensity')
                disp('A phase loop algorithm is detected')
                processedData = closedLoopFIR(app,alg1,loadedData);
                if strcmp(alg1.algorithm,'Set Intensity')
                    processedData(processedData>0)=alg1.intensity(1);
                end
            elseif strcmp(alg1.algorithm,'Step functions')
                % If open loop then it just needs to be calculated for the
                % length of the loaded data.
                onepulse = [zeros(1,alg1.pulseoff),ones(1,alg1.pulselen).*alg1.intensity(1)];
                allpulses = repmat(onepulse,1,alg1.pulsespercyc);
                if (length(allpulses)<alg1.OnTime) % pad it out if needed
                    allpulses = [allpulses,zeros(1,alg1.OnTime-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                % now need to repeat for the length of the data.
                % need to find the number of times the stimulation will fit
                % into the data,then repeat for that number, plus another
                % if needed. (So, ceil!)
                allpulses = [allpulses,zeros(1,alg.OffTime)];
                repnums = ceil(length(loadedData)/length(allpulses));
                processedData = repmat(allpulses,repnums);
            elseif strcmp(alg1.algorithm,'Random noise')
                processedData = randn(1,alg1.OnTime)*alg1.gain(1);
            elseif strcmp(alg1.algorithm,'Sinusoidal')
                dt = 0.1*(1/alg1.frequency(1));
                x = 0:dt:alg1.OnTime;  % Needs work...
                processedData = alg1.gain(1).*sin(2.*pi.*alg.frequency(1).*x+alg1.phase(1));
            else
                warning('The algorithm is unrecognised, outputting a zero line.')
                processedData = zeros(1,length(loadedData));
            end
        end
        
        
        function stim = closedLoopFIR(app,alg,data)
            disp('closedLoopFIR has been triggered for the test data.')
            % set up the kernel:
            x=-alg.kernlen+1:1:0;x=x*(1/500); % h needs to be 1/sampling frequency I think? Assuming 500Hz for now. Check the C code!!
            kernelcos=cos(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x);
            kernelsin=sin(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x);
            kernel=kernelcos*cos(alg.phase(1))-kernelsin*sin(alg.phase(1));
            kernel = kernel-mean(kernel);
            stim = zeros(1,length(data));
            % apply the closed loop algorithm here.
            for i = 1:(length(data)-alg.kernlen)
                stim(i+alg.kernlen-1) = sum(kernel'.*data(i:i+alg.kernlen-1)); % convolve with the kernel
                stim(i+alg.kernlen-1) = stim(i+alg.kernlen-1) - alg.threshold; % is this where the threshold is applied in the C code? Need to check this.
                stim(i+alg.kernlen-1) = max(stim(i+alg.kernlen-1),0); % half wave rectify
            end
            disp('Got to the end of the closed loop algorithm function')
        end
        
        function passParameters(app)
            
            alg1 = getappdata(0,'algorithm_1');
            
            if  isempty(app.isSerial)
                disp('No output field detected, so assigning default.')
                assignDefaultConnections(app)
            end
            
            disp('Current output is set to:')
            disp(app.out)
            
            if  ~isfield(alg1,'algorithm') || strcmp(alg1.algorithm,'No Algorithm')
                alg1.algorithm = 'No Algorithm';
                
                value = 19; % trigger 19 for the most basic parameter pass, one value for each parameter.
                parameters = [0, 0 ,0, 0, 0, 0, 0, 0, ...
                    0, 0, 0, 0, 0, 0, 0, 0, 0, 0, 0]; % 19 bytes
            else
                
                if strcmp(alg1.algorithm,'Set Intensity')
                    algoOption = 1;
                else
                    algoOption = 0;
                end
                
                if strcmp(alg1.PseudSeq,'Randomised')
                    seqRand = 100;
                else
                    seqRand = 0; % fixed
                end
                
                % NB: Need to sort the phaseCond, expects values from 0:7
                % but is currently in actual degrees.
                %
                % setIntValB1 and B2 need to be set up, there for an
                % overflow. Similar for kernelLenB1 and B2. Seems the
                % second value is a multiple of 256
                if isfield(alg1,'intensity')
                    [setIntValB1,setIntValB2] = convert2Bytes(app,alg1.intensity);
                else
                    setIntValB1 = [];
                    setIntValB2 = [];
                end
                if isfield(alg1,'kernlen')
                    [kernelLenB1,kernelLenB2] = convert2Bytes(app,alg1.kernlen);
                else
                    kernelLenB1 = 0;
                    kernelLenB2 = 0;
                end
                [onTimeB1,onTimeB2] = convert2Bytes(app,alg1.OnTime);
                [offTimeB1,offTimeB2] = convert2Bytes(app,alg1.OffTime);
                
                manLED = 0;
                manLEDLvlB1 = 0;
                manLEDLvlB2 = 0;
                dutyCycle = 0;
                
                parameters = [algoOption, seqRand ,alg1.phase, seqRand, alg1.gain, alg1.frequency,...
                    alg1.threshold, setIntValB1, setIntValB2, kernelLenB1, kernelLenB2, dutyCycle,...
                    onTimeB1, onTimeB2, offTimeB1, offTimeB2, manLED, manLEDLvlB1, manLEDLvlB2];
                
                value = length(parameters)+seqRand;
            end
                        
            if app.isSerial
                response = sendTrigger(app,value);
                if response
                    write(app.out,parameters,'uint8');
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated');
                else
                    warning('Trigger failed to send, attempting to send parameters anyway.')
                    app.ConnectionstatusLamp.Color = 'red';
                    try
                        fwrite(fid,parameters);
                        app.ConnectionstatusLamp.Color = 'green';
                        disp('Algorithm successfully updated, however trigger may have been missed.');
                    catch
                        warning('Parameters send to fail.')
                    end
                    disp(parameters)
                end
            else
                try
                    fid = fopen(app.out,'w');
                    disp(fid);
                catch ME1
                    warning('file open failed');
                    try
                        fid = fopen('test.bin','w');
                    catch ME2
                        warning('Tried opening test file and this also failed.')
                    end
                end
                response = sendTrigger(app,value);
                if response
                    fwrite(fid,parameters);
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated');
                    fclose(fid);
                else
                    warning('Trigger failed to send, attempting to send parameters anyway.')
                    app.ConnectionstatusLamp.Color = 'red';
                    try
                        fwrite(fid,parameters);
                        app.ConnectionstatusLamp.Color = 'green';
                        disp('Algorithm successfully updated, however trigger may have been missed.');
                    catch
                        warning('Parameters send to fail.')
                    end
                    disp(parameters)
                end
            end


            %!!! We need an addtional command to the microcontroller
            %which receives its version number and an acknowledge that
            %these parameters have been updated.
            
        end
        
    end
    
    methods (Access = private)
        
        function [B1,B2] = convert2Bytes(app,value)
            % expecting an integer value
            value = ceil(abs(value));
            if value>255
                B2 = floor(value/256);
                if B2 > 256 % this means we have overflowed again!
                    warning('Parameter value greater than 2^16 passed, this will need code modification to account for overflowing, for now capping value at 2^16.')
                    B2 = 255;
                    B1 = 255;
                end
                B1 = value - B2*256;
            else
                B2 = 0;
                B1 = value;
            end
        end


        function response = sendTrigger(app,value)
            % this function should send a trigger to the controller based
            % on the given input value. If the value is >255 then it is too
            % big!
            if abs(value)<256
                
                if isempty(app.isSerial)
                   assignDefaultConnections(app);
                end
                
                if  app.isSerial
                    disp('Sending trigger to serial')
                    write(app.out,value,'uint8')
                    response = true;
                else
                    disp('Sending trigger to static file')
                    %try
                    fid = fopen(app.out,'w');
                    fwrite(fid,value);
                    response = true;
                    fclose(fid);
                end
                disp(['Trigger value of ' num2str(value) ' sent to output.'])
            else
                warning('the trigger value is too large to store in a single byte, this cannot be sent.')
                response = false;
            end
        end
        
        function getMicroVersion(app)
            % A function to get the microcontroller version information.
            sendTrigger(app,5); % Using 5 as the command for getting the version. As 5 is V in numerals..
            
            % recieve input
            if app.isSerial
                bin_msg = read(app.input, app.inputBufferSize, 'uint8');
            else
                try
                    bin_msg = read(app.input);
                catch msg1
                    warning('Failed to read input, no version has been sent.')
                    bin_msg = '01011000 01011000';% XX 
                end
            end
            
            % convert to ascii
            %ascii_msg_decoded=char(bin2dec(bin_msg)); % ***** NEEDS A FIX *****
            % Add to the GUI and save file
            %app.XXLabel_2.Text = ascii_msg_decoded;
            
        end
        
        function metadata = getMetaData(app)
            microversion = ['Connected Controller Version: To Be Implemented']; % string(app.XXLabel_2.Text)];
            dateTime = string(clock);
            GUIversion = string(app.GUISoftwareVersionLabel.Text);
            if app.isSerial
                 inpt = ['Serial input on port %s with baud rate %i ',app.input.Port,app.input.BaudRate];
                 outpt = ['Serial output on port %s with baud rate %i ',app.input.Port,app.input.BaudRate];
            else
                inpt = ['Input details: %s', string(app.input)];
                outpt = ['Output details: %s', string(app.out)];
            end
            metadata = [microversion GUIversion dateTime inpt outpt app.ExperimentParamsLabel.Text 'CANDO project Newcastle University'];
            
        end
        
        
        function assignDefaultConnections(app)
            % Check if there is a serial port connected, if so use that.
            % Otherwise use a file.
            delete(instrfind);
            sprt = serialportlist;
            
            if isempty(sprt) % If there are no serial devices connected then use a static file
                disp('No serial devices selected, so please select a static file as an output:')
                [fname,dirname] = uiputfile();
                savedata = [dirname,fname];
                app.out = savedata;
                disp('Output file selected is:')
                disp(savedata)
                app.isSerial = false;
                
                disp('No serial devices selected, so please select an input file:')
                [fname,dirname] = uigetfile();
                app.input = [dirname,fname];
                disp('Test signal selected from file:')
                disp(app.input)                
                
            else % Otherwise go ahead and assign the serial port to both input and out!
                disp('Serial device detected and assigned as input and output.')
                delete(instrfind);
                % Serial communication settings
                Baud_Rate = 115219;
                Data_Bits = 8;
                Stop_Bits = 1;
                Parity = 'none';
                % Setup a serial port and connect to it
                % The appropriate port name should be provided, ideally
                % autodetected 
                sprt = serialportlist;
                app.out = serialport(sprt,Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
                %baudRate   = double(230400); %256000);
                %app.out = serialport(sprt, baudRate);
                app.isSerial = true;
                app.input = app.out; %make this a pointer to the same serial port
            end
            
        end
        
%         function readAndPlotSerial(app,src)
%             % Read the incoming data:
%             dataBuffer = read(src, app.inputBufferSize, 'uint8');
%             dataPoints = inputBuffSize/app.dataLength;
%             byteSequence = 1:dataPoints;
%             % preallocate for speed:
%             LFPData          = zeros(1,dataPoints);
%             AlgValues        = zeros(1,dataPoints);
%             phaseCondition   = zeros(1,dataPoints);
%             time             = zeros(1,dataPoints);
%             gain             = zeros(1,dataPoints);
%             IntensAlg        = zeros(1,dataPoints);
%             Algorithm        = zeros(1,dataPoints);
%             for datapoint = 1:dataPoints
%                 % extract the bytes associated with the data point
%                 dataBytes = dataBuffer(byteSequence);
%                 % convert the byte sequence to a binary sequence
%                 binVals = [];
%                 for n = 1:dataPoints
%                     binVals = [binVals, dec2bin(dataBytes(n),8)];
%                 end
%                 
%                 % Byte 1: bits 0-7:     LFP Data
%                 % Byte 2: bits 8-15:    not used
%                 % Byte 3: bits 0-7:     Algorithm values
%                 % Byte 4: bits 8-11:    Algorithm values (cont)
%                 % Byte 4: bits 12-15:   Phase condition
%                 % Byte 5: bits 0-7:     Time
%                 % Byte 5: bits 8-9:     Time (cont)
%                 % Byte 6: bits 10-13:   Gain
%                 % Byte 6: bits 14:      Algo: 0 = original, 1 = set intensity
%                 % Byte 6: bits 15:      Algorithm ON/OFF
%                 LFPData(datapoint)          = bin2dec(binVals(1:8));
%                 AlgValues(datapoint)        = bin2dec(binVals(17:28));
%                 phaseCondition(datapoint)   = bin2dec(binVals(29:32));
%                 time(datapoint)             = bin2dec(binVals(33:42));
%                 gain(datapoint)             = bin2dec(binVals(43:46));
%                 IntensAlg(datapoint)        = binVals(47);
%                 Algorithm(datapoint)        = binVals(48);
%                 
%                 LFPData          = [LFPData; bin2dec(binVals(1:8))];
%                 AlgValues        = [AlgValues; bin2dec(binVals(9:16))];
%                 
%                 byteSequence = byteSequence + app.dataLength;
%             end
%             % ----------------------------------------
%             % Prepare the data for display
%             % ----------------------------------------
%             % convert LFPData into uV
%             LFPData = LFPData ./ 255 .* 3.3;
%             %
%             % Convert phase condition to degrees
%             phaseCondition = phaseCondition .* 45;
%             t_sequence = 1:500;
%             LFPData_sequence = LFPData(2:501);
%             ProcessedData_sequence = AlgValues(2:501);
%             AlgState_sequence = phaseCondition;
%             % ----------------------------------------
%             % Append data to struct
%             % ----------------------------------------
%             data.index          = [data.index; app.index];
%             data.LFPData        = [data.LFPData; LFPData];
%             data.AlgValues      = [data.AlgValues; AlgValues];
%             data.phaseCondition = [data.phaseCondition; phaseCondition];
%             data.time           = [data.time; time];
%             data.gain           = [data.gain; gain];
%             data.IntensAlg      = [data.IntensAlg; IntensAlg];
%             data.Algorithm      = [data.Algorithm; Algorithm];
%             % ----------------------------------------
%             % Plot the data
%             % ----------------------------------------
%             % Plot the incoming data (Top graph)
%             plot(app.LFPGraph, t_sequence, LFPData_sequence);
%             pause(0.00001);
%             % Plot the processed data (middle graph)
%             plot(app.ProcessedGraph, t_sequence, ProcessedData_sequence);
%             pause(0.00001);
%             % Plot the Algorithm state overlaid on the LFP
%             hold(app.LFPGraph)
%             plot(app.LFPGraph, t_sequence, AlgState_sequence);
%             pause(0.00001);
%             % ----------------------------------------
%             % Save data to file
%             % ----------------------------------------
%             fname      = sprintf ('%s %i', app.filename, app.index);
%             matfile    = fullfile(app.path, fname);
%             save(matfile,'LFPData','AlgValues','phaseCondition','app.index');
%             app.index = app.index + 1;
%         end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {661, 661, 661};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {661, 661};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {22, '1x', 400};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            % This is the main 'GO' function.
            % It is operated with a toggle button which defines whether to run
            % the system or stop the system.
            
            
            ONState = app.StartButton.Value;
            
            if ONState == 1
                
                app.StartButton.BackgroundColor = 'Red';
                app.StartButton.Text = 'Stop';
                
                passParameters(app) % Pass the parameters to the microcontroller
            
                getMicroVersion(app) % Get the microcontroller version (Or C code version)
                
                % Get the time and date and use them to define a default data name
                dateandtime = clock;
                app.filename = strcat(num2str(dateandtime(1)),'_', num2str(dateandtime(2)), '_', num2str(dateandtime(3)),'_Data');
                [~, path]  = uiputfile('1','Save Workspace As');
                app.path = path;
                fname      = sprintf ('%s metadata', app.filename);
                matfile    = fullfile(path, fname);
                metadata = getMetaData(app);
                save(matfile,'metadata');
                
                
                if ~app.isSerial  % If we aren't using a serial connection
                    fid = fopen(app.input,'r+'); % Open the file.
                    recordFromFile = 1; % Set the following condition, record from file, to be true.
                    app.ConnectionstatusLamp.Color = 'green'; % Indicate a successful connection
                    sendTrigger(app,1); % trigger to ask the microcontroller to send data packet.
                    while(recordFromFile)
                        dataBuffer = fread(fid,app.inputBufferSize, 'uint8'); % Read the incoming signal from file
                        fname      = sprintf ('%s %i', app.filename, app.index); % Make the filename
                        matfile    = fullfile(app.path, fname); % Add to file path
                        save(matfile,'dataBuffer'); % Save
                        app.index = app.index + 1; % Increase index for dynamic file naming.
                        pause(0.1)
                    end
                    
                else % If we are using a serial port connection...
                    app.ConnectionstatusLamp.Color = 'green'; % Indicate this with the connection lamp.
                    src = app.input;
                    src.UserData = app;
                    configureCallback(src,"byte",app.inputBufferSize,@readAndPlotSerial) % Trigger a read when there is enough data available incoming
                    sendTrigger(app,1); % trigger to ask the microcontroller to send data packet
                end
            else
                app.StartButton.BackgroundColor = 'Green';
                app.StartButton.Text = 'Start';
                sendTrigger(app,2) % Trigger controller to stop the data transfer
                if app.isSerial
                    configureCallback(app.input,"off") % Stop the callback function
                    delete(instrfind);
                else
                    recordFromFile = 0; % Set this to false to break the loop.
                end
            end
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            
            % This function updates the algorithm parameters on the
            % microcontroller. It gives an error message if a connection
            % cannot be made.
            
            % First off, load the experiment planner window:
            disp('Loading the experiment planner')
            app.ExperimentPlanner = ExperimentPlanner_V15a(app);
            
            
        end

        % Callback function
        function StopButtonValueChanged(app, event)
            
        end

        % Value changed function: InputselectDropDown
        function InputselectDropDownValueChanged(app, event)
            value = app.InputselectDropDown.Value;
            
            if strcmp(value,'Static File Input')
                SelectAndPlotData(app)
            elseif strcmp(value,'Linux Test Signal')
                [fname,dirname] = uigetfile();
                app.input = [dirname,fname];
                disp('Test signal selected from file:')
                disp(app.input)
            else
                delete(instrfind);
                % Serial communication settings
                Baud_Rate = 115219;
                Data_Bits = 8;
                Stop_Bits = 1;
                Parity = 'none';
                % Setup a serial port and connect to it
                % The appropriate port name ('COM14' in this case) should be provided
                sprt = serialportlist;
                app.input = serialport(sprt,Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
                disp('Serial port input selected')
            end
            
        end

        % Value changed function: ONtimeValue
        function ONtimeValueValueChanged(app, event)
            value = app.ONtimeValue.Value;
            
            alg1 = getappdata(0,'algorithm_1');
            alg1.OnTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            
            passParameters(app)
        end

        % Value changed function: OFFtimeValue
        function OFFtimeValueValueChanged(app, event)
            value = app.OFFtimeValue.Value;
            
            alg1 = getappdata(0,'algorithm_1');
            alg1.OffTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            
            passParameters(app);
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            
            % Need to get the value attached to the algorithm data, and
            % into the writing.
            alg1 = getappdata(0,'algorithm_1');
            alg1.threshold = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
            
            if val
                replotLoadedData(app);
            end
            
            passParameters(app)
        end

        % Value changed function: OutputselectDropDown
        function OutputselectDropDownValueChanged(app, event)
            value = app.OutputselectDropDown.Value;
            
            if strcmp(value,'Static File Output') % if we want to output to a file on the computer, rather than to the microcontroller
                % trigger the file open function, and then have this saved
                % as the output. When Start is pushed or whenever the
                % f.write is called it needs to write to a file instead of
                % the default serial. Need to ammend functions calling
                % f.write (passParameters and Start Button call back)
                [fname,dirname] = uiputfile();
                savedata = [dirname,fname];
                app.out = savedata;
                disp('Output file selected is:')
                disp(savedata)
                app.isSerial = false;
            else

                delete(instrfind);
                % Serial communication settings
                Baud_Rate = 115219;
                Data_Bits = 8;
                Stop_Bits = 1;
                Parity = 'none';
                % Setup a serial port and connect to it
                % The appropriate port name ('COM14' in this case) should be provided
                sprt = serialportlist;
                app.out = serialport(sprt,Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
                app.isSerial = true;
                
            end
            
            
            if ~isempty(app.out)
                disp('Output assignment successful.')
            else
                warning('Check output assignment.')
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.LFPGraph = uiaxes(app.CenterPanel);
            title(app.LFPGraph, 'Original Recording (LFP)')
            xlabel(app.LFPGraph, 'time (ms)')
            ylabel(app.LFPGraph, 'Voltage (uV)')
            app.ProcessedGraph = uiaxes(app.CenterPanel);
            title(app.ProcessedGraph, 'Processed Output')
            xlabel(app.ProcessedGraph, 'time (ms)')
            ylabel(app.ProcessedGraph, 'Algorithm value')
            app.StartButton = uibutton(app.CenterPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.CurrentstatusPanel = uipanel(app.CenterPanel);
            app.CurrentstatusPanel.Title = 'Current status';
            app.AlgorithmONOFFLampLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmONOFFLampLabel.Text = 'Algorithm ON/OFF';
            app.AlgorithmONOFFLamp = uilamp(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel.Text = 'Algorithm status ';
            app.AlgorithmLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.ConnectionstatusLampLabel = uilabel(app.CurrentstatusPanel);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.CurrentstatusPanel);
            app.PhaseLabel = uilabel(app.CurrentstatusPanel);
            app.PhaseLabel.Text = 'Phase';
            app.degLabel_2 = uilabel(app.CurrentstatusPanel);
            app.degLabel_2.Text = '0 deg';
            app.IntensityLabel = uilabel(app.CurrentstatusPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.Label_6 = uilabel(app.CurrentstatusPanel);
            app.Label_6.Text = '0';
            app.RightPanel = uipanel(app.GridLayout);
            app.GUISoftwareVersionLabel = uilabel(app.RightPanel);
            app.GUISoftwareVersionLabel.Text = 'GUI Software Version';
            app.MicrocontrollerSoftwareVersionLabel = uilabel(app.RightPanel);
            app.MicrocontrollerSoftwareVersionLabel.Text = 'Microcontroller Software Version';
            app.Label = uilabel(app.RightPanel);
            app.Label.Text = '15';
            app.XXLabel_2 = uilabel(app.RightPanel);
            app.XXLabel_2.Text = 'XX';
            app.QuickConfigOptionsPanel = uipanel(app.RightPanel);
            app.QuickConfigOptionsPanel.Title = 'Quick Config Options';
            app.ONtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.ONtimeEditFieldLabel.Text = 'ON time';
            app.ONtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ONtimeValue.ValueChangedFcn = createCallbackFcn(app, @ONtimeValueValueChanged, true);
            app.msLabel = uilabel(app.QuickConfigOptionsPanel);
            app.msLabel.Text = '(ms)';
            app.OFFtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.OFFtimeEditFieldLabel.Text = 'OFF time';
            app.OFFtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.OFFtimeValue.ValueChangedFcn = createCallbackFcn(app, @OFFtimeValueValueChanged, true);
            app.msLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.msLabel_2.Text = '(ms)';
            app.ThresholdEditFieldLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.ThresholdEditFieldLabel_2.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ConfigureButton = uibutton(app.RightPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.UserFeedbackText = uilabel(app.RightPanel);
            app.UserFeedbackText.Visible = 'off';
            app.UserFeedbackText.Text = 'User Feedback';
            app.InputselectDropDownLabel = uilabel(app.RightPanel);
            app.InputselectDropDownLabel.Text = 'Input select';
            app.InputselectDropDown = uidropdown(app.RightPanel);
            app.InputselectDropDown.Items = {'Intan', 'CANDO 3', 'Linux Test Signal', 'Signal Generator', 'Static File Input'};
            app.InputselectDropDown.ValueChangedFcn = createCallbackFcn(app, @InputselectDropDownValueChanged, true);
            app.InputselectDropDown.Value = 'Intan';
            app.OutputselectDropDownLabel = uilabel(app.RightPanel);
            app.OutputselectDropDownLabel.Text = 'Output select';
            app.OutputselectDropDown = uidropdown(app.RightPanel);
            app.OutputselectDropDown.Items = {'Commercial LED', 'CANDO 3 v1', 'CANDO 3 v2', 'Oscilloscope', 'Static File Output'};
            app.OutputselectDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputselectDropDownValueChanged, true);
            app.OutputselectDropDown.Value = 'Commercial LED';
            app.ExperimentalParametersPanel = uipanel(app.RightPanel);
            app.ExperimentalParametersPanel.Title = 'Experimental Parameters';
            app.ExperimentParamsLabel = uilabel(app.ExperimentalParametersPanel);
            app.ExperimentParamsLabel.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CANDO_control_GUI_V15b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end