classdef CANDO_control_GUI_V22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        CenterPanel                     matlab.ui.container.Panel
        LFPGraph                        matlab.ui.control.UIAxes
        ProcessedGraph                  matlab.ui.control.UIAxes
        StartButton                     matlab.ui.control.StateButton
        CurrentstatusPanel              matlab.ui.container.Panel
        AlgorithmONOFFLampLabel         matlab.ui.control.Label
        AlgorithmONOFFLamp              matlab.ui.control.Lamp
        AlgorithmstatusLabel            matlab.ui.control.Label
        AlgorithmLabel                  matlab.ui.control.Label
        ConnectionstatusLampLabel       matlab.ui.control.Label
        ConnectionstatusLamp            matlab.ui.control.Lamp
        PhaseLabel                      matlab.ui.control.Label
        degLabel_2                      matlab.ui.control.Label
        IntensityLabel                  matlab.ui.control.Label
        IntensityValue                  matlab.ui.control.Label
        GainLabel                       matlab.ui.control.Label
        GainValue                       matlab.ui.control.Label
        StaggeredRecordingTimeEditFieldLabel  matlab.ui.control.Label
        StaggeredRecordingTimeEditField  matlab.ui.control.NumericEditField
        TimeunitsDropDownLabel          matlab.ui.control.Label
        TimeunitsDropDown               matlab.ui.control.DropDown
        RefreshrateSliderLabel          matlab.ui.control.Label
        RefreshrateSlider               matlab.ui.control.Slider
        ChannelViewDropDownLabel        matlab.ui.control.Label
        ChannelViewDropDown             matlab.ui.control.DropDown
        GUISoftwareVersionLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        MicrocontrollerSoftwareVersionLabel  matlab.ui.control.Label
        XXLabel_2                       matlab.ui.control.Label
        AmplifierGainCMOSDropDownLabel  matlab.ui.control.Label
        AmplifierGainCMOSDropDown       matlab.ui.control.DropDown
        FullCyclesCompletedLabel        matlab.ui.control.Label
        fullcycs                        matlab.ui.control.Label
        WarningLabel                    matlab.ui.control.Label
        MicrocontrollerResetButton      matlab.ui.control.Button
        MicrocontrollerCalibrationButton  matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        QuickConfigOptionsPanel         matlab.ui.container.Panel
        ONtimeEditFieldLabel            matlab.ui.control.Label
        ONtimeValue                     matlab.ui.control.NumericEditField
        sLabel                          matlab.ui.control.Label
        OFFtimeEditFieldLabel           matlab.ui.control.Label
        OFFtimeValue                    matlab.ui.control.NumericEditField
        sLabel_2                        matlab.ui.control.Label
        ThresholdEditFieldLabel_2       matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        IntensityEditFieldLabel         matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.NumericEditField
        AlgorithmDropDownLabel          matlab.ui.control.Label
        AlgorithmDropDown               matlab.ui.control.DropDown
        UseOnOfftimesSwitchLabel        matlab.ui.control.Label
        UseOnOfftimesSwitch             matlab.ui.control.Switch
        InputselectDropDownLabel        matlab.ui.control.Label
        InputselectDropDown             matlab.ui.control.DropDown
        OutputselectDropDownLabel       matlab.ui.control.Label
        OutputselectDropDown            matlab.ui.control.DropDown
        TabGroup                        matlab.ui.container.TabGroup
        AutoTab                         matlab.ui.container.Tab
        ExperimentalParametersPanel     matlab.ui.container.Panel
        ExperimentParamsLabel           matlab.ui.control.Label
        ManualTab                       matlab.ui.container.Tab
        StimulationtypeSwitchLabel      matlab.ui.control.Label
        StimulationtypeSwitch           matlab.ui.control.Switch
        DirectstimulationlevelSliderLabel  matlab.ui.control.Label
        DirectstimulationlevelSlider    matlab.ui.control.Slider
        Lamp                            matlab.ui.control.Lamp
        StimulationONmanualoverrideButton  matlab.ui.control.StateButton
        PortselectDropDownLabel         matlab.ui.control.Label
        PortselectDropDown              matlab.ui.control.DropDown
        UserFeedbackText                matlab.ui.control.Label
        ConfigureButton                 matlab.ui.control.Button
        ElectrodeselectDropDownLabel    matlab.ui.control.Label
        ElectrodeselectDropDown         matlab.ui.control.DropDown
        LEDselectDropDownLabel          matlab.ui.control.Label
        LEDselectDropDown               matlab.ui.control.DropDown
        SelectOptrodeButton             matlab.ui.control.Button
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ExperimentPlanner % Placeholder for the experiment planner gui
        OptrodeSelector % Placeholder for the optrode selector gui
        algorithm1 % structure to store the algorithm results from the experimental planner
        algorithm2 % As above for the second algorithm, if relevant.
        out = 0; % Default nothing here.
        input
        isSerial
        index = 1 % Iterator for the main recording sequence
        %Ts = 0.001;
        %t =1; % Wanting 1 second
        %nos = 1:Ts:t;
        dataLength = 10 % The length of a single datapacket from the microcontroller in bytes
        path % save directory path
        inputBufferSize = 10000 % app.dataLength(size(app.nos)); %6000 % The amount of incoming data to accrue before processing
        filename % root filename for saved files
        matfile
        matfilecnt = 0;
        manLED = 0; % manual or automatic (algorithm) control
        manLEDLvlB1 = 0; % light on level for manual control
        manLEDLvlB2 = 0; % light on level for manual control (second byte)
        dutyCycle = 1; % Are we doing a manual or automatic time keeping
        manLEDisOn = 2;
        portselect = 0; % Flag to track whether a serial port has been manually selected. 
        ampGain = 2100; % default to 2100, this should only be relevant if ASIC is connected.
        ampGainVal = 2;
        loopCnt = 0;
        hasVersion = 0;
        dataBuffer = [];
        errlog = [pwd,'gui_error_log']; % save name for the error logger.
    end
    
    properties (Access = public)
        Listening = 0 % Flag to stop or retain the listening loops.
    end
    
    methods (Access = public)
        
        function experimentWriter(app,algorithm1,algorithm2,varargin)
            % a function to add text to the panel which holds the
            % experiment plan in the main app.
            % Needs to be called by the experiment planner and by the quick
            % update functions on the main gui if the parameters are
            % changed.
            if (strcmp(algorithm2.algorithm,'No Algorithm'))
                just1 = true;
            else
                just1 = false;
            end
            ParameterText_1 = '';
            ParameterText_2 = '';
            % if there is something selected for algorithm 1:
            if (strcmp(algorithm1.algorithm,'No Algorithm'))
                if just1
                    ParameterText_1 = "No algorithms selected, the control box will record data only.";
                end
                % if there is no algorithm selected for the first algorithm
                % but there is for the second, then do nothing here and the
                % text will be populated by the second algorithm.
            else % If we have selected an algorithm..
                if just1
                    ParameterText_1 = ['Algorithm: ' algorithm1.algorithm newline];
                else
                    ParameterText_1 = ['First Algorithm: ' algorithm1.algorithm newline];
                end
                % if have multiple values for phase, extract these.
                if length(algorithm1.phase)>1
                    phases = algorithm1.phase(1):algorithm1.phase(2):algorithm1.phase(3);
                else 
                    phases = algorithm1.phase;
                end
                % if have multiple values for phase, extract these.
                if length(algorithm1.gain)>1
                    gains = algorithm1.gain(1):algorithm1.gain(2):algorithm1.gain(3);
                else 
                    gains = algorithm1.gain;
                end
                
                if (strcmp(algorithm1.algorithm,'Phase Shift'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(gains) newline ...
                        'Phase: ' num2str(phases) newline];
                elseif (strcmp(algorithm1.algorithm,'Set Intensity')) || (strcmp(algorithm1.algorithm,'Compare Both'))
                    
                    % if have multiple values for intensity, extract these.
                    if isfield(algorithm1, 'intensity')       
                        if length(algorithm1.intensity)>1
                            intensities = algorithm1.intensity(1):algorithm1.intensity(2):algorithm1.intensity(3);
                        else 
                            intensities = algorithm1.intensity;
                        end
                    else
                        algorithm1.intensity = 1;
                        intensities = algorithm1.intensity;
                    end
                        
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Threshold: ' num2str(algorithm1.threshold) newline ...
                        'Kernel length: ' num2str(algorithm1.kernlen) newline ...
                        'Gain: ' num2str(gains) newline ...
                        'Phase: ' num2str(phases) newline ...
                        'Intensity: ' num2str(intensities) newline];
                elseif (strcmp(algorithm1.algorithm,'Step functions'))
                    ParameterText_1 = [ParameterText_1,'Pulse length: ' num2str(algorithm1.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm1.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm1.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm1.pulsepercyc) newline];
                elseif (strcmp(algorithm1.algorithm,'Random noise'))
                    ParameterText_1 = [ParameterText_1,'Gain: ' num2str(algorithm1.gain) newline];
                elseif (strcmp(algorithm1.algorithm,'Sinusoidal'))
                    ParameterText_1 = [ParameterText_1,'Frequency: ' num2str(algorithm1.frequency) newline...
                        'Gain: ' num2str(gains) newline ...
                        'Phase: ' num2str(phases) newline];
                else
                    ParameterText_1 = [ParameterText_1,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end
            
            if (just1)
                % nothing to add
            elseif (strcmp(algorithm1.algorithm,'No Algorithm')) % if only the second algorithm
                ParameterText_2 = ['Algorithm: ' algorithm2.algorithm newline];
            else
                ParameterText_2 = ['Second Algorithm: ' algorithm2.algorithm newline];
                if (strcmp(algorithm2.algorithm,'Phase Shift'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                elseif (strcmp(algorithm2.algorithm,'Set Intensity'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Threshold: ' num2str(algorithm2.threshold) newline ...
                        'Kernel length: ' num2str(algorithm2.kernlen) newline ...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline];
                elseif (strcmp(algorithm2.algorithm,'Step functions'))
                    ParameterText_2 = [ParameterText_2,'Pulse length: ' num2str(algorithm2.pulselen) newline...
                        'Pulse off time: ' num2str(algorithm2.pulseoff) newline ...
                        'Intensity: ' num2str(algorithm2.intensity) newline ...
                        'Pulses per cycle: ' num2str(algorithm2.pulsepercyc) newline];
                elseif (strcmp(algorithm2.algorithm,'Random noise'))
                    ParameterText_2 = [ParameterText_2,'Gain: ' num2str(algorithm2.gain) newline];
                elseif (strcmp(algorithm2.algorithm,'Sinusoidal'))
                    ParameterText_2 = [ParameterText_2,'Frequency: ' num2str(algorithm2.frequency) newline...
                        'Gain: ' num2str(algorithm2.gain) newline ...
                        'Phase: ' num2str(algorithm2.phase) newline];
                else
                    ParameterText_2 = [ParameterText_2,'Something appears to have gone wrong, the parameters for this algorithm are missing.'];
                end
            end
            
            ParameterText_3 = ['Stimulation on time: ' num2str(algorithm1.OnTime) newline 'Stimulation off time: ' ...
                num2str(algorithm1.OffTime) newline algorithm1.PseudSeq newline newline ...
                'Expected total experiment time for one full run: ' algorithm1.TimeEst];
            
            app.ExperimentParamsLabel.Text = [ParameterText_1,newline,ParameterText_2,newline,ParameterText_3];
            
            % Update the quick update parameter fields to match:
            app.GainEditField.Value = algorithm1.gain(1);
            app.ONtimeValue.Value = algorithm1.OnTime;
            app.OFFtimeValue.Value = algorithm1.OffTime;
            app.ThresholdEditField.Value = algorithm1.threshold;
            if (strcmp(algorithm1.algorithm,'Set Intensity'))
                app.IntensityEditField.Value = algorithm1.intensity(1);
            end
            
        end
        
        function SelectAndPlotData(app)
            
            [fname,dirname] = uigetfile();
            loadedData = load([dirname,fname]);
            % now plot the loaded data
            setappdata(app.LFPGraph,'dataloaded',true);
            setappdata(app.LFPGraph,'staticdata',loadedData.Seizure(:,1));
            %try
            plot(app.LFPGraph,loadedData.Seizure(:,1)); % this will prob needed changing from x.Seizure because that is specific to the example data
            disp('Plotted the data.')
            %try
            processedData = algorithmRun(app,loadedData.Seizure(:,1));
            disp('algrithm processed')
            %catch ME2
            warning('Applying the algorithm to the loaded Data has resulted in errors. Please check your algorithm parameters and make a selection if you havent already, or check your data input file.')
            %end
            plot(app.ProcessedGraph,processedData)
            %catch ME
            warning('Issue plotting and processing loaded data, please check if your file is a time series in matlab readable format.')
            %end
            
        end
        
        function replotLoadedData(app)
            loadedData = getappdata(app.LFPGraph,'staticdata');
            processedData = algorithmRun(app,loadedData);
            plot(app.ProcessedGraph,processedData)
        end
        
        function processedData = algorithmRun(app,loadedData)
            alg1 = getappdata(0,'algorithm_1');
            %alg2 = getappdata(app.ExperimentPlanner,'algorithm_2');
            disp('Running the algorithm over the test data: ')
            disp(alg1)
            % check algorithm and apply to the loaded data if closed loop.
            if strcmp(alg1.algorithm,'Phase Shift') || strcmp(alg1.algorithm,'Set Intensity')
                disp('A phase loop algorithm is detected')
                processedData = closedLoopFIR(app,alg1,loadedData);
                if strcmp(alg1.algorithm,'Set Intensity')
                    processedData(processedData>0)=alg1.intensity(1);
                end
            elseif strcmp(alg1.algorithm,'Step functions')
                % If open loop then it just needs to be calculated for the
                % length of the loaded data.
                onepulse = [zeros(1,alg1.pulseoff),ones(1,alg1.pulselen).*alg1.intensity(1)];
                allpulses = repmat(onepulse,1,alg1.pulsespercyc);
                if (length(allpulses)<alg1.OnTime) % pad it out if needed
                    allpulses = [allpulses,zeros(1,alg1.OnTime-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                % now need to repeat for the length of the data.
                % need to find the number of times the stimulation will fit
                % into the data,then repeat for that number, plus another
                % if needed. (So, ceil!)
                allpulses = [allpulses,zeros(1,alg.OffTime)];
                repnums = ceil(length(loadedData)/length(allpulses));
                processedData = repmat(allpulses,repnums);
            elseif strcmp(alg1.algorithm,'Random noise')
                processedData = randn(1,alg1.OnTime)*alg1.gain(1);
            elseif strcmp(alg1.algorithm,'Sinusoidal')
                dt = 0.1*(1/alg1.frequency(1));
                x = 0:dt:alg1.OnTime;  % Needs work...
                processedData = alg1.gain(1).*sin(2.*pi.*alg.frequency(1).*x+alg1.phase(1));
            else
                warning('The algorithm is unrecognised, outputting a zero line.')
                processedData = zeros(1,length(loadedData));
            end
        end
        
        
        function stim = closedLoopFIR(app,alg,data)
            disp('closedLoopFIR has been triggered for the test data.')
            % set up the kernel:
            x=-alg.kernlen+1:1:0;x=x*(1/500); % h needs to be 1/sampling frequency I think? Assuming 500Hz for now. Check the C code!!
            kernelcos=cos(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x);
            kernelsin=sin(alg.frequency(1)*2*pi*x).*exp(alg.frequency(1)/0.8*x);
            kernel=kernelcos*cos(alg.phase(1))-kernelsin*sin(alg.phase(1));
            kernel = kernel-mean(kernel);
            stim = zeros(1,length(data));
            % apply the closed loop algorithm here.
            for i = 1:(length(data)-alg.kernlen)
                stim(i+alg.kernlen-1) = sum(kernel'.*data(i:i+alg.kernlen-1)); % convolve with the kernel
                stim(i+alg.kernlen-1) = stim(i+alg.kernlen-1) * alg.gain;
                stim(i+alg.kernlen-1) = stim(i+alg.kernlen-1) - alg.threshold; % is this where the threshold is applied in the C code? Need to check this.
                stim(i+alg.kernlen-1) = max(stim(i+alg.kernlen-1),0); % half wave rectify
            end
            disp('Got to the end of the closed loop algorithm function')
        end
        
        function passParameters(app)
            
            alg1 = getappdata(0,'algorithm_1');
            
            if  isempty(app.isSerial)
                disp('No output field detected, so assigning default.')
                assignDefaultConnections(app)
            end
            
            if ~isfield(alg1,'TimeEstNum')
                alg1.TimeEstNum = 0;
            end
            app.fullcycs.Text = '0';
            app.loopCnt = 0;
            
            disp('Current output is set to:')
            disp(app.out)
            
            if  ~isfield(alg1,'algorithm') || strcmp(alg1.algorithm,'No Algorithm')
                alg1.algorithm = 'No Algorithm';
                alg1.PseudSeq = 0; % no loops 
                setappdata(0,'algorithm_1',alg1);
                
                
                value = 11; % trigger 11 to warn the controller of an upcoming parameter packet but without algorithm stuff.
                parameters = [app.manLED, 0 ,0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, app.manLEDLvlB1, app.manLEDLvlB2, app.manLEDisOn, ...
                              0, 0, 0, 0, value]; % 35 bytes
            else
                
                if strcmp(alg1.algorithm,'Set Intensity')
                    algoOption = 1;
                elseif strcmp(alg1.algorithm,'Compare Both')
                    algoOption = 2;
                else
                    algoOption = 0;
                end
                
                if strcmp(alg1.PseudSeq,'Pseudorandom')
                    seqRand = 2; % randomised
                elseif strcmp(alg1.PseudSeq,'Sequential')
                    seqRand = 1; % fixed
                else  %%%% **** NEED TO IMPLEMENT *** %%%%
                    seqRand = 0; % no loops
                end
                
                % NB: Need to sort the phaseCond, expects values from 0:7
                % but is currently in actual degrees.
                %
                % setIntValB1 and B2 need to be set up, there for an
                % overflow. Similar for kernelLenB1 and B2. Seems the
                % second value is a multiple of 256

                if isfield(alg1,'kernlen')
                    [kernelLenB1,kernelLenB2] = convert2Bytes(app,alg1.kernlen);
                else
                    kernelLenB1 = 0;
                    kernelLenB2 = 0;
                end
                % on/ off times
                
                [onTimeB1,onTimeB2]   = convert2Bytes(app,alg1.OnTime);
                [offTimeB1,offTimeB2] = convert2Bytes(app,alg1.OffTime);
                
                % threshold
                [thresholdB1,thresholdB2] = convert2Bytes(app,alg1.threshold);
                
                % phase
                if length(alg1.phase)>1
                    [phaseStartB1,phaseStartB2] = convert2Bytes(app,alg1.phase(1));
                    phaseStep = alg1.phase(2);
                    [phaseEndB1,phaseEndB2] = convert2Bytes(app,alg1.phase(3));
                else
                    [phaseStartB1,phaseStartB2] = convert2Bytes(app,alg1.phase);
                    phaseStep = 0;
                    [phaseEndB1,phaseEndB2] = convert2Bytes(app,alg1.phase);
                end
                
                % gain
                if length(alg1.gain)>1
                    gainStart = alg1.gain(1);
                    gainStep  = alg1.gain(2);
                    gainEnd   = alg1.gain(3);
                else
                    gainStart = alg1.gain;
                    gainStep  = 0;
                    gainEnd   = alg1.gain;
                end
                
                % intensity:
                if isfield(alg1,'intensity')
                    if length(alg1.intensity)>1
                        [intenStartB1,intenStartB2] = convert2Bytes(app,alg1.intensity(1));
                        [intenStepB1,intenStepB2]   = convert2Bytes(app,alg1.intensity(2));
                        [intenStopB1,intenStopB2]   = convert2Bytes(app,alg1.intensity(3));
                    else
                        [intenStartB1,intenStartB2]   = convert2Bytes(app,alg1.intensity(1));
                        intenStepB1  = 0;
                        intenStepB2  = 0;
                        [intenStopB1,intenStopB2]   = convert2Bytes(app,alg1.intensity(1));

                    end
                else
                    intenStartB1 = 0;
                    intenStartB2 = 0;
                    intenStepB1  = 0;
                    intenStepB2  = 0;
                    intenStopB1  = 0;
                    intenStopB2  = 0;
                end
                    
                value = 10; % tell the controller to recieve full parameter packet.
  
                parameters = [app.manLED, algoOption, seqRand, kernelLenB1, kernelLenB2, ...
                              phaseStartB1, phaseStartB2, phaseStep, phaseEndB1, phaseEndB2, ...    
                              alg1.frequency, gainStart, gainStep, gainEnd, intenStartB1, ...
                              intenStartB2, intenStepB1, intenStepB2, intenStopB1, intenStopB2, ...
                              thresholdB1, thresholdB2, app.dutyCycle, onTimeB1, onTimeB2, ...
                              offTimeB1,offTimeB2, app.manLEDLvlB1, app.manLEDLvlB2, app.manLEDisOn,...
                              0, 0, 0, 0, value]; % NB 35 bytes, currently bytes 31:34 are empty.
            
            end
            
            delete(instrfind);
            
            if app.isSerial
                % New version includes
                %the trigger in the first byte of the parameter block.
                try
                    write(app.out,parameters,'uint8');
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated.');
                catch
                    warning('Parameters send to fail.')
                end
                disp(parameters)
            else
                try
                    fid = fopen(app.out,'w');
                    disp(fid);
                catch ME1
                    warning('file open failed');
                    try
                        fid = fopen('test.bin','w');
                    catch ME2
                        warning('Tried opening test file and this also failed.')
                    end
                end
                try
                    fwrite(fid,parameters);
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated.');
                catch
                    warning('Parameters send to fail.')
                end
                disp(parameters)
            end
            
            if isempty(app.filename)
                            % Get the time and date and use them to define a default data name
                dateandtime = clock;
                app.filename = strcat(num2str(dateandtime(1)),'_', num2str(dateandtime(2)), '_', num2str(dateandtime(3)),'_Data');
                [~, app.path]  = uiputfile('1','Save Workspace As');
                fname      = sprintf ('%s metadata', app.filename);
                app.matfile    = fullfile(app.path, fname);
                metadata = getMetaData(app);
                save(app.matfile,'metadata');
            else
                app.matfilecnt = app.matfilecnt + 1;
                metadata   = getMetaData(app);
                save([app.matfile int2str(app.matfilecnt)],'metadata');
            end
                    
            app.dataBuffer = [];
        end
    end
    
    methods (Access = private)
        
        function [B1,B2] = convert2Bytes(app,value)
            % expecting an integer value
            value = ceil(abs(value));
            B1 = bitand(value,0x00FF); % take the first 8 bits
            B2 = bitshift(bitand(value,0xFF00),-8);
            
        end


        function response = sendTrigger(app,value)
            % this function should send a trigger to the controller based
            % on the given input value. If the value is >255 then it is too
            % big!
            % NB: updated for v16 to include a tail of 30 bytes in order to
            % trigger the microcontroller interrupt.
            if abs(value)<256
                dataPacket = [zeros(1,34),value];
                if isempty(app.isSerial)
                   assignDefaultConnections(app);
                end

                if  app.isSerial
                    disp('Sending trigger to serial')
                    write(app.out,dataPacket,'uint8')
                    response = true;
                else
                    disp('Sending trigger to static file')
                    fid = fopen(app.out,'w');
                    fwrite(fid,dataPacket);
                    response = true;
                    fclose(fid);
                end
                disp(['Trigger value of ' num2str(value) ' sent to output.'])
            else
                warning('the trigger value is too large to store in a single byte, this cannot be sent.')
                response = false;
            end
        end
        
        function getMicroVersion(app)
            % A function to get the microcontroller version information.
            sendTrigger(app,5); % Using 5 as the command for getting the version. As 5 is V in numerals..
            
            % recieve input
            if app.isSerial
                % while byte is not equal to termination character
                try
                    version_size = read(app.input, 1,'uint8');  % termination character sent
                    bin_msg = char(read(app.input,version_size,'uint8'));
                catch
                    app.WarningLabel.Text = 'Failed to read version number';
                    app.WarningLabel.Visible = 1;
                    bin_msg = 'ERR';
                    log = [app.WarningLabel.Text,' ',datestr(datetime),','];
                    save(app.errlog,"log",'-append')
                end
            else
                try
                    bin_msg = read(app.input);
                catch msg1
                    warning('Failed to read input, no version has been sent.')
                    bin_msg = '01011000 01011000';% XX 
                    log = [app.WarningLabel.Text,' ',datestr(datetime),','];
                    save(app.errlog,"log",'-append')
                end
            end
            
            % convert to ascii
            %ascii_msg_decoded=char(bin2dec(bin_msg));
            % Add to the GUI and save file
            app.XXLabel_2.Text = bin_msg; 
            
        end
        
        function metadata = getMetaData(app)
            microversion = ['Connected Controller Version:' string(app.XXLabel_2.Text)];
            dateTime = string(clock);
            GUIversion = string(app.GUISoftwareVersionLabel.Text);
            if app.isSerial
                 inpt = ['Serial input on port %s with baud rate %i ',app.input.Port,app.input.BaudRate];
                 outpt = ['Serial output on port %s with baud rate %i ',app.input.Port,app.input.BaudRate];
            else
                inpt = ['Input details: %s', string(app.input)];
                outpt = ['Output details: %s', string(app.out)];
            end
            metadata = [microversion GUIversion dateTime inpt outpt app.ExperimentParamsLabel.Text 'CANDO project Newcastle University'];
            
        end
        
        
        function assignDefaultConnections(app)
            % Check if there is a serial port connected, if so use that.
            % Otherwise use a file.
            
            if app.out == 0 % if nothing has been defined yet
                
                sprt = serialportlist;
                if isempty(sprt) % If there are no serial devices connected then use a static file
                    disp('No serial devices selected, so please select a static file as an output:')
                    [fname,dirname] = uiputfile();
                    savedata = [dirname,fname];
                    app.out = savedata;
                    disp('Output file selected is:')
                    disp(savedata)
                    app.isSerial = false;
                    
                    disp('No serial devices selected, so please select an input file:')
                    [fname,dirname] = uigetfile();
                    app.input = [dirname,fname];
                    disp('Test signal selected from file:')
                    disp(app.input)                
                    
                else % Otherwise go ahead and assign the serial port to both input and out!
                    disp('Serial device detected and assigned as input and output.')
                    delete(instrfind);
                    % Serial communication settings
%                     Baud_Rate = 115219;
                    Baud_Rate = 255940;
                    Data_Bits = 8;
                    Stop_Bits = 1;
                    Parity = 'none';
                    % Setup a serial port and connect to it
                    % The appropriate port name should be provided, ideally
                    % autodetected 
                    sprt = serialportlist;
                    app.out = serialport(sprt(1),Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
                    %baudRate   = double(230400); %256000);
                    %app.out = serialport(sprt, baudRate);
                    app.isSerial = true;
                    app.input = app.out; %make this a pointer to the same serial port
                end
                
            end
            
        end
        
        function serialRead(app)
            app.ConnectionstatusLamp.Color = 'green';
            sendTrigger(app,1); % trigger to ask the microcontroller to send data packet.
            
            while(app.Listening)
                if(app.input.NumBytesAvailable >= app.inputBufferSize)
                    % if we have enough input to process:   
                    % Get the input data stream and put it in the buffer
                    app.dataBuffer = read(app.input, app.inputBufferSize, 'uint8');
                    % ------------------------------------------------
                    % extract data
                    % ------------------------------------------------
                    % The data is in a binary sequence so the data needs to
                    % be acquired as bytes, converted to binary and then
                    % the information extracted for each of the components   
                    dataPoints = app.inputBufferSize/app.dataLength;    
                    byteSequence = 1:app.dataLength;     
                    % preallocate for speed:
                    LFPData          = zeros(1,dataPoints);
                    AlgValues        = zeros(1,dataPoints);  
                    phaseCondition   = zeros(1,dataPoints);
                    time             = zeros(1,dataPoints);
                    gain             = zeros(1,dataPoints);
                    IntensAlg        = zeros(1,dataPoints);
                    Algorithm        = zeros(1,dataPoints);
                    intensity        = zeros(1,dataPoints);
                    for datapoint = 1:dataPoints 
                        % extract the bytes associated with the data point
                        dataBytes = app.dataBuffer(byteSequence);
                        % convert the byte sequence to a binary sequence
                        binVals = [];
                        for n = 1:app.dataLength
                            binVals = [binVals, dec2bin(dataBytes(n),8)];
                        end
                        % Byte 1: bits 1-8:      LFP Data
                        % Byte 2: bits 9-16:     Algorithm values
                        % Byte 3: bits 17-24:    Algorithm values (cont)
                        % Byte 4: bits 25-32:    Phase condition
                        % Byte 5: bits 33:       Phase condition (cont)
                        % Byte 5: bits 39:       Algo: 0 = original, 1 = set intensity
                        % Byte 5: bits 40:       Algorithm ON/OFF 0 = off, 1 = on
                        % Byte 6: bits 41-48:    Gain
                        % Byte 7: bits 49-57:    Set Intensity Value
                        % Byte 8: bits 58-61:    Set Intensity Value
                        % Byte 8: bits 62-65:    Warnings
                        LFPData(datapoint)          = bitshift(dataBytes(2),8) + dataBytes(1);
                        AlgValues(datapoint)        = bitshift(dataBytes(4),8)+dataBytes(3); % Combine bytes 2 and 3
                        phaseCondition(datapoint)   = bitshift(bitand(dataBytes(6),1),8)+dataBytes(5); % Combine bytes 4 and the first bit of byte 5
                        gain(datapoint)             = dataBytes(7);
                        cycleCnt(datapoint)         = str2double(binVals(43));
                        IntensAlg(datapoint)        = str2double(binVals(42));
                        Algorithm(datapoint)        = str2double(binVals(41));
                        intensity(datapoint)        = bitshift(bitand(dataBytes(9),15),8)+dataBytes(8); % Combine bytes 7 and the first 4 bits of byte 8
                        %LFPData          = [LFPData; bin2dec(binVals(1:8))];
                        %AlgValues        = [AlgValues; bin2dec(binVals(9:16))];
                        byteSequence = byteSequence + app.dataLength;
                    end
                    % convert LFPData into uV
                    %LFPData = LFPData ./ 255 .* 3.3;
                    if strcmp(app.InputselectDropDown.Value,'ASIC') 
                        LFPData = (LFPData - 512) / 512 * 3.3 / app.ampGain * 1000000; % to shift incoming to plotting between -3.3:3.3 bytes
                    else
                        LFPData = LFPData / 255 * 3.3; % to shift incoming to plotting between 0:3.3 volts
                    end
                    % Display on the GUI 
                    app.degLabel_2.Text = num2str(phaseCondition(dataPoints));
                    app.IntensityValue.Text = num2str(intensity(dataPoints)); % This needs changing with updated packet!
                    app.GainValue.Text = num2str(gain(dataPoints));
                    
                    tot = sum(cycleCnt);
                    app.fullcycs.Text = num2str(str2double(app.fullcycs.Text) + tot);
                    
                    if Algorithm(dataPoints) % If algorithm is on
                        app.AlgorithmONOFFLamp.Color = 'green';                                         
                    else
                        app.AlgorithmONOFFLamp.Color = 'red';
                    end
                    
                    if IntensAlg(dataPoints) % if this is 1 then the current algorithm is set intensity
                        app.AlgorithmLabel.Text = 'Set Intensity';
                        app.IntensityLabel.HandleVisibility = 'on';
                        app.IntensityLabel.Visible = 1;
                        app.IntensityValue.HandleVisibility = 'on';
                        app.IntensityValue.Visible = 1; 
                    else
                        app.AlgorithmLabel.Text = 'Phase Shift';
                        app.IntensityLabel.HandleVisibility = 'off';
                        app.IntensityLabel.Visible = 0;
                        app.IntensityValue.HandleVisibility = 'off';
                        app.IntensityValue.Visible = 0; 
                    end
                    
                    
                    %numfullcycs = 
                    
                    % ----------------------------------------
                    % Prepare the data for display
                    % ----------------------------------------
                    t_sequence             = 1:dataPoints;
                    LFPData_sequence       = LFPData(1:dataPoints);
                    ProcessedData_sequence = AlgValues(1:dataPoints);
                    AlgState_sequence      = phaseCondition;
                    
                    if strcmp(app.InputselectDropDown.Value,'ASIC')
                        t_sequence = t_sequence * 2;
                    end
                    % ----------------------------------------
                    % Plot the data
                    % ----------------------------------------
                    % Plot the incoming data (Top graph)
                    plot(app.LFPGraph, t_sequence, LFPData_sequence);
                    pause(0.0001);
%                     if strcmp(app.InputselectDropDown.Value,'ASIC')
%                         if app.ampGain == 2100
%                             app.LFPGraph.YLim = [-1000 1000];
%                         else
%                             app.LFPGraph.YLim = [-5000 5000];
%                         end
%                     else
                        app.LFPGraph.YLim = [0 3.3];
%                     end
%                     app.ProcessedGraph.Xlim = [0 (round(app.RefreshrateSlider.Value) * app.inputBufferSize)];
                    % Plot the algorithm output (Bottom graph)
                    plot(app.ProcessedGraph, t_sequence, ProcessedData_sequence);
                    pause(0.0001);
                    app.ProcessedGraph.YLim = [-10 4200];
%                     app.ProcessedGraph.Xlim = [0 (round(app.RefreshrateSlider.Value) * app.inputBufferSize)];
                    % ----------------------------------------
                    % Save data to file
                    % ----------------------------------------
                    fname      = sprintf ('%s %i', app.filename, app.index);
                    matfile    = fullfile(app.path, fname);
                    ind        = app.index;
                    save(matfile,'LFPData','AlgValues','phaseCondition','gain','Algorithm','intensity','ind');
                    app.index  = app.index + 1;
                end
                pause(0.00001)      
            end
        end

        function localRead(app)
            %fid = fopen(app.input,'r+');
            connectionStatus = 1;
            app.ConnectionstatusLamp.Color = 'green';
            while(app.Listening)
                fid = fopen(app.input,'r+');
                % Need some sort of pause here until we have the right
                % number of input values:
                 
                inputfile = app.input;
                fcheck = dir(inputfile);
                
                if (fcheck.bytes >= app.inputBufferSize)
                    % wait here a while, until there is enough data to act on  
                    dataBuffer = fread(fid,app.inputBufferSize, 'uint8');
                     % ------------------------------------------------
                    % extract data
                    % ------------------------------------------------
                    % The data is in a binary sequence so the data needs to
                    % be acquired as bytes, converted to binary and then
                    % the information extracted for each of the components   
                    dataPoints = app.inputBufferSize/app.dataLength;    
                    byteSequence = 1:app.dataLength;     
                    % preallocate for speed:
                    LFPData          = zeros(1,dataPoints);
                    AlgValues        = zeros(1,dataPoints);  
                    phaseCondition   = zeros(1,dataPoints);
                    time             = zeros(1,dataPoints);
                    gain             = zeros(1,dataPoints);
                    IntensAlg        = zeros(1,dataPoints);
                    Algorithm        = zeros(1,dataPoints);
                    for datapoint = 1:dataPoints 
                        % extract the bytes associated with the data point
                        dataBytes = dataBuffer(byteSequence);
                        % convert the byte sequence to a binary sequence
                        binVals = [];
                        for n = 1:app.dataLength
                            binVals = [binVals, dec2bin(dataBytes(n),8)];
                        end
                        % Byte 1: bits 0-7:     LFP Data
                        % Byte 2: bits 8-15:    not used
                        % Byte 3: bits 0-7:     Algorithm values
                        % Byte 4: bits 8-11:    Algorithm values (cont)
                        % Byte 4: bits 12-15:   Phase condition
                        % Byte 5: bits 0-7:     Time
                        % Byte 5: bits 8-9:     Time (cont)
                        % Byte 6: bits 10-13:   Gain
                        % Byte 6: bits 14:      Algo: 0 = original, 1 = set intensity
                        % Byte 6: bits 15:      Algorithm ON/OFF
                        LFPData(datapoint)          = dataBytes(1);
                        AlgValues(datapoint)        = bitshift(dataBytes(4),8)+dataBytes(3); % Combine bytes 3 and 4
                        phaseCondition(datapoint)   = dataBytes(5); %bin2dec(binVals(32:39));
                        time(datapoint)             = bin2dec(binVals(40:42));
                        gain(datapoint)             = bin2dec(binVals(43:46));
                        IntensAlg(datapoint)        = binVals(47);
                        Algorithm(datapoint)        = binVals(48);
                        %LFPData          = [LFPData; bin2dec(binVals(1:8))];
                        %AlgValues        = [AlgValues; bin2dec(binVals(9:16))];
                        byteSequence = byteSequence + app.dataLength;
                    end
                    % ----------------------------------------
                    % Prepare the data for display
                    % ----------------------------------------
                    %!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!!
                    % This needs to be updated to convert the LFP into uV
                    % and time in ms (if possible - not important)
                    % convert LFPData into uV
                    LFPData = LFPData ./ 255 .* 3.3;
                    % Convert phase condition to degrees
                    % phaseCondition = phaseCondition .* 45;
                    % Display on the GUI 
                    app.degLabel_2.Text = num2str(phaseCondition(dataPoints));
                    app.IntensityValue.Text = num2str(gain(dataPoints));
                    if Algorithm(dataPoints) % If algorithm is on
                        app.AlgorithmONOFFLamp.Color = 'green';
                    else
                        app.AlgorithmONOFFLamp.Color = 'red'; 
                    end
                    if IntensAlg(dataPoints) % if this is 1 then the current algorithm is set intensity
                        app.AlgorithmLabel.Text = 'Set Intensity';
                        app.IntensityLabel.Text = 'Intensity';
                    else
                        app.AlgorithmLabel.Text = 'Original FIR';
                        app.IntensityLabel.Text = 'Gain';
                    end
                    % ----------------------------------------
                    % Prepare the data for display
                    % ----------------------------------------
                    t_sequence = 1:dataPoints-1;
                    LFPData_sequence = LFPData(2:dataPoints);
                    ProcessedData_sequence = AlgValues(2:dataPoints);
                    AlgState_sequence = phaseCondition;
                    % ----------------------------------------
                    % Plot the data
                    % ----------------------------------------
                    % Plot the incoming data (Top graph)
                    plot(app.LFPGraph, t_sequence, LFPData_sequence);
                    pause(0.00001);
                    % Plot the algorithm output (Bottom graph)
                    plot(app.ProcessedGraph, t_sequence, ProcessedData_sequence);
                    pause(0.00001);
                    % ----------------------------------------
                    % Save data to file
                    % ----------------------------------------
                    fname      = sprintf ('%s %i', app.filename, app.index);
                    matfile    = fullfile(app.path, fname);
                    ind = app.index;
                    save(matfile,'LFPData','AlgValues','phaseCondition','ind');
                    app.index = app.index + 1;
                end
                pause(0.1)  
            end
        end
        
        function updateParameters(app)
                       
            alg1 = getappdata(0,'algorithm_1');
            
            if  isempty(app.isSerial)
                disp('No output field detected, so assigning default.')
                assignDefaultConnections(app)
            end
            
            app.fullcycs.Text = '0';
            app.loopCnt = 0;
            
            disp('Current output is set to:')
            disp(app.out)
            
            if strcmp(alg1.algorithm,'No Algorithm') 
                value = 11; % trigger 10 to warn the controller of an upcoming parameter packet.
                parameters = [app.manLED, 0 ,0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, 0, 0, 0, ...
                              0, 0, app.manLEDLvlB1, app.manLEDLvlB2, app.manLEDisOn, ...
                              0, 0, 0, 0, value]; % 35 bytes
                
            else
            
              if strcmp(alg1.algorithm,'Set Intensity')
                algoOption = 1;
              elseif strcmp(alg1.algorithm,'Compare Both')
                algoOption = 2;
              else
                algoOption = 0;
              end
                
                if strcmp(alg1.PseudSeq,'Pseudorandom')
                    seqRand = 2; % randomised
                elseif strcmp(alg1.PseudSeq,'Sequential')
                    seqRand = 1; % fixed
                else  %%%% **** NEED TO IMPLEMENT *** %%%%
                    seqRand = 0; % no loops
                end

                       % on/ off times
                [onTimeB1,onTimeB2]   = convert2Bytes(app,alg1.OnTime);
                [offTimeB1,offTimeB2] = convert2Bytes(app,alg1.OffTime);
                
                % threshold
                [thresholdB1,thresholdB2] = convert2Bytes(app,alg1.threshold);
                
                % phase
                if length(alg1.phase)>1
                    [phaseStartB1,phaseStartB2] = convert2Bytes(app,alg1.phase(1));
                    phaseStep = alg1.phase(2);
                    [phaseEndB1,phaseEndB2] = convert2Bytes(app,alg1.phase(3));
                else
                    [phaseStartB1,phaseStartB2] = convert2Bytes(app,alg1.phase);
                    phaseStep = 0;
                    [phaseEndB1,phaseEndB2] = convert2Bytes(app,alg1.phase);
                end
         
                % gain
                if length(alg1.gain)>1
                    gainStart = alg1.gain(1);
                    gainStep  = alg1.gain(2);
                    gainEnd   = alg1.gain(3);
                else
                    gainStart = alg1.gain;
                    gainStep  = 0;
                    gainEnd   = alg1.gain;
                end
            
                % intensity:
                if isfield(alg1,'intensity')
                    if length(alg1.intensity)>1
                        [intenStartB1,intenStartB2] = convert2Bytes(app,alg1.intensity(1));
                        [intenStepB1,intenStepB2]   = convert2Bytes(app,alg1.intensity(2));
                        [intenStopB1,intenStopB2]   = convert2Bytes(app,alg1.intensity(3));
                    else
                        [intenStartB1,intenStartB2]   = convert2Bytes(app,alg1.intensity(1));
                        intenStepB1  = 0;
                        intenStepB2  = 0;
                        [intenStopB1,intenStopB2]   = convert2Bytes(app,alg1.intensity(1));

                    end
                else
                    intenStartB1 = 0;
                    intenStartB2 = 0;
                    intenStepB1  = 0;
                    intenStepB2  = 0;
                    intenStopB1  = 0;
                    intenStopB2  = 0;
                end
             
                value = 11; % tell the controller to recieve parameter packet for updates.

                      
                parameters = [app.manLED, algoOption, seqRand, 0, 0, ...
                          0, 0, 0, 0, 0, ...    
                          0, gainStart, gainStep, gainEnd, intenStartB1, ...
                          intenStartB2, intenStepB1, intenStepB2, intenStopB1, intenStopB2, ...
                          thresholdB1, thresholdB2, app.dutyCycle, onTimeB1, onTimeB2, offTimeB1,...
                          offTimeB2, app.manLEDLvlB1, app.manLEDLvlB2, app.manLEDisOn,...
                          0, 0, 0, 0, value];
            
            end
        
            delete(instrfind);
            
            if app.isSerial
                try
                    write(app.out,parameters,'uint8');
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated.');
                catch
                    warning('Parameters send to fail.')
                end
                disp(parameters)
            else
                try
                    fid = fopen(app.out,'w');
                    disp(fid);
                catch ME1
                    warning('file open failed');
                    try
                        fid = fopen('test.bin','w');
                    catch ME2
                        warning('Tried opening test file and this also failed.')
                    end
                end
                try
                    fwrite(fid,parameters);
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated.');
                catch
                    warning('Parameters send to fail.')
                end
                disp(parameters)        
            end
            
            if isempty(app.matfile)
                app.matfilecnt = app.matfilecnt + 1;
                metadata = getMetaData(app);
                save([app.matfile int2str(app.matfilecnt)],'metadata');
            end

        end
        
        function updateASIC(app)
            if  isempty(app.isSerial)
                disp('No output field detected, so assigning default.')
                assignDefaultConnections(app)
            end

            if strcmp(app.InputselectDropDown.Value,'ASIC') 
                isASICin = 1; % using electrode input from the CANDO ASIC
                elec_to_use = str2num(app.ElectrodeselectDropDown.Value);%app.Channel.Value;
            else
                isASICin = 0; % using electrode input from the microcontroller
                elec_to_use = 0;
            end
            
            if strcmp(app.OutputselectDropDown.Value,'ASIC')
                isASICout = 1; % using CANDO LED output
                LEDout = str2num(app.LEDselectDropDown.Value);
            else
                isASICout = 0; % using LED output elsewhere
                LEDout = 1;
            end
            
            value = 7; % trigger 7 to warn the controller of an upcoming parameter packet for ASIC info.
            parameters = [isASICin, isASICout, elec_to_use, LEDout, app.ampGainVal, ...
                          0, 0, 0, 0, 0, ...
                          0, 0, 0, 0, 0, ...
                          0, 0, 0, 0, 0, ...
                          0, 0, 0, 0, 0, ...
                          0, 0, 0, 0, 0, ...
                          0, 0, 0, 0,value]; % 35 bytes
                      
            delete(instrfind);
            
            if app.isSerial
                try
                    write(app.out,parameters,'uint8');
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('ASIC successfully updated.');
                catch
                    warning('ASIC parameters send failed.')
                end
                disp(parameters)
            else
                try
                    fid = fopen(app.out,'w');
                    disp(fid);
                catch ME1
                    warning('file open failed');
                    try
                        fid = fopen('test.bin','w');
                    catch ME2
                        warning('Tried opening test file and this also failed.')
                    end
                end
                try
                    fwrite(fid,parameters);
                    app.ConnectionstatusLamp.Color = 'green';
                    disp('Algorithm successfully updated.');
                catch
                    warning('Parameters send to fail.')
                end
                disp(parameters)
                
                app.dataBuffer = [];
            end
            
        end
        
        function setDefaultParams(app)
            
            alg1.OnTime = 0;
            alg1.OffTime = 0;
            alg1.TimeEstNum = 0;
            alg1.TimeEst = '0 seconds';
            alg1.algorithm = 'No Algorithm';
            alg1.frequency = 10;
            alg1.threshold = 1;
            alg1.kernlen = 512;
            alg1.gain = 1;
            alg1.phase = [0,45,315];
            alg1.intensity = 1000;
            
            app.ExperimentParamsLabel.Text = 'Default setting: No Algorithm';
            
            app.QuickConfigOptionsPanel.Visible = 0;
            
            setappdata(0,'algorithm_1',alg1)
            
            app.errlog = [pwd,'gui_error_log.txt'];
            errstart = ['Initialised: ',datestr(datetime),','];
            save(app.errlog,'errstart');
            
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Populate the port dropdown with possible ports to select
            % from:
            if license('test', 'instr_control_toolbox')
                p = instrhwinfo('serial');
                app.PortselectDropDown.Items = p.AvailableSerialPorts;
            else
                app.PortselectDropDown.Items = 'Default';
            end
            setDefaultParams(app)
           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {688, 688, 688};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {688, 688};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {12, '1x', 400};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: StartButton
        function StartButtonValueChanged(app, event)
            % This is the main 'GO' function.
            % It is operated with a toggle button which defines whether to run
            % the system or stop the system.
            

            ONState = app.StartButton.Value;
            
            
            if ONState
                
                app.StartButton.BackgroundColor = 'Red';
                app.StartButton.Text = 'Stop';
                
                passParameters(app) % Pass the parameters to the microcontroller
                if ~app.hasVersion
                    getMicroVersion(app) % Get the microcontroller version (Or C code version)
                    
                    app.hasVersion = 1;
                end
                
                app.index = 1;                
                % Check if we are doing a staggere recording:
                if app.StaggeredRecordingTimeEditField > 0 % if a value has been entered
                    % Check the units, seconds is default
                    if app.TimeunitsDropDown.Value == 'Minutes'
                        waitTime = app.StaggeredRecordingTimeEditField.Value*60;
                    elseif app.TimeunitsDropDown.Value == 'Hours'
                        waitTime = app.StaggeredRecordingTimeEditField.Value*3600;
                    elseif app.TimeunitsDropDown.Value == 'Days'
                        waitTime = app.StaggeredRecordingTimeEditField.Value*86400;
                    else
                        waitTime = app.StaggeredRecordingTimeEditField.Value;
                    end
                
                    while(ONState) % loopity loop
                    
                        app.Listening = 1;
                        if ~app.isSerial
                            localRead(app)
                        else
                            serialRead(app)
                            sendTrigger(app,2) % stop the microcontroller sending any more
                        end
                        app.Listening = 0;
                        pause(waitTime) % pause for the designated time before triggering the record and read again.
                    end
                else
                    app.Listening = 1;
                    if ~app.isSerial
                        localRead(app)
                    else
                        serialRead(app)
                    end
                end
            else
                app.Listening = 0;
                app.StartButton.BackgroundColor = 'Green';
                app.StartButton.Text = 'Start';
                disp('Stopping Data Collection')
                sendTrigger(app,2); % If the Microcontroller was sending data, stop!
                app.ConnectionstatusLamp.Color = 'red';
                setDefaultParams(app);
                try
                    fclose(app.output);
                catch ME1
                    app.WarningLabel.Text = "Warning: Connection to device failed to close";
                end
                
                % Close all serial ports Matlab can find. This makes absolutely
                % sure the ports get closed.
                delete(instrfind);
                
            end
        end

        % Button pushed function: ConfigureButton
        function ConfigureButtonPushed(app, event)
            
            % This function updates the algorithm parameters on the
            % microcontroller. It gives an error message if a connection
            % cannot be made.
            
            % First off, load the experiment planner window:
            % sendTrigger(app,2); % If the Microcontroller was sending data, stop!
            app.QuickConfigOptionsPanel.Visible = 1;
            
            disp('Loading the experiment planner')
            app.ExperimentPlanner = ExperimentPlanner_V22(app);
           
        end

        % Callback function
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            
            % -------------------------------------------------------------------------
            % If the Start button is clicked
            % -------------------------------------------------------------------------
            if value == 1
                app.ConnectionstatusLamp.Color = 'red';
                
                % Try to close the serial port. But don't worry if this fails
                % the delete(instrfind) is the bullet proof backup.
                try
                    fclose(app.output);
                catch ME1
                end
                
                % Close all serial ports Matlab can find. This makes absolutely
                % sure the ports get closed.
                delete(instrfind);
            end
        end

        % Value changed function: InputselectDropDown
        function InputselectDropDownValueChanged(app, event)
            value = app.InputselectDropDown.Value;   
            if strcmp(value,'Static File Input')
                SelectAndPlotData(app)
                app.inputBufferSize = 10000;
            elseif strcmp(value,'Test Signal')
                [fname,dirname] = uigetfile();
                app.input = [dirname,fname];
                disp('Test signal selected from file:')
                disp(app.input)
                app.inputBufferSize = 10000;
            elseif strcmp(value,'ASIC')
                updateASIC(app)
                % add another electrode and channel to the dropdowns 
                app.ElectrodeselectDropDown.Items = {'0','1'};
                app.ChannelViewDropDown.Items = {'Channel 0','Channel 1'};
                app.inputBufferSize = 5000;
                if app.portselect
                    disp(['Serial port already selected: ',app.PortselectDropDown.Value])
                else
                    assignDefaultConnections(app)
                end
            else 
                if app.portselect
                    disp(['Serial port already selected: ',app.PortselectDropDown.Value])
                else
                    assignDefaultConnections(app)
                end
                app.inputBufferSize = 10000;
                updateASIC(app)
            end
            
        end

        % Value changed function: ONtimeValue
        function ONtimeValueValueChanged(app, event)
            value = app.ONtimeValue.Value;
            
            alg1 = getappdata(0,'algorithm_1');
            alg1.OnTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            
            updateParameters(app)
        end

        % Value changed function: OFFtimeValue
        function OFFtimeValueValueChanged(app, event)
            value = app.OFFtimeValue.Value;
            
            alg1 = getappdata(0,'algorithm_1');
            alg1.OffTime = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            
            updateParameters(app);
            
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            
            % Need to get the value attached to the algorithm data, and
            % into the writing.
            alg1 = getappdata(0,'algorithm_1');
            alg1.threshold = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
            
            if val
                replotLoadedData(app);
            end
            
            updateParameters(app)
        end

        % Value changed function: OutputselectDropDown
        function OutputselectDropDownValueChanged(app, event)
            value = app.OutputselectDropDown.Value;
            
            if strcmp(value,'Static File Output') % if we want to output to a file on the computer, rather than to the microcontroller
                % trigger the file open function, and then have this saved
                % as the output. When Start is pushed or whenever the
                % f.write is called it needs to write to a file instead of
                % the default serial. Need to ammend functions calling
                % f.write (passParameters and Start Button call back)
                [fname,dirname] = uiputfile();
                savedata = [dirname,fname];
                app.out = savedata;
                disp('Output file selected is:')
                disp(savedata)
                app.isSerial = false;
            elseif strcmp(value,'ASIC')
                % trigger update ASIC function and add LED output fields 
                assignDefaultConnections(app)
                updateASIC(app)
                app.LEDselectDropDown.Items = {'0','1','2','3','4','5','6','7'};
            else
                assignDefaultConnections(app)
                updateASIC(app)
            end
            
            
            if ~isempty(app.out)
                disp('Output assignment successful.')
            else
                warning('Check output assignment.')
            end
            
        end

        % Value changed function: DirectstimulationlevelSlider
        function DirectstimulationlevelSliderValueChanged(app, event)
            value = app.DirectstimulationlevelSlider.Value/100*4095;
            [app.manLEDLvlB1,app.manLEDLvlB2] = convert2Bytes(app,value);
            updateParameters(app);
        end

        % Value changed function: StimulationONmanualoverrideButton
        function StimulationONmanualoverrideButtonValueChanged(app, event)
            value = app.StimulationONmanualoverrideButton.Value;
            disp(value)
            app.manLEDisOn = value;
            app.dutyCycle = 0;
            app.UseOnOfftimesSwitch.Value = 'Off';
            updateParameters(app);
            if value == 1  
                app.Lamp.Color = 'green';
                app.StimulationONmanualoverrideButton.Text = 'Stimulation OFF';
            else
                app.Lamp.Color = 'red';
                app.StimulationONmanualoverrideButton.Text = 'Stimulation ON';
            end
        end

        % Value changed function: StimulationtypeSwitch
        function StimulationtypeSwitchValueChanged(app, event)
            value = app.StimulationtypeSwitch.Value;
            if strcmp(value,'Direct value')
                app.manLED = 1;
            else
                app.manLED = 0;
            end
            updateParameters(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if  app.isSerial
                sendTrigger(app,2); % If the Microcontroller was sending data, stop!
            end
            delete(app)
        end

        % Value changed function: PortselectDropDown
        function PortselectDropDownValueChanged(app, event)
            value = app.PortselectDropDown.Value;
            delete(instrfind);
            % Serial communication settings
            Baud_Rate = 255940;
%             Baud_Rate = 115219;
            Data_Bits = 8;
            Stop_Bits = 1;
            Parity = 'none';
            % Setup a serial port and connect to it
            app.input = serialport(value,Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
            app.out = serialport(value,Baud_Rate,'DataBits',Data_Bits,'Parity',Parity,'StopBits',Stop_Bits);
            disp(['Serial port input selected:' value])
            app.portselect = 1;
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
                        
            alg1 = getappdata(0,'algorithm_1');
            alg1.gain = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
            
            if val
                replotLoadedData(app);
            end
            
            updateParameters(app)
        end

        % Value changed function: IntensityEditField
        function IntensityEditFieldValueChanged(app, event)
            value = app.IntensityEditField.Value;
                        
            alg1 = getappdata(0,'algorithm_1');
            alg1.intensity = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
            
            if val
                replotLoadedData(app);
            end
            
            updateParameters(app)
        end

        % Value changed function: AlgorithmDropDown
        function AlgorithmDropDownValueChanged(app, event)
            value = app.AlgorithmDropDown.Value;
                        
            alg1 = getappdata(0,'algorithm_1');
            alg1.algorithm = value;
            setappdata(0,'algorithm_1',alg1);
            alg2 = getappdata(0,'algorithm_2');
            experimentWriter(app,alg1,alg2);
            try
                val = getappdata(app.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.');
            end
            
            if val
                replotLoadedData(app);
            end
            
            updateParameters(app)
        end

        % Button pushed function: MicrocontrollerResetButton
        function MicrocontrollerResetButtonPushed(app, event)
            assignDefaultConnections(app)
            sendTrigger(app,200) % trigger to force a manual rest
        end

        % Value changed function: AmplifierGainCMOSDropDown
        function AmplifierGainCMOSDropDownValueChanged(app, event)
            value = app.AmplifierGainCMOSDropDown.Value;
            
            if isequal(value,'300')
                val = 0;
            elseif isequal(value,'385')
                val = 1;
            elseif isequal(value,'2100')
                val = 2;
            elseif isequal(value,'4500')
                val = 3;
            else
                val = 2; % default 
                warning('Amplifier gain is not being properly processed, defaulting to gain of 2100')
            end
            disp(['Value: ' value]);
            app.ampGain = str2num(value);
            app.ampGainVal = val;
            
            updateASIC(app);
        end

        % Value changed function: RefreshrateSlider
        function RefreshrateSliderValueChanged(app, event)
            value = app.RefreshrateSlider.Value;
        end

        % Value changed function: ChannelViewDropDown
        function ChannelViewDropDownValueChanged(app, event)
            % this should change the plotted channel, need a function for
            % this, and should only have multiple values if the ASIC is connected. 
            value = app.ChannelViewDropDown.Value;
            
        end

        % Value changed function: ElectrodeselectDropDown
        function ElectrodeselectDropDownValueChanged(app, event)
            value = app.ElectrodeselectDropDown.Value;
            updateASIC(app);
        end

        % Value changed function: LEDselectDropDown
        function LEDselectDropDownValueChanged(app, event)
            value = app.LEDselectDropDown.Value;
            updateASIC(app);
        end

        % Button pushed function: SelectOptrodeButton
        function SelectOptrodeButtonPushed(app, event)
            
            disp('Loading the optrode selector')
            app.OptrodeSelector = optrode_selector(app);
            
        end

        % Button pushed function: MicrocontrollerCalibrationButton
        function MicrocontrollerCalibrationButtonPushed(app, event)
            % Need to prompt user to select a calibration file.
            [fname,dirname] = uigetfile('*.csv','Select Calibration File');
            % read in the file contents 
            calibration_data = readmatrix([dirname,fname]);
            % Check this looks like a calibration file
            if size(calibration_data,2) == 1 && size(calibration_data,1) == 64
                disp('calibration data successfully read')
                 % send contents to microcontroller:
                 if isempty(app.isSerial)
                     assignDefaultConnections(app);
                 end
                 
                config_array = [];
                
                % write 2 bytes per value
                for i = 1:64
                    [B1,B2] = convert2Bytes(app,calibration_data(i));
                    config_array = [config_array; B1; B2]; % should end up being 128 bytes long
                end
                
                write(app.out,[config_array(1:34); 4],'uint8') % send the calibration data!
                display('First packet:');
                display([config_array(1:34); 4]);
                
                write(app.out,[config_array(35:68); 4],'uint8')
                display('Second packet:');
                display([config_array(35:68);4]);
                
                write(app.out,[config_array(69:102); 4],'uint8')
                display('Third packet:');
                display([config_array(69:102);4]);
                
                write(app.out,[config_array(103:128);0;0;0;0;0;0;0;0; 4],'uint8')
                display('Fourth packet:');
                display([config_array(103:128);0;0;0;0;0;0;0;0;4]);
            else
                warning('The calibration data does not match expected dimensions, please check that this is the correct file and retry.')
            end
           
            
        end

        % Value changed function: UseOnOfftimesSwitch
        function UseOnOfftimesSwitchValueChanged(app, event)
            value = app.UseOnOfftimesSwitch.Value;
            if strcmp(value, 'On')
                app.dutyCycle = 1;
            elseif strcmp(value, 'Off')
                app.dutyCycle = 0;
            end
            
            updateParameters(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CenterPanel = uipanel(app.GridLayout);
            app.LFPGraph = uiaxes(app.CenterPanel);
            title(app.LFPGraph, 'Original Recording (LFP)')
            xlabel(app.LFPGraph, 'time (ms)')
            ylabel(app.LFPGraph, 'Voltage (uV)')
            app.ProcessedGraph = uiaxes(app.CenterPanel);
            title(app.ProcessedGraph, 'Processed Output')
            xlabel(app.ProcessedGraph, 'time (ms)')
            ylabel(app.ProcessedGraph, 'Algorithm value')
            app.StartButton = uibutton(app.CenterPanel, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonValueChanged, true);
            app.StartButton.Text = 'Start';
            app.CurrentstatusPanel = uipanel(app.CenterPanel);
            app.CurrentstatusPanel.Title = 'Current status';
            app.AlgorithmONOFFLampLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmONOFFLampLabel.Text = 'Algorithm ON/OFF';
            app.AlgorithmONOFFLamp = uilamp(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmstatusLabel.Text = 'Algorithm status ';
            app.AlgorithmLabel = uilabel(app.CurrentstatusPanel);
            app.AlgorithmLabel.Text = 'Algorithm';
            app.ConnectionstatusLampLabel = uilabel(app.CurrentstatusPanel);
            app.ConnectionstatusLampLabel.Text = 'Connection status';
            app.ConnectionstatusLamp = uilamp(app.CurrentstatusPanel);
            app.PhaseLabel = uilabel(app.CurrentstatusPanel);
            app.PhaseLabel.Text = 'Phase';
            app.degLabel_2 = uilabel(app.CurrentstatusPanel);
            app.degLabel_2.Text = '0 deg';
            app.IntensityLabel = uilabel(app.CurrentstatusPanel);
            app.IntensityLabel.Text = 'Intensity';
            app.IntensityValue = uilabel(app.CurrentstatusPanel);
            app.IntensityValue.Text = '0';
            app.GainLabel = uilabel(app.CurrentstatusPanel);
            app.GainLabel.Text = 'Gain';
            app.GainValue = uilabel(app.CurrentstatusPanel);
            app.GainValue.Text = '0';
            app.StaggeredRecordingTimeEditFieldLabel = uilabel(app.CenterPanel);
            app.StaggeredRecordingTimeEditFieldLabel.Text = 'Staggered Recording Time:';
            app.StaggeredRecordingTimeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.TimeunitsDropDownLabel = uilabel(app.CenterPanel);
            app.TimeunitsDropDownLabel.Text = 'Time units';
            app.TimeunitsDropDown = uidropdown(app.CenterPanel);
            app.TimeunitsDropDown.Items = {'Seconds', 'Minutes', 'Hours', 'Days'};
            app.TimeunitsDropDown.Value = 'Seconds';
            app.RefreshrateSliderLabel = uilabel(app.CenterPanel);
            app.RefreshrateSliderLabel.Enable = 'off';
            app.RefreshrateSliderLabel.Visible = 'off';
            app.RefreshrateSliderLabel.Text = 'Refresh rate';
            app.RefreshrateSlider = uislider(app.CenterPanel);
            app.RefreshrateSlider.Limits = [1 10];
            app.RefreshrateSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RefreshrateSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.RefreshrateSlider.ValueChangedFcn = createCallbackFcn(app, @RefreshrateSliderValueChanged, true);
            app.RefreshrateSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.RefreshrateSlider.Enable = 'off';
            app.RefreshrateSlider.Visible = 'off';
            app.RefreshrateSlider.Value = 1;
            app.ChannelViewDropDownLabel = uilabel(app.CenterPanel);
            app.ChannelViewDropDownLabel.Text = 'Channel View';
            app.ChannelViewDropDown = uidropdown(app.CenterPanel);
            app.ChannelViewDropDown.Items = {'Channel 1'};
            app.ChannelViewDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelViewDropDownValueChanged, true);
            app.ChannelViewDropDown.Value = 'Channel 1';
            app.GUISoftwareVersionLabel = uilabel(app.CenterPanel);
            app.GUISoftwareVersionLabel.Text = 'GUI Software Version';
            app.Label = uilabel(app.CenterPanel);
            app.Label.Text = '20';
            app.MicrocontrollerSoftwareVersionLabel = uilabel(app.CenterPanel);
            app.MicrocontrollerSoftwareVersionLabel.Text = 'Microcontroller Software Version:';
            app.XXLabel_2 = uilabel(app.CenterPanel);
            app.XXLabel_2.Text = 'XX';
            app.AmplifierGainCMOSDropDownLabel = uilabel(app.CenterPanel);
            app.AmplifierGainCMOSDropDownLabel.Text = 'Amplifier Gain (CMOS)';
            app.AmplifierGainCMOSDropDown = uidropdown(app.CenterPanel);
            app.AmplifierGainCMOSDropDown.Items = {'300', '385', '2100', '4500'};
            app.AmplifierGainCMOSDropDown.ValueChangedFcn = createCallbackFcn(app, @AmplifierGainCMOSDropDownValueChanged, true);
            app.AmplifierGainCMOSDropDown.Value = '2100';
            app.FullCyclesCompletedLabel = uilabel(app.CenterPanel);
            app.FullCyclesCompletedLabel.Text = 'Full Cycles Completed:';
            app.fullcycs = uilabel(app.CenterPanel);
            app.fullcycs.Text = '0';
            app.WarningLabel = uilabel(app.CenterPanel);
            app.WarningLabel.Visible = 'off';
            app.WarningLabel.Text = 'Warning:';
            app.MicrocontrollerResetButton = uibutton(app.CenterPanel, 'push');
            app.MicrocontrollerResetButton.ButtonPushedFcn = createCallbackFcn(app, @MicrocontrollerResetButtonPushed, true);
            app.MicrocontrollerResetButton.Text = 'Microcontroller Reset';
            app.MicrocontrollerCalibrationButton = uibutton(app.CenterPanel, 'push');
            app.MicrocontrollerCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @MicrocontrollerCalibrationButtonPushed, true);
            app.MicrocontrollerCalibrationButton.Text = 'Microcontroller Calibration';
            app.RightPanel = uipanel(app.GridLayout);
            app.QuickConfigOptionsPanel = uipanel(app.RightPanel);
            app.QuickConfigOptionsPanel.Title = 'Quick Config Options';
            app.ONtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.ONtimeEditFieldLabel.Text = 'ON time';
            app.ONtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ONtimeValue.ValueChangedFcn = createCallbackFcn(app, @ONtimeValueValueChanged, true);
            app.sLabel = uilabel(app.QuickConfigOptionsPanel);
            app.sLabel.Text = '(s)';
            app.OFFtimeEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.OFFtimeEditFieldLabel.Text = 'OFF time';
            app.OFFtimeValue = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.OFFtimeValue.ValueChangedFcn = createCallbackFcn(app, @OFFtimeValueValueChanged, true);
            app.sLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.sLabel_2.Text = '(s)';
            app.ThresholdEditFieldLabel_2 = uilabel(app.QuickConfigOptionsPanel);
            app.ThresholdEditFieldLabel_2.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 1;
            app.GainEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.GainEditField.Limits = [-255 255];
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = 1;
            app.IntensityEditFieldLabel = uilabel(app.QuickConfigOptionsPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.QuickConfigOptionsPanel, 'numeric');
            app.IntensityEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityEditFieldValueChanged, true);
            app.IntensityEditField.Value = 1000;
            app.AlgorithmDropDownLabel = uilabel(app.QuickConfigOptionsPanel);
            app.AlgorithmDropDownLabel.Text = 'Algorithm';
            app.AlgorithmDropDown = uidropdown(app.QuickConfigOptionsPanel);
            app.AlgorithmDropDown.Items = {'No Algorithm', 'Phase Shift', 'Set Intensity'};
            app.AlgorithmDropDown.ValueChangedFcn = createCallbackFcn(app, @AlgorithmDropDownValueChanged, true);
            app.AlgorithmDropDown.Value = 'No Algorithm';
            app.UseOnOfftimesSwitchLabel = uilabel(app.QuickConfigOptionsPanel);
            app.UseOnOfftimesSwitchLabel.Text = 'Use On/ Off times';
            app.UseOnOfftimesSwitch = uiswitch(app.QuickConfigOptionsPanel, 'slider');
            app.UseOnOfftimesSwitch.ValueChangedFcn = createCallbackFcn(app, @UseOnOfftimesSwitchValueChanged, true);
            app.UseOnOfftimesSwitch.Value = 'On';
            app.InputselectDropDownLabel = uilabel(app.RightPanel);
            app.InputselectDropDownLabel.Text = 'Input select';
            app.InputselectDropDown = uidropdown(app.RightPanel);
            app.InputselectDropDown.Items = {'In Vitro Box', 'ASIC', 'Static File Input', 'Test Signal'};
            app.InputselectDropDown.ValueChangedFcn = createCallbackFcn(app, @InputselectDropDownValueChanged, true);
            app.InputselectDropDown.Value = 'In Vitro Box';
            app.OutputselectDropDownLabel = uilabel(app.RightPanel);
            app.OutputselectDropDownLabel.Text = 'Output select';
            app.OutputselectDropDown = uidropdown(app.RightPanel);
            app.OutputselectDropDown.Items = {'In Vitro Box', 'ASIC', 'Static File Output'};
            app.OutputselectDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputselectDropDownValueChanged, true);
            app.OutputselectDropDown.Value = 'In Vitro Box';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.ExperimentalParametersPanel = uipanel(app.AutoTab);
            app.ExperimentalParametersPanel.Title = 'Experimental Parameters';
            app.ExperimentParamsLabel = uilabel(app.ExperimentalParametersPanel);
            app.ExperimentParamsLabel.Text = '';
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.StimulationtypeSwitchLabel = uilabel(app.ManualTab);
            app.StimulationtypeSwitchLabel.Text = 'Stimulation type';
            app.StimulationtypeSwitch = uiswitch(app.ManualTab, 'slider');
            app.StimulationtypeSwitch.Items = {'Algorithm', 'Direct value'};
            app.StimulationtypeSwitch.ValueChangedFcn = createCallbackFcn(app, @StimulationtypeSwitchValueChanged, true);
            app.StimulationtypeSwitch.Value = 'Algorithm';
            app.DirectstimulationlevelSliderLabel = uilabel(app.ManualTab);
            app.DirectstimulationlevelSliderLabel.Text = 'Direct stimulation level';
            app.DirectstimulationlevelSlider = uislider(app.ManualTab);
            app.DirectstimulationlevelSlider.ValueChangedFcn = createCallbackFcn(app, @DirectstimulationlevelSliderValueChanged, true);
            app.Lamp = uilamp(app.ManualTab);
            app.StimulationONmanualoverrideButton = uibutton(app.ManualTab, 'state');
            app.StimulationONmanualoverrideButton.ValueChangedFcn = createCallbackFcn(app, @StimulationONmanualoverrideButtonValueChanged, true);
            app.StimulationONmanualoverrideButton.Text = {'Stimulation ON '; '(manual override)'};
            app.PortselectDropDownLabel = uilabel(app.RightPanel);
            app.PortselectDropDownLabel.Text = 'Port select:';
            app.PortselectDropDown = uidropdown(app.RightPanel);
            app.PortselectDropDown.ValueChangedFcn = createCallbackFcn(app, @PortselectDropDownValueChanged, true);
            app.UserFeedbackText = uilabel(app.RightPanel);
            app.UserFeedbackText.Visible = 'off';
            app.UserFeedbackText.Text = 'User Feedback';
            app.ConfigureButton = uibutton(app.RightPanel, 'push');
            app.ConfigureButton.ButtonPushedFcn = createCallbackFcn(app, @ConfigureButtonPushed, true);
            app.ConfigureButton.Text = 'Configure';
            app.ElectrodeselectDropDownLabel = uilabel(app.RightPanel);
            app.ElectrodeselectDropDownLabel.Text = 'Electrode select:';
            app.ElectrodeselectDropDown = uidropdown(app.RightPanel);
            app.ElectrodeselectDropDown.Items = {'0'};
            app.ElectrodeselectDropDown.ValueChangedFcn = createCallbackFcn(app, @ElectrodeselectDropDownValueChanged, true);
            app.ElectrodeselectDropDown.Value = '0';
            app.LEDselectDropDownLabel = uilabel(app.RightPanel);
            app.LEDselectDropDownLabel.Text = 'LED select';
            app.LEDselectDropDown = uidropdown(app.RightPanel);
            app.LEDselectDropDown.Items = {'0'};
            app.LEDselectDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDselectDropDownValueChanged, true);
            app.LEDselectDropDown.Value = '0';
            app.SelectOptrodeButton = uibutton(app.RightPanel, 'push');
            app.SelectOptrodeButton.ButtonPushedFcn = createCallbackFcn(app, @SelectOptrodeButtonPushed, true);
            app.SelectOptrodeButton.Text = 'Select Optrode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CANDO_control_GUI_V22

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end