classdef ExperimentPlanner < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Button                          matlab.ui.control.Button
        SelectAlgorithm1DropDownLabel   matlab.ui.control.Label
        SelectAlgorithm1DropDown        matlab.ui.control.DropDown
        Algorithm1parametersPanel       matlab.ui.container.Panel
        Panel                           matlab.ui.container.Panel
        CentralFrequencyHzEditField_3Label  matlab.ui.control.Label
        CentralFrequencyHzEditField     matlab.ui.control.EditField
        ThresholdEditField_4Label       matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.EditField
        KernellengthEditField_3Label    matlab.ui.control.Label
        KernellengthEditField           matlab.ui.control.EditField
        GainEditField_5Label            matlab.ui.control.Label
        GainEditField                   matlab.ui.control.EditField
        IntensityconditionsEditField_3Label  matlab.ui.control.Label
        IntensityconditionsEditField    matlab.ui.control.EditField
        PhaseconditionsdegEditField_3Label  matlab.ui.control.Label
        PhaseconditionsdegEditField     matlab.ui.control.EditField
        Panel_2                         matlab.ui.container.Panel
        OpenlooptypeButtonGroup         matlab.ui.container.ButtonGroup
        StepfunctionsButton             matlab.ui.control.RadioButton
        RandomnoiseButton               matlab.ui.control.RadioButton
        SinusoidalButton                matlab.ui.control.RadioButton
        StepfunctionsPanel              matlab.ui.container.Panel
        PulseofftimeEditField_6Label    matlab.ui.control.Label
        PulseofftimeEditField           matlab.ui.control.EditField
        IntensityEditField_7Label       matlab.ui.control.Label
        IntensityEditField              matlab.ui.control.EditField
        PulsespercycleEditField_6Label  matlab.ui.control.Label
        PulsespercycleEditField         matlab.ui.control.EditField
        PulselengthEditFieldLabel       matlab.ui.control.Label
        PulselengthEditField            matlab.ui.control.EditField
        SinusoidalPanel                 matlab.ui.container.Panel
        GainEditField_11Label           matlab.ui.control.Label
        GainEditFieldSine               matlab.ui.control.EditField
        FrequencyHzEditField_4Label     matlab.ui.control.Label
        FrequencyHzEditFieldSine        matlab.ui.control.EditField
        PhaseEditField_6Label           matlab.ui.control.Label
        PhaseEditField                  matlab.ui.control.EditField
        UIAxes                          matlab.ui.control.UIAxes
        GainEditField_3Label            matlab.ui.control.Label
        RandomnoisegainEditField        matlab.ui.control.EditField
        ExperimentPlannerLabel          matlab.ui.control.Label
        SelectAlgorithm2DropDownLabel   matlab.ui.control.Label
        SelectAlgorithm2DropDown        matlab.ui.control.DropDown
        AddadditionalalgorithmButton    matlab.ui.control.Button
        OntimeEditFieldLabel            matlab.ui.control.Label
        OntimeEditField                 matlab.ui.control.NumericEditField
        OfftimeEditFieldLabel           matlab.ui.control.Label
        OfftimeEditField                matlab.ui.control.NumericEditField
        ExpectedexperimentdurationLabel  matlab.ui.control.Label
        ButtonGroup                     matlab.ui.container.ButtonGroup
        PseudorandomButton              matlab.ui.control.ToggleButton
        SequentialButton                matlab.ui.control.ToggleButton
        Algorithm2parametersPanel       matlab.ui.container.Panel
        Panel_3                         matlab.ui.container.Panel
        CentralFrequencyHzEditField_4Label  matlab.ui.control.Label
        CentralFrequencyHzEditField_2   matlab.ui.control.EditField
        ThresholdEditField_5Label       matlab.ui.control.Label
        ThresholdEditField_2            matlab.ui.control.EditField
        KernellengthEditField_4Label    matlab.ui.control.Label
        KernellengthEditField_2         matlab.ui.control.EditField
        GainEditField_8Label            matlab.ui.control.Label
        GainEditField_2                 matlab.ui.control.EditField
        IntensityconditionsEditField_4Label  matlab.ui.control.Label
        IntensityconditionsEditField_2  matlab.ui.control.EditField
        PhaseconditionsdegEditField_4Label  matlab.ui.control.Label
        PhaseconditionsdegEditField_2   matlab.ui.control.EditField
        Panel_4                         matlab.ui.container.Panel
        OpenlooptypeButtonGroup_2       matlab.ui.container.ButtonGroup
        StepfunctionsButton_2           matlab.ui.control.RadioButton
        RandomnoiseButton_2             matlab.ui.control.RadioButton
        SinusoidalButton_2              matlab.ui.control.RadioButton
        StepfunctionsPanel_2            matlab.ui.container.Panel
        PulseofftimeEditField_9Label    matlab.ui.control.Label
        PulseofftimeEditField_2         matlab.ui.control.EditField
        IntensityEditField_12Label      matlab.ui.control.Label
        IntensityEditField_2            matlab.ui.control.EditField
        PulsespercycleEditField_9Label  matlab.ui.control.Label
        PulsespercycleEditField_2       matlab.ui.control.EditField
        PulselengthEditField_2Label     matlab.ui.control.Label
        PulselengthEditField_2          matlab.ui.control.EditField
        SinusoidalPanel_2               matlab.ui.container.Panel
        GainEditField_14Label           matlab.ui.control.Label
        GainEditFieldSine_2             matlab.ui.control.EditField
        FrequencyHzEditField_7Label     matlab.ui.control.Label
        FrequencyHzEditFieldSine_2      matlab.ui.control.EditField
        PhaseEditField_9Label           matlab.ui.control.Label
        PhaseEditField_2                matlab.ui.control.EditField
        UIAxes_2                        matlab.ui.control.UIAxes
        GainEditField_3Label_2          matlab.ui.control.Label
        RandomnoisegainEditField_2      matlab.ui.control.EditField
        TimeEst                         matlab.ui.control.Label
        LoadtestdataButton              matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app object
    end
    
    methods (Access = private)
        
        function EstTime = EstimateTime(app)
            % Calculate the total experiment time for one full run
            % Start by getting the time for one loop:
            OneSetRun = app.OntimeEditField.Value + app.OfftimeEditField.Value; 
            % Now need to check for parameter loops in the first algorithm:
            loops = 0; % start with zero. Add one for each algorithm, and one for each parameter in a parameter loop?
            
            % check algorithm, then check the appropriate parameters
            if (strcmp(app.SelectAlgorithm1DropDown.Value,'No Algorithm'))
                disp('no algorithm selected for the 1st, so no time to run')                
            else
                if (strcmp(app.SelectAlgorithm1DropDown.Value,'Phase Shift'))
                    params = [app.CentralFrequencyHzEditField,app.ThresholdEditField, ...
                        app.KernellengthEditField,app.GainEditField,app.PhaseconditionsdegEditField]; % list the relevant parameters
                elseif (strcmp(app.SelectAlgorithm1DropDown.Value,'Set Intensity'))
                    params = [app.CentralFrequencyHzEditField,app.ThresholdEditField,app.IntensityconditionsEditField   ...
                        app.KernellengthEditField,app.GainEditField,app.PhaseconditionsdegEditField]; % list the relevant parameters
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Step functions'))
                    params = [app.PulselengthEditField,app.PulseofftimeEditField,app.PulsespercycleEditField,app.IntensityEditField];
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Random noise'))
                    params = [app.RandomnoisegainEditField];
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Sinusoidal'))
                    params = [app.GainEditFieldSine,app.FrequencyHzEditFieldSine,app.PhaseEditField];
                else
                    disp('Error: selected algorithm unrecognised')
                    params = 0;
                end
                for i = 1:length(params)
                    loops = loops + length(str2num(params(i).Value)) - isscalar(str2num(params(i).Value));
                end
                if loops == 0 % if the algorithm is not 'no algorithm' and there are no loops, then make this one.
                    loops = 1;
                end
            end
            
            firstAlgTime = OneSetRun*loops;
            
            loops = 0; % reset for the second algorithm and repeat.

            % is there a second algorithm? If not then we are done. If
            % there is need to check for loops.
            if (strcmp(app.SelectAlgorithm2DropDown.Value,'No Algorithm'))
                disp('no algorithm selected for the 2nd, so no time to run')                
            else
                if (strcmp(app.SelectAlgorithm2DropDown.Value,'Phase Shift'))
                    params = [app.CentralFrequencyHzEditField_2,app.ThresholdEditField_2, ...
                        app.KernellengthEditField_2,app.GainEditField_2,app.PhaseconditionsdegEditField_2]; % list the relevant parameters
                elseif (strcmp(app.SelectAlgorithm2DropDown.Value,'Set Intensity'))
                    params = [app.CentralFrequencyHzEditField_2,app.ThresholdEditField_2,app.IntensityconditionsEditField_2...
                        app.KernellengthEditField_2,app.GainEditField_2,app.PhaseconditionsdegEditField_2]; % list the relevant parameters
                elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Step functions'))
                    params = [app.PulselengthEditField_2,app.PulseofftimeEditField_2,app.PulsespercycleEditField_2,app.IntensityEditField_2];
                elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Random noise'))
                    params = [app.RandomnoisegainEditField_2];
                elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Sinusoidal'))
                    params = [app.GainEditFieldSine_2,app.FrequencyHzEditFieldSine_2,app.PhaseEditField_2];
                else
                    disp('Error: selected algorithm unrecognised')
                    params = 0;
                end
                for i = 1:length(params)
                    loops = loops + length(str2num(params(i).Value)) - isscalar(str2num(params(i).Value)); 
                    % if the parameter is a scalar then the length should be 1, and the
                    % isscalar call will also give one, resulting in zero. 
                end
                if loops == 0 % if the algorithm is not 'no algorithm' and there are no loops, then make this one.
                    loops = 1;
                end
            end
            % if there is a second algorithm with no loops then we just
            % need one extra run to be added to the total time.
            
            secondAlgTime = OneSetRun*loops;
            % Final calculation
            EstTime = firstAlgTime + secondAlgTime; % total time in seconds.
            
            if EstTime>10000
                app.TimeEst.Text = [num2str(EstTime/3600),' hours'];
            elseif EstTime<100000 && EstTime>1000 % do in minutes
                app.TimeEst.Text = [num2str(EstTime/60),' minutes'];
            else 
                app.TimeEst.Text = [num2str(EstTime),' seconds'];
            end
        end
        
        
        function PlotAlg1(app)
            % function to update the figures to show algorithm examples.
            if (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Step functions'))
                onepulse = [zeros(1,str2num(app.PulseofftimeEditField.Value)),ones(1,str2num(app.PulselengthEditField.Value)).*str2num(app.IntensityEditField.Value)];
                allpulses = repmat(onepulse,1,str2num(app.PulsespercycleEditField.Value));
                if (length(allpulses)<app.OntimeEditField.Value)
                    allpulses = [allpulses,zeros(1,app.OntimeEditField.Value-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                plot(app.UIAxes,allpulses)
                xlim(app.UIAxes,[0,app.OntimeEditField.Value])
            elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Random noise'))
                plot(app.UIAxes,randn(1,app.OntimeEditField.Value)*str2num(app.RandomnoisegainEditField.Value))
            elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Sinusoidal'))
                dt = 0.1*(1/str2num(app.FrequencyHzEditFieldSine.Value));
                x = 1:dt:app.OntimeEditField.Value;
                plot(app.UIAxes,str2num(app.GainEditFieldSine.Value).*sin(2.*pi.*str2num(app.FrequencyHzEditFieldSine.Value).*x+str2num(app.PhaseEditField.Value)))
            else
                disp('Error: selected algorithm unrecognised for plotting')
            end              
        end
        
        function PlotAlg2(app)
            % function to update the figures to show algorithm examples.
            if (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Step functions'))
                onepulse = [zeros(1,str2num(app.PulseofftimeEditField_2.Value)),ones(1,str2num(app.PulselengthEditField_2.Value)).*str2num(app.IntensityEditField_2.Value)];
                allpulses = repmat(onepulse,1,str2num(app.PulsespercycleEditField_2.Value));
                if (length(allpulses)<app.OntimeEditField.Value)
                    allpulses = [allpulses,zeros(1,app.OntimeEditField.Value-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                plot(app.UIAxes_2,allpulses)
                xlim(app.UIAxes_2,[0,app.OntimeEditField.Value])
            elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Random noise'))
                plot(app.UIAxes_2,randn(1,app.OntimeEditField.Value)*str2num(app.RandomnoisegainEditField_2.Value))
            elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Sinusoidal'))
                dt = 0.1*(1/str2num(app.FrequencyHzEditFieldSine_2.Value));
                x = 0:dt:app.OntimeEditField.Value;  % Needs work...
                plot(app.UIAxes_2,x,str2num(app.GainEditFieldSine_2.Value).*sin(2.*pi.*str2num(app.FrequencyHzEditFieldSine_2.Value).*x+str2num(app.PhaseEditField_2.Value)))
            else
                disp('Error: selected algorithm unrecognised for plotting')
            end              
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, c)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            %app.Algorithm1parametersPanel.Visible = 'off';
            app.Panel.Visible = 'off';
            app.Panel_2.Visible = 'off';
            %app.Algorithm2parametersPanel.Visible = 'off';
            app.Panel_3.Visible = 'off';
            app.Panel_4.Visible = 'off';
            EstimateTime(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
 
            % need to write a function in the main app to create fields
            % based on the selected parameters and call it here, when the
            % configure button is pushed.
            
            % get current algorithm selections
            alg1.algorithm = app.SelectAlgorithm1DropDown.Value;
            disp('1st algorithm selected is:')
            disp(alg1.algorithm)
            alg2.algorithm = app.SelectAlgorithm2DropDown.Value;            
            disp('2nd algorithm selected is:')
            disp(alg2.algorithm)
            % Get parameter values dependent on algorithm
            if strcmp(alg1.algorithm,'Set Intensity') || strcmp(alg1.algorithm,'Phase Shift')
                alg1.frequency = str2num(app.CentralFrequencyHzEditField.Value);
                alg1.threshold = str2num(app.ThresholdEditField.Value);
                alg1.kernlen = str2num(app.KernellengthEditField.Value);
                alg1.gain = str2num(app.GainEditField.Value);
                alg1.phase = str2num(app.PhaseconditionsdegEditField.Value);
                if strcmp(alg1.algorithm,'Set Intensity')
                    alg1.intensity = str2num(app.IntensityconditionsEditField.Value);
                end
            elseif strcmp(alg1.algorithm,'Open Loop')
                alg1.algorithm = app.OpenlooptypeButtonGroup.SelectedObject.Text;
                disp('Open loop subtype selected is: ')
                disp(alg1.algorithm)
                if strcmp(alg1.algorithm,'Step functions')
                    alg1.intensity = str2num(app.IntensityEditField.Value);
                    alg1.pulselen = str2num(app.PulselengthEditField.Value);
                    alg1.pulseoff = str2num(app.PulseofftimeEditField.Value);
                    alg1.pulsepercyc = str2num(app.PulsespercycleEditField.Value);
                elseif strcmp(alg1.algorithm,'Sinusoidal')
                    alg1.gain = str2num(app.GainEditFieldSine.Value);
                    alg1.frequency = str2num(app.FrequencyHzEditFieldSine.Value);
                    alg1.phase = str2num(app.PhaseEditField.Value);
                elseif strcmp(alg1.algorithm,'Random noise')
                    alg1.gain = str2num(app.RandomnoisegainEditField.Value);
                else
                    disp('WARNING: Open loop selection unrecognised')
                end
            else
                disp('No algorithm selected, was this intentional? If you continue the system will record but no stimulation will be applied.')
            end
            
            if strcmp(alg2.algorithm,'Set Intensity') || strcmp(alg2.algorithm,'Phase Shift')
                alg2.frequency = str2num(app.CentralFrequencyHzEditField_2.Value);
                alg2.threshold = str2num(app.ThresholdEditField_2.Value);
                alg2.kernlen = str2num(app.KernellengthEditField_2.Value);
                alg2.gain = str2num(app.GainEditField_2.Value);
                alg2.phase = str2num(app.PhaseconditionsdegEditField_2.Value);
                if strcmp(alg2.algorithm,'Set Intensity')
                    alg2.intensity = str2num(app.IntensityconditionsEditField_2.Value);
                end
            elseif strcmp(alg2.algorithm,'Open Loop')
                alg2.algorithm = app.OpenlooptypeButtonGroup_2.SelectedObject.Text;
                disp('Open loop subtype selected is: ')
                disp(alg2.algorithm)
                if strcmp(alg2.algorithm,'Step functions')
                    alg2.intensity = str2num(app.IntensityEditField_2.Value);
                    alg2.pulselen = str2num(app.PulselengthEditField_2.Value);
                    alg2.pulseoff = str2num(app.PulseofftimeEditField_2.Value);
                    alg2.pulsepercyc = str2num(app.PulsespercycleEditField_2.Value);
                elseif strcmp(alg2.algorithm,'Sinusoidal')
                    alg2.gain = str2num(app.GainEditFieldSine_2.Value);
                    alg2.frequency = str2num(app.FrequencyHzEditFieldSine_2.Value);
                    alg2.phase = str2num(app.PhaseEditField_2.Value);
                elseif strcmp(alg2.algorithm,'Random noise')
                    alg2.gain = str2num(app.RandomnoisegainEditField_2.Value);
                else
                    disp('WARNING: Open loop selection unrecognised')
                end
            else
                % nothing here
            end
            
            alg1.PseudSeq = app.ButtonGroup.SelectedObject.Text; 
            alg1.OnTime = app.OntimeEditField.Value;
            alg1.OffTime = app.OfftimeEditField.Value;
            
            EstimateTime(app);
            alg1.TimeEst = app.TimeEst.Text;

            % store the algorithms to send to the other GUI.
            setappdata(0,'algorithm_1',alg1)
            setappdata(0,'algorithm_2',alg2)
            % send to designated output
            experimentWriter(app.CallingApp,alg1,alg2)
                            
            try 
                val = getappdata(app.CallingApp.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.')
            end
                
            if val
                replotLoadedData(app.CallingApp)
            end
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ConfigureButton.Enable = 'on';
            
            % Delete the dialog box  
            delete(app)
            
        end

        % Value changed function: SelectAlgorithm1DropDown
        function SelectAlgorithm1DropDownValueChanged(app, event)
            value = app.SelectAlgorithm1DropDown.Value;
            % depending on the algorithm selected, display the relevant
            % parameters.
            disp(value)
            if strcmp(value,'No Algorithm')
                % clear any fields and make sure there is nothing in the
                % fields
                app.Panel.Visible = 'off';
                app.Panel_2.Visible = 'off';
            elseif strcmp(value,'Phase Shift')
                app.Panel_2.Visible = 'off';
                app.Panel.Visible = 'on';
                app.IntensityconditionsEditField.Visible = 'off';
                app.IntensityconditionsEditField_3Label.Visible = 'off';
            elseif strcmp(value,'Set Intensity')
                app.Panel_2.Visible = 'off';
                app.Panel.Visible = 'on';     
                app.IntensityconditionsEditField.Visible = 'on';
                app.IntensityconditionsEditField_3Label.Visible = 'on';
            elseif strcmp(value,'Open Loop')
                app.Panel.Visible = 'off';
                app.Panel_2.Visible = 'on';
                app.OpenlooptypeButtonGroup.Visible = 'on';
            else
                % we shouldn't end up here, so need a warning text to say that no options have been implemented yet for this algorithm.               
                disp('Apologies, this option has not been fully implemented yet.')
            end    
            
             
            EstimateTime(app);


        end

        % Selection changed function: OpenlooptypeButtonGroup
        function OpenlooptypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OpenlooptypeButtonGroup.SelectedObject;
            disp(selectedButton.Text);
            app.UIAxes.Visible = 'on';
            if strcmp(selectedButton.Text,'Random noise')
                app.RandomnoisegainEditField.Visible = 'on';
                app.GainEditField_3Label.Visible = 'on';
                app.SinusoidalPanel.Visible = 'off';
                app.StepfunctionsPanel.Visible = 'off';
            elseif strcmp(selectedButton.Text,'Step functions')
                app.RandomnoisegainEditField.Visible = 'off';
                app.GainEditField_3Label.Visible = 'off';
                app.SinusoidalPanel.Visible = 'off';
                app.StepfunctionsPanel.Visible = 'on';
            elseif strcmp(selectedButton.Text,'Sinusoidal')
                app.RandomnoisegainEditField.Visible = 'off';
                app.GainEditField_3Label.Visible = 'off';
                app.SinusoidalPanel.Visible = 'on';
                app.StepfunctionsPanel.Visible = 'off';
            end
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: SelectAlgorithm2DropDown
        function SelectAlgorithm2DropDownValueChanged(app, event)
            value = app.SelectAlgorithm2DropDown.Value;
               % parameters.
            disp(value)
            if strcmp(value,'No Algorithm')
                % clear any fields and make sure there is nothing in the
                % fields
                app.Panel_3.Visible = 'off';
                app.Panel_4.Visible = 'off';
            elseif strcmp(value,'Phase Shift')
                app.Panel_3.Visible = 'on';
                app.Panel_4.Visible = 'off';
                app.IntensityconditionsEditField_2.Visible = 'off';
                app.IntensityconditionsEditField_4Label.Visible = 'off';
            elseif strcmp(value,'Set Intensity')
                app.Panel_3.Visible = 'on';
                app.Panel_4.Visible = 'off';
                app.IntensityconditionsEditField_2.Visible = 'on';
                app.IntensityconditionsEditField_4Label.Visible = 'on';
            elseif strcmp(value,'Open Loop')
                app.Panel_3.Visible = 'off';
                app.Panel_4.Visible = 'on';
                app.OpenlooptypeButtonGroup_2.Visible = 'on';
            else
                % we shouldn't end up here, so need a warning text to say that no options have been implemented yet for this algorithm.               
                disp('Apologies, this option has not been fully implemented yet.')
            end    
            EstimateTime(app);
        end

        % Selection changed function: OpenlooptypeButtonGroup_2
        function OpenlooptypeButtonGroup_2SelectionChanged(app, event)
            selectedButton2 = app.OpenlooptypeButtonGroup_2.SelectedObject;
            disp(selectedButton2.Text);
            app.UIAxes_2.Visible = 'on';
            if strcmp(selectedButton2.Text,'Random noise')
                app.RandomnoisegainEditField_2.Visible = 'on';
                app.GainEditField_3Label_2.Visible = 'on';
                app.SinusoidalPanel_2.Visible = 'off';
                app.StepfunctionsPanel_2.Visible = 'off';
            elseif strcmp(selectedButton2.Text,'Step functions')
                app.RandomnoisegainEditField_2.Visible = 'off';
                app.GainEditField_3Label_2.Visible = 'off';
                app.SinusoidalPanel_2.Visible = 'off';
                app.StepfunctionsPanel_2.Visible = 'on';
            elseif strcmp(selectedButton2.Text,'Sinusoidal')
                app.RandomnoisegainEditField_2.Visible = 'off';
                app.GainEditField_3Label_2.Visible = 'off';
                app.SinusoidalPanel_2.Visible = 'on';
                app.StepfunctionsPanel_2.Visible = 'off';
            end
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changing function: CentralFrequencyHzEditField
        function CentralFrequencyHzEditFieldValueChanging(app, event)
            changingValue = event.Value;
            EstimateTime(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: KernellengthEditField
        function KernellengthEditFieldValueChanged(app, event)
            value = app.KernellengthEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: IntensityconditionsEditField
        function IntensityconditionsEditFieldValueChanged(app, event)
            value = app.IntensityconditionsEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: PhaseconditionsdegEditField
        function PhaseconditionsdegEditFieldValueChanged(app, event)
            value = app.PhaseconditionsdegEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: PulseofftimeEditField
        function PulseofftimeEditFieldValueChanged(app, event)
            value = app.PulseofftimeEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: IntensityEditField
        function IntensityEditFieldValueChanged(app, event)
            value = app.IntensityEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: PulsespercycleEditField
        function PulsespercycleEditFieldValueChanged(app, event)
            value = app.PulsespercycleEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: PulselengthEditField
        function PulselengthEditFieldValueChanged(app, event)
            value = app.PulselengthEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: GainEditFieldSine
        function GainEditFieldSineValueChanged(app, event)
            value = app.GainEditFieldSine.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: FrequencyHzEditFieldSine
        function FrequencyHzEditFieldSineValueChanged(app, event)
            value = app.FrequencyHzEditFieldSine.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: PhaseEditField
        function PhaseEditFieldValueChanged(app, event)
            value = app.PhaseEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: RandomnoisegainEditField
        function RandomnoisegainEditFieldValueChanged(app, event)
            value = app.RandomnoisegainEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Value changed function: CentralFrequencyHzEditField_2
        function CentralFrequencyHzEditField_2ValueChanged(app, event)
            value = app.CentralFrequencyHzEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: ThresholdEditField_2
        function ThresholdEditField_2ValueChanged(app, event)
            value = app.ThresholdEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: KernellengthEditField_2
        function KernellengthEditField_2ValueChanged(app, event)
            value = app.KernellengthEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: GainEditField_2
        function GainEditField_2ValueChanged(app, event)
            value = app.GainEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: IntensityconditionsEditField_2
        function IntensityconditionsEditField_2ValueChanged(app, event)
            value = app.IntensityconditionsEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: PhaseconditionsdegEditField_2
        function PhaseconditionsdegEditField_2ValueChanged(app, event)
            value = app.PhaseconditionsdegEditField_2.Value;
            EstimateTime(app);
        end

        % Value changed function: PulseofftimeEditField_2
        function PulseofftimeEditField_2ValueChanged(app, event)
            value = app.PulseofftimeEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: IntensityEditField_2
        function IntensityEditField_2ValueChanged(app, event)
            value = app.IntensityEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: PulsespercycleEditField_2
        function PulsespercycleEditField_2ValueChanged(app, event)
            value = app.PulsespercycleEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: PulselengthEditField_2
        function PulselengthEditField_2ValueChanged(app, event)
            value = app.PulselengthEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: GainEditFieldSine_2
        function GainEditFieldSine_2ValueChanged(app, event)
            value = app.GainEditFieldSine_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: FrequencyHzEditFieldSine_2
        function FrequencyHzEditFieldSine_2ValueChanged(app, event)
            value = app.FrequencyHzEditFieldSine_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: PhaseEditField_2
        function PhaseEditField_2ValueChanged(app, event)
            value = app.PhaseEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: RandomnoisegainEditField_2
        function RandomnoisegainEditField_2ValueChanged(app, event)
            value = app.RandomnoisegainEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: OntimeEditField
        function OntimeEditFieldValueChanged(app, event)
            value = app.OntimeEditField.Value;
            EstimateTime(app);
            if strcmp(app.SelectAlgorithm1DropDown.Value,'Open Loop')
                PlotAlg1(app)
            end
            if strcmp(app.SelectAlgorithm2DropDown.Value,'Open Loop')
                PlotAlg2(app)
            end
        end

        % Value changed function: OfftimeEditField
        function OfftimeEditFieldValueChanged(app, event)
            value = app.OfftimeEditField.Value;
            EstimateTime(app);
            if strcmp(app.SelectAlgorithm1DropDown.Value,'Open Loop')
                PlotAlg1(app)
            end
            if strcmp(app.SelectAlgorithm2DropDown.Value,'Open Loop')
                PlotAlg2(app)
            end
        end

        % Button pushed function: LoadtestdataButton
        function LoadtestdataButtonPushed(app, event)
             % Needs to ask the user for a file and plot this on the main
             % figure. This needs to also be doable from the main gui, so
             % best to make a function there that is public and then call
             % that here:
             
             SelectAndPlotData(app.CallingApp);
             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Finish and Configure';
            app.SelectAlgorithm1DropDownLabel = uilabel(app.UIFigure);
            app.SelectAlgorithm1DropDownLabel.Text = 'Select Algorithm 1';
            app.SelectAlgorithm1DropDown = uidropdown(app.UIFigure);
            app.SelectAlgorithm1DropDown.Items = {'No Algorithm', 'Phase Shift', 'Set Intensity', 'Open Loop'};
            app.SelectAlgorithm1DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithm1DropDownValueChanged, true);
            app.SelectAlgorithm1DropDown.Value = 'No Algorithm';
            app.Algorithm1parametersPanel = uipanel(app.UIFigure);
            app.Algorithm1parametersPanel.Title = 'Algorithm 1 parameters';
            app.Panel = uipanel(app.Algorithm1parametersPanel);
            app.Panel.Visible = 'off';
            app.CentralFrequencyHzEditField_3Label = uilabel(app.Panel);
            app.CentralFrequencyHzEditField_3Label.Text = 'Central Frequency (Hz)';
            app.CentralFrequencyHzEditField = uieditfield(app.Panel, 'text');
            app.CentralFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @CentralFrequencyHzEditFieldValueChanging, true);
            app.CentralFrequencyHzEditField.Value = '10';
            app.ThresholdEditField_4Label = uilabel(app.Panel);
            app.ThresholdEditField_4Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '1';
            app.KernellengthEditField_3Label = uilabel(app.Panel);
            app.KernellengthEditField_3Label.Text = 'Kernel length';
            app.KernellengthEditField = uieditfield(app.Panel, 'text');
            app.KernellengthEditField.ValueChangedFcn = createCallbackFcn(app, @KernellengthEditFieldValueChanged, true);
            app.KernellengthEditField.Value = '512';
            app.GainEditField_5Label = uilabel(app.Panel);
            app.GainEditField_5Label.Text = 'Gain';
            app.GainEditField = uieditfield(app.Panel, 'text');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = '1';
            app.IntensityconditionsEditField_3Label = uilabel(app.Panel);
            app.IntensityconditionsEditField_3Label.Text = 'Intensity conditions';
            app.IntensityconditionsEditField = uieditfield(app.Panel, 'text');
            app.IntensityconditionsEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityconditionsEditFieldValueChanged, true);
            app.IntensityconditionsEditField.Value = '1';
            app.PhaseconditionsdegEditField_3Label = uilabel(app.Panel);
            app.PhaseconditionsdegEditField_3Label.Text = 'Phase conditions (deg)';
            app.PhaseconditionsdegEditField = uieditfield(app.Panel, 'text');
            app.PhaseconditionsdegEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseconditionsdegEditFieldValueChanged, true);
            app.PhaseconditionsdegEditField.Value = '0:45:315';
            app.Panel_2 = uipanel(app.Algorithm1parametersPanel);
            app.Panel_2.Visible = 'off';
            app.OpenlooptypeButtonGroup = uibuttongroup(app.Panel_2);
            app.OpenlooptypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @OpenlooptypeButtonGroupSelectionChanged, true);
            app.OpenlooptypeButtonGroup.Title = 'Open loop type';
            app.StepfunctionsButton = uiradiobutton(app.OpenlooptypeButtonGroup);
            app.StepfunctionsButton.Text = 'Step functions';
            app.StepfunctionsButton.Value = true;
            app.RandomnoiseButton = uiradiobutton(app.OpenlooptypeButtonGroup);
            app.RandomnoiseButton.Text = 'Random noise';
            app.SinusoidalButton = uiradiobutton(app.OpenlooptypeButtonGroup);
            app.SinusoidalButton.Text = 'Sinusoidal';
            app.StepfunctionsPanel = uipanel(app.Panel_2);
            app.StepfunctionsPanel.Title = 'Step functions';
            app.StepfunctionsPanel.Visible = 'off';
            app.PulseofftimeEditField_6Label = uilabel(app.StepfunctionsPanel);
            app.PulseofftimeEditField_6Label.Text = 'Pulse off time';
            app.PulseofftimeEditField = uieditfield(app.StepfunctionsPanel, 'text');
            app.PulseofftimeEditField.ValueChangedFcn = createCallbackFcn(app, @PulseofftimeEditFieldValueChanged, true);
            app.PulseofftimeEditField.Value = '1';
            app.IntensityEditField_7Label = uilabel(app.StepfunctionsPanel);
            app.IntensityEditField_7Label.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.StepfunctionsPanel, 'text');
            app.IntensityEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityEditFieldValueChanged, true);
            app.IntensityEditField.Value = '1';
            app.PulsespercycleEditField_6Label = uilabel(app.StepfunctionsPanel);
            app.PulsespercycleEditField_6Label.Text = 'Pulses per cycle';
            app.PulsespercycleEditField = uieditfield(app.StepfunctionsPanel, 'text');
            app.PulsespercycleEditField.ValueChangedFcn = createCallbackFcn(app, @PulsespercycleEditFieldValueChanged, true);
            app.PulsespercycleEditField.Value = '1';
            app.PulselengthEditFieldLabel = uilabel(app.StepfunctionsPanel);
            app.PulselengthEditFieldLabel.Text = 'Pulse length';
            app.PulselengthEditField = uieditfield(app.StepfunctionsPanel, 'text');
            app.PulselengthEditField.ValueChangedFcn = createCallbackFcn(app, @PulselengthEditFieldValueChanged, true);
            app.PulselengthEditField.Value = '1';
            app.SinusoidalPanel = uipanel(app.Panel_2);
            app.SinusoidalPanel.Title = 'Sinusoidal';
            app.SinusoidalPanel.Visible = 'off';
            app.GainEditField_11Label = uilabel(app.SinusoidalPanel);
            app.GainEditField_11Label.Text = 'Gain';
            app.GainEditFieldSine = uieditfield(app.SinusoidalPanel, 'text');
            app.GainEditFieldSine.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldSineValueChanged, true);
            app.GainEditFieldSine.Value = '1';
            app.FrequencyHzEditField_4Label = uilabel(app.SinusoidalPanel);
            app.FrequencyHzEditField_4Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditFieldSine = uieditfield(app.SinusoidalPanel, 'text');
            app.FrequencyHzEditFieldSine.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldSineValueChanged, true);
            app.FrequencyHzEditFieldSine.Value = '10';
            app.PhaseEditField_6Label = uilabel(app.SinusoidalPanel);
            app.PhaseEditField_6Label.Text = 'Phase';
            app.PhaseEditField = uieditfield(app.SinusoidalPanel, 'text');
            app.PhaseEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseEditFieldValueChanged, true);
            app.PhaseEditField.Value = '0';
            app.UIAxes = uiaxes(app.Panel_2);
            title(app.UIAxes, 'Stimulation example')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Intensity')
            app.UIAxes.Visible = 'off';
            app.GainEditField_3Label = uilabel(app.Panel_2);
            app.GainEditField_3Label.Visible = 'off';
            app.GainEditField_3Label.Text = 'Gain';
            app.RandomnoisegainEditField = uieditfield(app.Panel_2, 'text');
            app.RandomnoisegainEditField.ValueChangedFcn = createCallbackFcn(app, @RandomnoisegainEditFieldValueChanged, true);
            app.RandomnoisegainEditField.Visible = 'off';
            app.RandomnoisegainEditField.Value = '1';
            app.ExperimentPlannerLabel = uilabel(app.UIFigure);
            app.ExperimentPlannerLabel.Text = 'Experiment Planner';
            app.SelectAlgorithm2DropDownLabel = uilabel(app.UIFigure);
            app.SelectAlgorithm2DropDownLabel.Text = 'Select Algorithm 2';
            app.SelectAlgorithm2DropDown = uidropdown(app.UIFigure);
            app.SelectAlgorithm2DropDown.Items = {'No Algorithm', 'Phase Shift', 'Set Intensity', 'Open Loop'};
            app.SelectAlgorithm2DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithm2DropDownValueChanged, true);
            app.SelectAlgorithm2DropDown.Value = 'No Algorithm';
            app.AddadditionalalgorithmButton = uibutton(app.UIFigure, 'push');
            app.AddadditionalalgorithmButton.Visible = 'off';
            app.AddadditionalalgorithmButton.Text = 'Add additional algorithm';
            app.OntimeEditFieldLabel = uilabel(app.UIFigure);
            app.OntimeEditFieldLabel.Text = 'On time';
            app.OntimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OntimeEditField.ValueChangedFcn = createCallbackFcn(app, @OntimeEditFieldValueChanged, true);
            app.OfftimeEditFieldLabel = uilabel(app.UIFigure);
            app.OfftimeEditFieldLabel.Text = 'Off time';
            app.OfftimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OfftimeEditField.ValueChangedFcn = createCallbackFcn(app, @OfftimeEditFieldValueChanged, true);
            app.ExpectedexperimentdurationLabel = uilabel(app.UIFigure);
            app.ExpectedexperimentdurationLabel.Text = 'Expected experiment duration: ';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.PseudorandomButton = uitogglebutton(app.ButtonGroup);
            app.PseudorandomButton.Text = 'Pseudorandom';
            app.PseudorandomButton.Value = true;
            app.SequentialButton = uitogglebutton(app.ButtonGroup);
            app.SequentialButton.Text = 'Sequential';
            app.Algorithm2parametersPanel = uipanel(app.UIFigure);
            app.Algorithm2parametersPanel.Title = 'Algorithm 2 parameters';
            app.Panel_3 = uipanel(app.Algorithm2parametersPanel);
            app.Panel_3.Visible = 'off';
            app.CentralFrequencyHzEditField_4Label = uilabel(app.Panel_3);
            app.CentralFrequencyHzEditField_4Label.Text = 'Central Frequency (Hz)';
            app.CentralFrequencyHzEditField_2 = uieditfield(app.Panel_3, 'text');
            app.CentralFrequencyHzEditField_2.ValueChangedFcn = createCallbackFcn(app, @CentralFrequencyHzEditField_2ValueChanged, true);
            app.CentralFrequencyHzEditField_2.Value = '10';
            app.ThresholdEditField_5Label = uilabel(app.Panel_3);
            app.ThresholdEditField_5Label.Text = 'Threshold';
            app.ThresholdEditField_2 = uieditfield(app.Panel_3, 'text');
            app.ThresholdEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditField_2ValueChanged, true);
            app.ThresholdEditField_2.Value = '1';
            app.KernellengthEditField_4Label = uilabel(app.Panel_3);
            app.KernellengthEditField_4Label.Text = 'Kernel length';
            app.KernellengthEditField_2 = uieditfield(app.Panel_3, 'text');
            app.KernellengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @KernellengthEditField_2ValueChanged, true);
            app.KernellengthEditField_2.Value = '512';
            app.GainEditField_8Label = uilabel(app.Panel_3);
            app.GainEditField_8Label.Text = 'Gain';
            app.GainEditField_2 = uieditfield(app.Panel_3, 'text');
            app.GainEditField_2.ValueChangedFcn = createCallbackFcn(app, @GainEditField_2ValueChanged, true);
            app.GainEditField_2.Value = '1';
            app.IntensityconditionsEditField_4Label = uilabel(app.Panel_3);
            app.IntensityconditionsEditField_4Label.Text = 'Intensity conditions';
            app.IntensityconditionsEditField_2 = uieditfield(app.Panel_3, 'text');
            app.IntensityconditionsEditField_2.ValueChangedFcn = createCallbackFcn(app, @IntensityconditionsEditField_2ValueChanged, true);
            app.IntensityconditionsEditField_2.Value = '1';
            app.PhaseconditionsdegEditField_4Label = uilabel(app.Panel_3);
            app.PhaseconditionsdegEditField_4Label.Text = 'Phase conditions (deg)';
            app.PhaseconditionsdegEditField_2 = uieditfield(app.Panel_3, 'text');
            app.PhaseconditionsdegEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhaseconditionsdegEditField_2ValueChanged, true);
            app.PhaseconditionsdegEditField_2.Value = '0:45:315';
            app.Panel_4 = uipanel(app.Algorithm2parametersPanel);
            app.Panel_4.Visible = 'off';
            app.OpenlooptypeButtonGroup_2 = uibuttongroup(app.Panel_4);
            app.OpenlooptypeButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @OpenlooptypeButtonGroup_2SelectionChanged, true);
            app.OpenlooptypeButtonGroup_2.Title = 'Open loop type';
            app.StepfunctionsButton_2 = uiradiobutton(app.OpenlooptypeButtonGroup_2);
            app.StepfunctionsButton_2.Text = 'Step functions';
            app.StepfunctionsButton_2.Value = true;
            app.RandomnoiseButton_2 = uiradiobutton(app.OpenlooptypeButtonGroup_2);
            app.RandomnoiseButton_2.Text = 'Random noise';
            app.SinusoidalButton_2 = uiradiobutton(app.OpenlooptypeButtonGroup_2);
            app.SinusoidalButton_2.Text = 'Sinusoidal';
            app.StepfunctionsPanel_2 = uipanel(app.Panel_4);
            app.StepfunctionsPanel_2.Title = 'Step functions';
            app.StepfunctionsPanel_2.Visible = 'off';
            app.PulseofftimeEditField_9Label = uilabel(app.StepfunctionsPanel_2);
            app.PulseofftimeEditField_9Label.Text = 'Pulse off time';
            app.PulseofftimeEditField_2 = uieditfield(app.StepfunctionsPanel_2, 'text');
            app.PulseofftimeEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulseofftimeEditField_2ValueChanged, true);
            app.PulseofftimeEditField_2.Value = '1';
            app.IntensityEditField_12Label = uilabel(app.StepfunctionsPanel_2);
            app.IntensityEditField_12Label.Text = 'Intensity';
            app.IntensityEditField_2 = uieditfield(app.StepfunctionsPanel_2, 'text');
            app.IntensityEditField_2.ValueChangedFcn = createCallbackFcn(app, @IntensityEditField_2ValueChanged, true);
            app.IntensityEditField_2.Value = '1';
            app.PulsespercycleEditField_9Label = uilabel(app.StepfunctionsPanel_2);
            app.PulsespercycleEditField_9Label.Text = 'Pulses per cycle';
            app.PulsespercycleEditField_2 = uieditfield(app.StepfunctionsPanel_2, 'text');
            app.PulsespercycleEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulsespercycleEditField_2ValueChanged, true);
            app.PulsespercycleEditField_2.Value = '1';
            app.PulselengthEditField_2Label = uilabel(app.StepfunctionsPanel_2);
            app.PulselengthEditField_2Label.Text = 'Pulse length';
            app.PulselengthEditField_2 = uieditfield(app.StepfunctionsPanel_2, 'text');
            app.PulselengthEditField_2.ValueChangedFcn = createCallbackFcn(app, @PulselengthEditField_2ValueChanged, true);
            app.PulselengthEditField_2.Value = '1';
            app.SinusoidalPanel_2 = uipanel(app.Panel_4);
            app.SinusoidalPanel_2.Title = 'Sinusoidal';
            app.SinusoidalPanel_2.Visible = 'off';
            app.GainEditField_14Label = uilabel(app.SinusoidalPanel_2);
            app.GainEditField_14Label.Text = 'Gain';
            app.GainEditFieldSine_2 = uieditfield(app.SinusoidalPanel_2, 'text');
            app.GainEditFieldSine_2.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldSine_2ValueChanged, true);
            app.GainEditFieldSine_2.Value = '1';
            app.FrequencyHzEditField_7Label = uilabel(app.SinusoidalPanel_2);
            app.FrequencyHzEditField_7Label.Text = 'Frequency (Hz)';
            app.FrequencyHzEditFieldSine_2 = uieditfield(app.SinusoidalPanel_2, 'text');
            app.FrequencyHzEditFieldSine_2.ValueChangedFcn = createCallbackFcn(app, @FrequencyHzEditFieldSine_2ValueChanged, true);
            app.FrequencyHzEditFieldSine_2.Value = '10';
            app.PhaseEditField_9Label = uilabel(app.SinusoidalPanel_2);
            app.PhaseEditField_9Label.Text = 'Phase';
            app.PhaseEditField_2 = uieditfield(app.SinusoidalPanel_2, 'text');
            app.PhaseEditField_2.ValueChangedFcn = createCallbackFcn(app, @PhaseEditField_2ValueChanged, true);
            app.PhaseEditField_2.Value = '0';
            app.UIAxes_2 = uiaxes(app.Panel_4);
            title(app.UIAxes_2, 'Stimulation example')
            xlabel(app.UIAxes_2, 'Time')
            ylabel(app.UIAxes_2, 'Intensity')
            app.UIAxes_2.Visible = 'off';
            app.GainEditField_3Label_2 = uilabel(app.Panel_4);
            app.GainEditField_3Label_2.Visible = 'off';
            app.GainEditField_3Label_2.Text = 'Gain';
            app.RandomnoisegainEditField_2 = uieditfield(app.Panel_4, 'text');
            app.RandomnoisegainEditField_2.ValueChangedFcn = createCallbackFcn(app, @RandomnoisegainEditField_2ValueChanged, true);
            app.RandomnoisegainEditField_2.Visible = 'off';
            app.RandomnoisegainEditField_2.Value = '1';
            app.TimeEst = uilabel(app.UIFigure);
            app.TimeEst.Text = '';
            app.LoadtestdataButton = uibutton(app.UIFigure, 'push');
            app.LoadtestdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtestdataButtonPushed, true);
            app.LoadtestdataButton.Text = {'Load test '; 'data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentPlanner(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end