classdef ExperimentPlanner_V22 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Button                         matlab.ui.control.Button
        SelectAlgorithm1DropDownLabel  matlab.ui.control.Label
        SelectAlgorithm1DropDown       matlab.ui.control.DropDown
        Algorithm1parametersPanel      matlab.ui.container.Panel
        Panel                          matlab.ui.container.Panel
        CentralFrequencyHzEditField_3Label  matlab.ui.control.Label
        CentralFrequencyHzEditField    matlab.ui.control.EditField
        ThresholdEditField_4Label      matlab.ui.control.Label
        ThresholdEditField             matlab.ui.control.EditField
        KernellengthEditField_3Label   matlab.ui.control.Label
        KernellengthEditField          matlab.ui.control.EditField
        GainEditField_5Label           matlab.ui.control.Label
        GainEditField                  matlab.ui.control.EditField
        IntensityconditionsEditField_3Label  matlab.ui.control.Label
        IntensityconditionsEditField   matlab.ui.control.EditField
        PhaseconditionsdegEditField_3Label  matlab.ui.control.Label
        PhaseconditionsdegEditField    matlab.ui.control.EditField
        ExperimentPlannerLabel         matlab.ui.control.Label
        AddadditionalalgorithmButton   matlab.ui.control.Button
        OntimeEditFieldLabel           matlab.ui.control.Label
        OntimeEditField                matlab.ui.control.NumericEditField
        OfftimeEditFieldLabel          matlab.ui.control.Label
        OfftimeEditField               matlab.ui.control.NumericEditField
        ExpectedexperimentdurationLabel  matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        PseudorandomButton             matlab.ui.control.ToggleButton
        SequentialButton               matlab.ui.control.ToggleButton
        TimeEst                        matlab.ui.control.Label
        LoadtestdataButton             matlab.ui.control.Button
    end


    properties (Access = private)
        CallingApp   % Main app object
        TimeEstimate
    end
    
    methods (Access = private)
        
        function EstTime = EstimateTime(app)
            % Calculate the total experiment time for one full run
            % Start by getting the time for one loop:
            OneSetRun = app.OntimeEditField.Value + app.OfftimeEditField.Value; 
            % Now need to check for parameter loops in the first algorithm:
            loops = 0; % start with zero. Add one for each algorithm, and one for each parameter in a parameter loop?
            
            % check algorithm, then check the appropriate parameters
            if (strcmp(app.SelectAlgorithm1DropDown.Value,'No Algorithm'))
                disp('no algorithm selected for the 1st, so no time to run')                
            else
                if (strcmp(app.SelectAlgorithm1DropDown.Value,'Phase Shift'))
                    params = [app.CentralFrequencyHzEditField,app.ThresholdEditField, ...
                        app.KernellengthEditField,app.GainEditField,app.PhaseconditionsdegEditField]; % list the relevant parameters
                elseif (strcmp(app.SelectAlgorithm1DropDown.Value,'Set Intensity'))
                    params = [app.CentralFrequencyHzEditField,app.ThresholdEditField,app.IntensityconditionsEditField   ...
                        app.KernellengthEditField,app.GainEditField,app.PhaseconditionsdegEditField]; % list the relevant parameters
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Step functions'))
                    params = [app.PulselengthEditField,app.PulseofftimeEditField,app.PulsespercycleEditField,app.IntensityEditField];
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Random noise'))
                    params = [app.RandomnoisegainEditField];
                elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Sinusoidal'))
                    params = [app.GainEditFieldSine,app.FrequencyHzEditFieldSine,app.PhaseEditField];
                elseif (strcmp(app.SelectAlgorithm1DropDown.Value,'Compare Both'))
                    params = [app.CentralFrequencyHzEditField,app.ThresholdEditField,app.IntensityconditionsEditField   ...
                        app.KernellengthEditField,app.GainEditField,app.PhaseconditionsdegEditField,app.GainEditField,  ...
                        app.PhaseconditionsdegEditField]; % list the relevant parameters, repeating gain and phase in this case
                else
                    disp('Error: selected algorithm unrecognised')
                    params = 0;
                end
                
                loops = 1;
                for i = 1:length(params)
                    currpar = str2num(params(i).Value);
                    if length(currpar)>1
                        % find the parameter length
                        npars(i) = ((currpar(3) - currpar(1)) / currpar(2)) + 1;
                    else
                        npars(i) = 1;
                    end
                    %loops = loops + length(str2num(params(i).Value)) - isscalar(str2num(params(i).Value));
                    loops = loops * npars(i);
                end
            end
            
            firstAlgTime = OneSetRun*loops;
            
%             loops = 0; % reset for the second algorithm and repeat.

            % is there a second algorithm? If not then we are done. If
            % there is need to check for loops.
%             if (strcmp(app.SelectAlgorithm2DropDown.Value,'No Algorithm'))
%                 disp('no algorithm selected for the 2nd, so no time to run')                
%             else
%                 if (strcmp(app.SelectAlgorithm2DropDown.Value,'Phase Shift'))
%                     params = [app.CentralFrequencyHzEditField_2,app.ThresholdEditField_2, ...
%                         app.KernellengthEditField_2,app.GainEditField_2,app.PhaseconditionsdegEditField_2]; % list the relevant parameters
%                 elseif (strcmp(app.SelectAlgorithm2DropDown.Value,'Set Intensity'))
%                     params = [app.CentralFrequencyHzEditField_2,app.ThresholdEditField_2,app.IntensityconditionsEditField_2...
%                         app.KernellengthEditField_2,app.GainEditField_2,app.PhaseconditionsdegEditField_2]; % list the relevant parameters
%                 elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Step functions'))
%                     params = [app.PulselengthEditField_2,app.PulseofftimeEditField_2,app.PulsespercycleEditField_2,app.IntensityEditField_2];
%                 elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Random noise'))
%                     params = [app.RandomnoisegainEditField_2];
%                 elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Sinusoidal'))
%                     params = [app.GainEditFieldSine_2,app.FrequencyHzEditFieldSine_2,app.PhaseEditField_2];
%                 else
%                     disp('Error: selected algorithm unrecognised')
%                     params = 0;
%                 end
%                 for i = 1:length(params)
%                     loops = loops + length(str2num(params(i).Value)) - isscalar(str2num(params(i).Value)); 
%                     % if the parameter is a scalar then the length should be 1, and the
%                     % isscalar call will also give one, resulting in zero. 
%                 end
%                 if loops == 0 % if the algorithm is not 'no algorithm' and there are no loops, then make this one.
%                     loops = 1;
%                 end
%             end
            % if there is a second algorithm with no loops then we just
            % need one extra run to be added to the total time.
            
%             secondAlgTime = OneSetRun*loops;
            % Final calculation
            EstTime = firstAlgTime; % + secondAlgTime; %divide by 1000 to get total time in seconds.
            app.TimeEstimate = loops;
            
            if EstTime>10000
                app.TimeEst.Text = [num2str(EstTime/3600),' hours'];
            elseif EstTime<100000 && EstTime>1000 % do in minutes
                app.TimeEst.Text = [num2str(EstTime/60),' minutes'];
            else 
                app.TimeEst.Text = [num2str(EstTime),' seconds'];
            end
        end
        
        
        function PlotAlg1(app)
            % function to update the figures to show algorithm examples.
            if (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Step functions'))
                onepulse = [zeros(1,str2num(app.PulseofftimeEditField.Value)),ones(1,str2num(app.PulselengthEditField.Value)).*str2num(app.IntensityEditField.Value)];
                allpulses = repmat(onepulse,1,str2num(app.PulsespercycleEditField.Value));
                if (length(allpulses)<app.OntimeEditField.Value)
                    allpulses = [allpulses,zeros(1,app.OntimeEditField.Value-length(allpulses))];
                elseif (length(allpulses)>app.OntimeEditField.Value)
                    disp('WARNING: There are more pulses requested than will fit in the on time.')
                    disp('The number of pulses per cycle may be less than the user requested amount.')
                end
                plot(app.UIAxes,allpulses)
                xlim(app.UIAxes,[0,app.OntimeEditField.Value])
            elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Random noise'))
                plot(app.UIAxes,randn(1,app.OntimeEditField.Value)*str2num(app.RandomnoisegainEditField.Value))
            elseif (strcmp(app.OpenlooptypeButtonGroup.SelectedObject.Text,'Sinusoidal'))
                dt = 0.1*(1/str2num(app.FrequencyHzEditFieldSine.Value));
                x = 1:dt:app.OntimeEditField.Value;
                plot(app.UIAxes,str2num(app.GainEditFieldSine.Value).*sin(2.*pi.*str2num(app.FrequencyHzEditFieldSine.Value).*x+str2num(app.PhaseEditField.Value)))
            else
                disp('Error: selected algorithm unrecognised for plotting')
            end              
        end
        
%         function PlotAlg2(app)
%             % function to update the figures to show algorithm examples.
%             if (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Step functions'))
%                 onepulse = [zeros(1,str2num(app.PulseofftimeEditField_2.Value)),ones(1,str2num(app.PulselengthEditField_2.Value)).*str2num(app.IntensityEditField_2.Value)];
%                 allpulses = repmat(onepulse,1,str2num(app.PulsespercycleEditField_2.Value));
%                 if (length(allpulses)<app.OntimeEditField.Value)
%                     allpulses = [allpulses,zeros(1,app.OntimeEditField.Value-length(allpulses))];
%                 elseif (length(allpulses)>app.OntimeEditField.Value)
%                     disp('WARNING: There are more pulses requested than will fit in the on time.')
%                     disp('The number of pulses per cycle may be less than the user requested amount.')
%                 end
%                 plot(app.UIAxes_2,allpulses)
%                 xlim(app.UIAxes_2,[0,app.OntimeEditField.Value])
%             elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Random noise'))
%                 plot(app.UIAxes_2,randn(1,app.OntimeEditField.Value)*str2num(app.RandomnoisegainEditField_2.Value))
%             elseif (strcmp(app.OpenlooptypeButtonGroup_2.SelectedObject.Text,'Sinusoidal'))
%                 dt = 0.1*(1/str2num(app.FrequencyHzEditFieldSine_2.Value));
%                 x = 0:dt:app.OntimeEditField.Value;  % Needs work...
%                 plot(app.UIAxes_2,x,str2num(app.GainEditFieldSine_2.Value).*sin(2.*pi.*str2num(app.FrequencyHzEditFieldSine_2.Value).*x+str2num(app.PhaseEditField_2.Value)))
%             else
%                 disp('Error: selected algorithm unrecognised for plotting')
%             end              
%         end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartupFcn(app, mainapp, sz, c)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            %app.Algorithm1parametersPanel.Visible = 'off';
            app.Panel.Visible = 'off';
%             app.Panel_2.Visible = 'off';
            %app.Algorithm2parametersPanel.Visible = 'off';
%             app.Panel_3.Visible = 'off';
%             app.Panel_4.Visible = 'off';
            EstimateTime(app)
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
 
            
            % get current algorithm selections
            alg1.algorithm = app.SelectAlgorithm1DropDown.Value;
            disp('1st algorithm selected is:')
            disp(alg1.algorithm)
%             alg2.algorithm = app.SelectAlgorithm2DropDown.Value;            
%             disp('2nd algorithm selected is:')
%             disp(alg2.algorithm)
            % Get parameter values dependent on algorithm
            if strcmp(alg1.algorithm,'Set Intensity') || strcmp(alg1.algorithm,'Phase Shift') || strcmp(alg1.algorithm,'Compare Both')
                alg1.frequency = str2num(app.CentralFrequencyHzEditField.Value);
                alg1.threshold = str2num(app.ThresholdEditField.Value);
                alg1.kernlen = str2num(app.KernellengthEditField.Value);
                alg1.gain = str2num(app.GainEditField.Value);
                alg1.phase = str2num(app.PhaseconditionsdegEditField.Value);
                if strcmp(alg1.algorithm,'Set Intensity') || strcmp(alg1.algorithm,'Compare Both')
                    alg1.intensity = str2num(app.IntensityconditionsEditField.Value);
                end
            elseif strcmp(alg1.algorithm,'Open Loop')
                alg1.algorithm = app.OpenlooptypeButtonGroup.SelectedObject.Text;
                disp('Open loop subtype selected is: ')
                disp(alg1.algorithm)
                if strcmp(alg1.algorithm,'Step functions')
                    alg1.intensity = str2num(app.IntensityEditField.Value);
                    alg1.pulselen = str2num(app.PulselengthEditField.Value);
                    alg1.pulseoff = str2num(app.PulseofftimeEditField.Value);
                    alg1.pulsepercyc = str2num(app.PulsespercycleEditField.Value);
                elseif strcmp(alg1.algorithm,'Sinusoidal')
                    alg1.gain = str2num(app.GainEditFieldSine.Value);
                    alg1.frequency = str2num(app.FrequencyHzEditFieldSine.Value);
                    alg1.phase = str2num(app.PhaseEditField.Value);
                elseif strcmp(alg1.algorithm,'Random noise')
                    alg1.gain = str2num(app.RandomnoisegainEditField.Value);
                else
                    disp('WARNING: Open loop selection unrecognised')
                end
            else
                disp('No algorithm selected, was this intentional? If you continue the system will record but no stimulation will be applied.')
            end
            
%             if strcmp(alg2.algorithm,'Set Intensity') || strcmp(alg2.algorithm,'Phase Shift')
%                 alg2.frequency = str2num(app.CentralFrequencyHzEditField_2.Value);
%                 alg2.threshold = str2num(app.ThresholdEditField_2.Value);
%                 alg2.kernlen = str2num(app.KernellengthEditField_2.Value);
%                 alg2.gain = str2num(app.GainEditField_2.Value);
%                 alg2.phase = str2num(app.PhaseconditionsdegEditField_2.Value);
%                 if strcmp(alg2.algorithm,'Set Intensity')
%                     alg2.intensity = str2num(app.IntensityconditionsEditField_2.Value);
%                 end
%             elseif strcmp(alg2.algorithm,'Open Loop')
%                 alg2.algorithm = app.OpenlooptypeButtonGroup_2.SelectedObject.Text;
%                 disp('Open loop subtype selected is: ')
%                 disp(alg2.algorithm)
%                 if strcmp(alg2.algorithm,'Step functions')
%                     alg2.intensity = str2num(app.IntensityEditField_2.Value);
%                     alg2.pulselen = str2num(app.PulselengthEditField_2.Value);
%                     alg2.pulseoff = str2num(app.PulseofftimeEditField_2.Value);
%                     alg2.pulsepercyc = str2num(app.PulsespercycleEditField_2.Value);
%                 elseif strcmp(alg2.algorithm,'Sinusoidal')
%                     alg2.gain = str2num(app.GainEditFieldSine_2.Value);
%                     alg2.frequency = str2num(app.FrequencyHzEditFieldSine_2.Value);
%                     alg2.phase = str2num(app.PhaseEditField_2.Value);
%                 elseif strcmp(alg2.algorithm,'Random noise')
%                     alg2.gain = str2num(app.RandomnoisegainEditField_2.Value);
%                 else
%                     disp('WARNING: Open loop selection unrecognised')
%                 end
%             else
%                 % nothing here
%             end
            
            alg1.PseudSeq = app.ButtonGroup.SelectedObject.Text; 
            alg1.OnTime = app.OntimeEditField.Value;
            alg1.OffTime = app.OfftimeEditField.Value;
            
            EstimateTime(app);
            alg1.TimeEst = app.TimeEst.Text;
            alg1.TimeEstNum = app.TimeEstimate;
             
            % store the algorithms to send to the other GUI.
            setappdata(0,'algorithm_1',alg1)
            alg2.algorithm =  'No Algorithm';
            setappdata(0,'algorithm_2',alg2)
            % send to designated output
            
            experimentWriter(app.CallingApp,alg1,alg2)
                            
            try 
                val = getappdata(app.CallingApp.LFPGraph,'dataloaded');
                disp(val)
            catch ME
                val = false;
                disp('No static data loaded.')
            end
                
            if val
                replotLoadedData(app.CallingApp)
            end
            
            passParameters(app.CallingApp)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: UIFigure
        function DialogAppCloseRequest(app, event)
            % Enable the Plot Opions button in main app
            app.CallingApp.ConfigureButton.Enable = 'on';
            
            % Delete the dialog box  
            delete(app)
            
        end

        % Value changed function: SelectAlgorithm1DropDown
        function SelectAlgorithm1DropDownValueChanged(app, event)
            value = app.SelectAlgorithm1DropDown.Value;
            % depending on the algorithm selected, display the relevant
            % parameters.
            disp(value)
            if strcmp(value,'No Algorithm')
                % clear any fields and make sure there is nothing in the
                % fields
                app.Panel.Visible = 'off';
%                 app.Panel_2.Visible = 'off';
            elseif strcmp(value,'Phase Shift')
%                 app.Panel_2.Visible = 'off';
                app.Panel.Visible = 'on';
                app.IntensityconditionsEditField.Visible = 'off';
                app.IntensityconditionsEditField_3Label.Visible = 'off';
            elseif strcmp(value,'Set Intensity')
%                 app.Panel_2.Visible = 'off';
                app.Panel.Visible = 'on';     
                app.IntensityconditionsEditField.Visible = 'on';
                app.IntensityconditionsEditField_3Label.Visible = 'on';
%             elseif strcmp(value,'Open Loop')
%                 app.Panel.Visible = 'off';
%                 app.Panel_2.Visible = 'on';
%                 app.OpenlooptypeButtonGroup.Visible = 'on';
            elseif strcmp(value,'Compare Both')
                app.Panel.Visible = 'on';   
                app.IntensityconditionsEditField.Visible = 'on';
                app.IntensityconditionsEditField_3Label.Visible = 'on';
                disp(['Selected to compare both algorithms, a Phase Shift and' ...
                    ' a Set Intensity algorithm will be initiated for the same' ...
                    ' phase and gain conditions and the box with test these together'])
            else
                % we shouldn't end up here, so need a warning text to say that no options have been implemented yet for this algorithm.               
                disp('Apologies, this option has not been fully implemented yet.')
            end    
            
             
            EstimateTime(app);


        end

        % Callback function
        function OpenlooptypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.OpenlooptypeButtonGroup.SelectedObject;
            disp(selectedButton.Text);
            app.UIAxes.Visible = 'on';
            if strcmp(selectedButton.Text,'Random noise')
                app.RandomnoisegainEditField.Visible = 'on';
                app.GainEditField_3Label.Visible = 'on';
                app.SinusoidalPanel.Visible = 'off';
                app.StepfunctionsPanel.Visible = 'off';
            elseif strcmp(selectedButton.Text,'Step functions')
                app.RandomnoisegainEditField.Visible = 'off';
                app.GainEditField_3Label.Visible = 'off';
                app.SinusoidalPanel.Visible = 'off';
                app.StepfunctionsPanel.Visible = 'on';
            elseif strcmp(selectedButton.Text,'Sinusoidal')
                app.RandomnoisegainEditField.Visible = 'off';
                app.GainEditField_3Label.Visible = 'off';
                app.SinusoidalPanel.Visible = 'on';
                app.StepfunctionsPanel.Visible = 'off';
            end
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function SelectAlgorithm2DropDownValueChanged(app, event)
            value = app.SelectAlgorithm2DropDown.Value;
               % parameters.
            disp(value)
            if strcmp(value,'No Algorithm')
                % clear any fields and make sure there is nothing in the
                % fields
                app.Panel.Visible = 'off';
            elseif strcmp(value,'Phase Shift')
                app.Panel.Visible = 'on';
                app.IntensityconditionsEditField.Visible = 'off';
                app.IntensityconditionsEditField.Visible = 'off';
            elseif strcmp(value,'Set Intensity')
                app.Panel.Visible = 'on';
                app.IntensityconditionsEditField.Visible = 'on';
                app.IntensityconditionsEditField.Visible = 'on';
            %elseif strcmp(value,'Open Loop')
            %    app.Panel.Visible = 'off';
            %    app.OpenlooptypeButtonGroup_2.Visible = 'on';
            else
                % we shouldn't end up here, so need a warning text to say that no options have been implemented yet for this algorithm.               
                disp('Apologies, this option has not been fully implemented yet.')
            end    
            EstimateTime(app);
        end

        % Callback function
        function OpenlooptypeButtonGroup_2SelectionChanged(app, event)
            selectedButton2 = app.OpenlooptypeButtonGroup_2.SelectedObject;
            disp(selectedButton2.Text);
            app.UIAxes_2.Visible = 'on';
            if strcmp(selectedButton2.Text,'Random noise')
                app.RandomnoisegainEditField_2.Visible = 'on';
                app.GainEditField_3Label_2.Visible = 'on';
                app.SinusoidalPanel_2.Visible = 'off';
                app.StepfunctionsPanel_2.Visible = 'off';
            elseif strcmp(selectedButton2.Text,'Step functions')
                app.RandomnoisegainEditField_2.Visible = 'off';
                app.GainEditField_3Label_2.Visible = 'off';
                app.SinusoidalPanel_2.Visible = 'off';
                app.StepfunctionsPanel_2.Visible = 'on';
            elseif strcmp(selectedButton2.Text,'Sinusoidal')
                app.RandomnoisegainEditField_2.Visible = 'off';
                app.GainEditField_3Label_2.Visible = 'off';
                app.SinusoidalPanel_2.Visible = 'on';
                app.StepfunctionsPanel_2.Visible = 'off';
            end
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changing function: CentralFrequencyHzEditField
        function CentralFrequencyHzEditFieldValueChanging(app, event)
            changingValue = event.Value;
            EstimateTime(app);
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: KernellengthEditField
        function KernellengthEditFieldValueChanged(app, event)
            value = app.KernellengthEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: IntensityconditionsEditField
        function IntensityconditionsEditFieldValueChanged(app, event)
            value = app.IntensityconditionsEditField.Value;
            EstimateTime(app);
        end

        % Value changed function: PhaseconditionsdegEditField
        function PhaseconditionsdegEditFieldValueChanged(app, event)
            value = app.PhaseconditionsdegEditField.Value;
            EstimateTime(app);
        end

        % Callback function
        function PulseofftimeEditFieldValueChanged(app, event)
            value = app.PulseofftimeEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function IntensityEditFieldValueChanged(app, event)
            value = app.IntensityEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function PulsespercycleEditFieldValueChanged(app, event)
            value = app.PulsespercycleEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function PulselengthEditFieldValueChanged(app, event)
            value = app.PulselengthEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function GainEditFieldSineValueChanged(app, event)
            value = app.GainEditFieldSine.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function FrequencyHzEditFieldSineValueChanged(app, event)
            value = app.FrequencyHzEditFieldSine.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function PhaseEditFieldValueChanged(app, event)
            value = app.PhaseEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function RandomnoisegainEditFieldValueChanged(app, event)
            value = app.RandomnoisegainEditField.Value;
            EstimateTime(app);
            PlotAlg1(app)
        end

        % Callback function
        function CentralFrequencyHzEditField_2ValueChanged(app, event)
            value = app.CentralFrequencyHzEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function ThresholdEditField_2ValueChanged(app, event)
            value = app.ThresholdEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function KernellengthEditField_2ValueChanged(app, event)
            value = app.KernellengthEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function GainEditField_2ValueChanged(app, event)
            value = app.GainEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function IntensityconditionsEditField_2ValueChanged(app, event)
            value = app.IntensityconditionsEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function PhaseconditionsdegEditField_2ValueChanged(app, event)
            value = app.PhaseconditionsdegEditField_2.Value;
            EstimateTime(app);
        end

        % Callback function
        function PulseofftimeEditField_2ValueChanged(app, event)
            value = app.PulseofftimeEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function IntensityEditField_2ValueChanged(app, event)
            value = app.IntensityEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function PulsespercycleEditField_2ValueChanged(app, event)
            value = app.PulsespercycleEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function PulselengthEditField_2ValueChanged(app, event)
            value = app.PulselengthEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function GainEditFieldSine_2ValueChanged(app, event)
            value = app.GainEditFieldSine_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function FrequencyHzEditFieldSine_2ValueChanged(app, event)
            value = app.FrequencyHzEditFieldSine_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function PhaseEditField_2ValueChanged(app, event)
            value = app.PhaseEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Callback function
        function RandomnoisegainEditField_2ValueChanged(app, event)
            value = app.RandomnoisegainEditField_2.Value;
            EstimateTime(app);
            PlotAlg2(app)
        end

        % Value changed function: OntimeEditField
        function OntimeEditFieldValueChanged(app, event)
            value = app.OntimeEditField.Value;
            EstimateTime(app);
            if strcmp(app.SelectAlgorithm1DropDown.Value,'Open Loop')
                PlotAlg1(app)
            end
%             if strcmp(app.SelectAlgorithm2DropDown.Value,'Open Loop')
%                 PlotAlg2(app)
%             end
        end

        % Value changed function: OfftimeEditField
        function OfftimeEditFieldValueChanged(app, event)
            value = app.OfftimeEditField.Value;
            EstimateTime(app);
            if strcmp(app.SelectAlgorithm1DropDown.Value,'Open Loop')
                PlotAlg1(app)
            end
%             if strcmp(app.SelectAlgorithm2DropDown.Value,'Open Loop')
%                 PlotAlg2(app)
%             end
        end

        % Button pushed function: LoadtestdataButton
        function LoadtestdataButtonPushed(app, event)
             % Needs to ask the user for a file and plot this on the main
             % figure. This needs to also be doable from the main gui, so
             % best to make a function there that is public and then call
             % that here:
             
             SelectAndPlotData(app.CallingApp);
             
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Options';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @DialogAppCloseRequest, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Finish and Configure';
            app.SelectAlgorithm1DropDownLabel = uilabel(app.UIFigure);
            app.SelectAlgorithm1DropDownLabel.Text = 'Select Algorithm 1';
            app.SelectAlgorithm1DropDown = uidropdown(app.UIFigure);
            app.SelectAlgorithm1DropDown.Items = {'No Algorithm', 'Phase Shift', 'Set Intensity', 'Compare Both'};
            app.SelectAlgorithm1DropDown.ValueChangedFcn = createCallbackFcn(app, @SelectAlgorithm1DropDownValueChanged, true);
            app.SelectAlgorithm1DropDown.Value = 'No Algorithm';
            app.Algorithm1parametersPanel = uipanel(app.UIFigure);
            app.Algorithm1parametersPanel.Title = 'Algorithm 1 parameters';
            app.Panel = uipanel(app.Algorithm1parametersPanel);
            app.Panel.Visible = 'off';
            app.CentralFrequencyHzEditField_3Label = uilabel(app.Panel);
            app.CentralFrequencyHzEditField_3Label.Text = 'Central Frequency (Hz)';
            app.CentralFrequencyHzEditField = uieditfield(app.Panel, 'text');
            app.CentralFrequencyHzEditField.ValueChangingFcn = createCallbackFcn(app, @CentralFrequencyHzEditFieldValueChanging, true);
            app.CentralFrequencyHzEditField.Value = '10';
            app.ThresholdEditField_4Label = uilabel(app.Panel);
            app.ThresholdEditField_4Label.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.Panel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '1';
            app.KernellengthEditField_3Label = uilabel(app.Panel);
            app.KernellengthEditField_3Label.Text = 'Kernel length';
            app.KernellengthEditField = uieditfield(app.Panel, 'text');
            app.KernellengthEditField.ValueChangedFcn = createCallbackFcn(app, @KernellengthEditFieldValueChanged, true);
            app.KernellengthEditField.Value = '512';
            app.GainEditField_5Label = uilabel(app.Panel);
            app.GainEditField_5Label.Text = 'Gain';
            app.GainEditField = uieditfield(app.Panel, 'text');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.GainEditField.Value = '1';
            app.IntensityconditionsEditField_3Label = uilabel(app.Panel);
            app.IntensityconditionsEditField_3Label.Text = 'Intensity conditions';
            app.IntensityconditionsEditField = uieditfield(app.Panel, 'text');
            app.IntensityconditionsEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityconditionsEditFieldValueChanged, true);
            app.IntensityconditionsEditField.Value = '1000';
            app.PhaseconditionsdegEditField_3Label = uilabel(app.Panel);
            app.PhaseconditionsdegEditField_3Label.Text = 'Phase conditions (deg)';
            app.PhaseconditionsdegEditField = uieditfield(app.Panel, 'text');
            app.PhaseconditionsdegEditField.ValueChangedFcn = createCallbackFcn(app, @PhaseconditionsdegEditFieldValueChanged, true);
            app.PhaseconditionsdegEditField.Value = '0,45,315';
            app.ExperimentPlannerLabel = uilabel(app.UIFigure);
            app.ExperimentPlannerLabel.Text = 'Experiment Planner';
            app.AddadditionalalgorithmButton = uibutton(app.UIFigure, 'push');
            app.AddadditionalalgorithmButton.Visible = 'off';
            app.AddadditionalalgorithmButton.Text = 'Add additional algorithm';
            app.OntimeEditFieldLabel = uilabel(app.UIFigure);
            app.OntimeEditFieldLabel.Text = 'On time';
            app.OntimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OntimeEditField.ValueChangedFcn = createCallbackFcn(app, @OntimeEditFieldValueChanged, true);
            app.OfftimeEditFieldLabel = uilabel(app.UIFigure);
            app.OfftimeEditFieldLabel.Text = 'Off time';
            app.OfftimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.OfftimeEditField.ValueChangedFcn = createCallbackFcn(app, @OfftimeEditFieldValueChanged, true);
            app.ExpectedexperimentdurationLabel = uilabel(app.UIFigure);
            app.ExpectedexperimentdurationLabel.Text = 'Expected experiment duration: ';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.PseudorandomButton = uitogglebutton(app.ButtonGroup);
            app.PseudorandomButton.Text = 'Pseudorandom';
            app.PseudorandomButton.Value = true;
            app.SequentialButton = uitogglebutton(app.ButtonGroup);
            app.SequentialButton.Text = 'Sequential';
            app.TimeEst = uilabel(app.UIFigure);
            app.TimeEst.Text = '';
            app.LoadtestdataButton = uibutton(app.UIFigure, 'push');
            app.LoadtestdataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtestdataButtonPushed, true);
            app.LoadtestdataButton.Text = {'Load test '; 'data'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ExperimentPlanner_V22(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)StartupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end