classdef optrode_selector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Image                          matlab.ui.control.Image
        SelectOptrodeDropDownLabel     matlab.ui.control.Label
        SelectOptrodeDropDown          matlab.ui.control.DropDown
        AlgorithmElectrodeButtonGroup  matlab.ui.container.ButtonGroup
        Button                         matlab.ui.control.RadioButton
        Button_2                       matlab.ui.control.RadioButton
        Button_3                       matlab.ui.control.RadioButton
        Electrode1LampLabel            matlab.ui.control.Label
        Electrode1Lamp                 matlab.ui.control.Lamp
        Electrode2LampLabel            matlab.ui.control.Label
        Electrode2Lamp                 matlab.ui.control.Lamp
        Electrode3LampLabel            matlab.ui.control.Label
        Electrode3Lamp                 matlab.ui.control.Lamp
        CheckBox                       matlab.ui.control.CheckBox
        CheckBox_2                     matlab.ui.control.CheckBox
        CheckBox_3                     matlab.ui.control.CheckBox
        CheckBox_4                     matlab.ui.control.CheckBox
        CheckBox_5                     matlab.ui.control.CheckBox
        CheckBox_6                     matlab.ui.control.CheckBox
        CheckBox_7                     matlab.ui.control.CheckBox
        CheckBox_8                     matlab.ui.control.CheckBox
        CheckBox_9                     matlab.ui.control.CheckBox
        Shank1Label                    matlab.ui.control.Label
        Shank2Label                    matlab.ui.control.Label
        Shank3Label                    matlab.ui.control.Label
        LED1Label                      matlab.ui.control.Label
        LED2Label                      matlab.ui.control.Label
        LED3Label                      matlab.ui.control.Label
        LED4Label                      matlab.ui.control.Label
        LED5Label                      matlab.ui.control.Label
        LED6Label                      matlab.ui.control.Label
        LED7Label                      matlab.ui.control.Label
        LED8Label                      matlab.ui.control.Label
        LED9Label                      matlab.ui.control.Label
        OptrodeSelectorPanel           matlab.ui.container.Panel
    end

    
    properties (Access = private)
        CallingApp % Description
        Electrode = 1
        SelectedLEDs = [];
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: AlgorithmElectrodeButtonGroup
        function AlgorithmElectrodeButtonGroupSelectionChanged(app, event)
            selectedButton = app.AlgorithmElectrodeButtonGroup.SelectedObject;
            if selectedButton == app.Button
                app.Electrode1Lamp.Color = 'g';
                app.Electrode2Lamp.Color = 'w';
                app.Electrode3Lamp.Color = 'w';
                app.Electrode = 1;
            elseif selectedButton == app.Button_2
                app.Electrode1Lamp.Color = 'w';
                app.Electrode2Lamp.Color = 'g';
                app.Electrode3Lamp.Color = 'w';
                app.Electrode = 2;
            elseif selectedButton == app.Button_3
                app.Electrode1Lamp.Color = 'w';
                app.Electrode2Lamp.Color = 'w';
                app.Electrode3Lamp.Color = 'g';
                app.Electrode = 3;
            end
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Image = uiimage(app.UIFigure);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'otrode3_2.png';
            app.SelectOptrodeDropDownLabel = uilabel(app.UIFigure);
            app.SelectOptrodeDropDownLabel.Text = 'Select Optrode:';
            app.SelectOptrodeDropDown = uidropdown(app.UIFigure);
            app.SelectOptrodeDropDown.Items = {'Single Shaft', 'Multifork 3', 'Multifork 4', ''};
            app.SelectOptrodeDropDown.Value = 'Multifork 3';
            app.AlgorithmElectrodeButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgorithmElectrodeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AlgorithmElectrodeButtonGroupSelectionChanged, true);
            app.AlgorithmElectrodeButtonGroup.Title = 'Algorithm Electrode';
            app.Button = uiradiobutton(app.AlgorithmElectrodeButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.AlgorithmElectrodeButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.AlgorithmElectrodeButtonGroup);
            app.Button_3.Text = '3';
            app.Electrode1LampLabel = uilabel(app.UIFigure);
            app.Electrode1LampLabel.Text = 'Electrode 1';
            app.Electrode1Lamp = uilamp(app.UIFigure);
            app.Electrode2LampLabel = uilabel(app.UIFigure);
            app.Electrode2LampLabel.Text = 'Electrode 2';
            app.Electrode2Lamp = uilamp(app.UIFigure);
            app.Electrode3LampLabel = uilabel(app.UIFigure);
            app.Electrode3LampLabel.Text = 'Electrode 3';
            app.Electrode3Lamp = uilamp(app.UIFigure);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.UIFigure);
            app.CheckBox_2.Text = '';
            app.CheckBox_3 = uicheckbox(app.UIFigure);
            app.CheckBox_3.Text = '';
            app.CheckBox_4 = uicheckbox(app.UIFigure);
            app.CheckBox_4.Text = '';
            app.CheckBox_5 = uicheckbox(app.UIFigure);
            app.CheckBox_5.Text = '';
            app.CheckBox_6 = uicheckbox(app.UIFigure);
            app.CheckBox_6.Text = '';
            app.CheckBox_7 = uicheckbox(app.UIFigure);
            app.CheckBox_7.Text = '';
            app.CheckBox_8 = uicheckbox(app.UIFigure);
            app.CheckBox_8.Text = '';
            app.CheckBox_9 = uicheckbox(app.UIFigure);
            app.CheckBox_9.Text = '';
            app.Shank1Label = uilabel(app.UIFigure);
            app.Shank1Label.Text = 'Shank 1';
            app.Shank2Label = uilabel(app.UIFigure);
            app.Shank2Label.Text = 'Shank 2';
            app.Shank3Label = uilabel(app.UIFigure);
            app.Shank3Label.Text = 'Shank 3';
            app.LED1Label = uilabel(app.UIFigure);
            app.LED1Label.Text = 'LED 1';
            app.LED2Label = uilabel(app.UIFigure);
            app.LED2Label.Text = 'LED 2';
            app.LED3Label = uilabel(app.UIFigure);
            app.LED3Label.Text = 'LED 3';
            app.LED4Label = uilabel(app.UIFigure);
            app.LED4Label.Text = 'LED 4';
            app.LED5Label = uilabel(app.UIFigure);
            app.LED5Label.Text = 'LED 5';
            app.LED6Label = uilabel(app.UIFigure);
            app.LED6Label.Text = 'LED 6';
            app.LED7Label = uilabel(app.UIFigure);
            app.LED7Label.Text = 'LED 7';
            app.LED8Label = uilabel(app.UIFigure);
            app.LED8Label.Text = 'LED 8';
            app.LED9Label = uilabel(app.UIFigure);
            app.LED9Label.Text = 'LED 9';
            app.OptrodeSelectorPanel = uipanel(app.UIFigure);
            app.OptrodeSelectorPanel.Title = 'Optrode Selector';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optrode_selector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end